classdef AppAnalise < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainApp                        matlab.ui.Figure
        Grid1                          matlab.ui.container.GridLayout
        Panel_2                        matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        MainMenu                       matlab.ui.container.GridLayout
        menu_Grid1                     matlab.ui.container.GridLayout
        Button_Separator3              matlab.ui.control.Image
        Button_SaveFile                matlab.ui.control.StateButton
        Button_EditInfo                matlab.ui.control.StateButton
        Button_Report                  matlab.ui.control.StateButton
        Button_Playback                matlab.ui.control.StateButton
        Button_File                    matlab.ui.control.StateButton
        Button_Separator2              matlab.ui.control.Image
        Button_Home                    matlab.ui.control.StateButton
        Button_Separator1              matlab.ui.control.Image
        menu_Grid2                     matlab.ui.container.GridLayout
        menu_searchPath                matlab.ui.control.Image
        menu_userPath                  matlab.ui.control.DropDown
        Panel                          matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        ToolBar                        matlab.ui.container.GridLayout
        ToolBar_Button1                matlab.ui.control.Button
        ToolBar_Button2                matlab.ui.control.Button
        ToolBar_Button3                matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        Tab1_Home                      matlab.ui.container.Tab
        Home_Grid1                     matlab.ui.container.GridLayout
        Home_ProjectLogo               matlab.ui.control.Image
        Home_subTitle2                 matlab.ui.control.Label
        Home_subTitle1                 matlab.ui.control.Label
        Home_mainTitle                 matlab.ui.control.Label
        Tab2_File                      matlab.ui.container.Tab
        File_Grid1                     matlab.ui.container.GridLayout
        File_GridB                     matlab.ui.container.GridLayout
        Panel_4                        matlab.ui.container.Panel
        File_GridB2                    matlab.ui.container.GridLayout
        File_Label4_2                  matlab.ui.control.Label
        File_EstimatedMemory           matlab.ui.control.NumericEditField
        EstimativadeusodamemriaapsleituradoespectroMBLabel  matlab.ui.control.Label
        File_Samples                   matlab.ui.control.NumericEditField
        NmerodetraosLabel              matlab.ui.control.Label
        File_DataPoints                matlab.ui.control.NumericEditField
        PontosportraoLabel             matlab.ui.control.Label
        UnidadeLabel                   matlab.ui.control.Label
        File_LevelUnit                 matlab.ui.control.EditField
        OperaoLabel                    matlab.ui.control.Label
        File_Operation                 matlab.ui.control.EditField
        ResoluokHzLabel                matlab.ui.control.Label
        File_Resolution                matlab.ui.control.NumericEditField
        PassovarredurakHzLabel         matlab.ui.control.Label
        File_StepWidth                 matlab.ui.control.NumericEditField
        DescrioEditFieldLabel          matlab.ui.control.Label
        File_Description               matlab.ui.control.EditField
        File_GridB1                    matlab.ui.container.GridLayout
        EstimativadeusodamemriaapsleituradoespectroMBLabel_2  matlab.ui.control.Label
        File_Memory                    matlab.ui.control.NumericEditField
        QuantidadedearquivosLabel      matlab.ui.control.Label
        File_Number                    matlab.ui.control.NumericEditField
        File_Label4                    matlab.ui.control.Label
        File_Button2                   matlab.ui.control.Button
        File_GridA                     matlab.ui.container.GridLayout
        File_Tree2                     matlab.ui.container.Tree
        udpLogger_Label3               matlab.ui.control.Label
        File_Panel1                    matlab.ui.container.Panel
        File_GridA1                    matlab.ui.container.GridLayout
        File_ObservationTime           matlab.ui.control.EditField
        PerododeobservaoLabel          matlab.ui.control.Label
        SensorLabel                    matlab.ui.control.Label
        FormatodoarquivoLabel          matlab.ui.control.Label
        File_City                      matlab.ui.control.EditField
        File_Node                      matlab.ui.control.EditField
        LocaldamonitoraoLabel          matlab.ui.control.Label
        File_Format                    matlab.ui.control.EditField
        File_Label2                    matlab.ui.control.Label
        File_Button1                   matlab.ui.control.Button
        File_Tree1                     matlab.ui.container.Tree
        File_Label1                    matlab.ui.control.Label
        Tab3_Playback                  matlab.ui.container.Tab
        GridLayout5                    matlab.ui.container.GridLayout
        mainInfo_Grid                  matlab.ui.container.GridLayout
        mainInfo_DeleteAll             matlab.ui.control.Button
        mainInfo_Label1                matlab.ui.control.Label
        mainInfo_Warning               matlab.ui.control.Image
        mainInfo_Tree                  matlab.ui.container.Tree
        mainInfo_Label2                matlab.ui.control.Label
        mainInfo_Expand                matlab.ui.control.Image
        mainInfo_Label3                matlab.ui.control.Label
        mainInfo_Text                  matlab.ui.control.TextArea
        Grid3_Playback                 matlab.ui.container.GridLayout
        Playback_mainLabel             matlab.ui.control.Label
        Playback_Panel0                matlab.ui.container.Panel
        Playback_Grid0                 matlab.ui.container.GridLayout
        Playback_Grid1                 matlab.ui.container.GridLayout
        Playback_peakUnit              matlab.ui.control.Label
        FrequnciacentralLabel          matlab.ui.control.Label
        Playback_peakCF                matlab.ui.control.NumericEditField
        Playback_peakTree              matlab.ui.container.Tree
        Playback_deleteMarker          matlab.ui.control.Image
        Playback_addMarker             matlab.ui.control.Image
        Playback_Panel2                matlab.ui.container.Panel
        Playback_peakGrid              matlab.ui.container.GridLayout
        Playback_peakTrace             matlab.ui.control.DropDown
        TraoDropDownLabel              matlab.ui.control.Label
        NmerodepicosLabel              matlab.ui.control.Label
        Playback_peakNumbers           matlab.ui.control.Spinner
        Playback_peakTHR               matlab.ui.control.Spinner
        Playback_peakProeminence       matlab.ui.control.Spinner
        Playback_peakDistance          matlab.ui.control.Spinner
        Playback_peakBW                matlab.ui.control.Spinner
        ThresholddBVmLabel             matlab.ui.control.Label
        Marker_Label2                  matlab.ui.control.Label
        Marker_Label3                  matlab.ui.control.Label
        LarguramnimaocupadakHzLabel    matlab.ui.control.Label
        Playback_peakRadioGroup        matlab.ui.container.ButtonGroup
        Playback_peakRadio1            matlab.ui.control.RadioButton
        Playback_peakRadio2            matlab.ui.control.RadioButton
        Playback_peakLabel0            matlab.ui.control.Label
        Playback_Panel1                matlab.ui.container.Panel
        Playback_traceGrid             matlab.ui.container.GridLayout
        Playback_traceFactor           matlab.ui.control.Spinner
        FatordeintegraoLabel           matlab.ui.control.Label
        Playback_traceCheckBox1        matlab.ui.control.CheckBox
        Playback_traceRadioGroup2      matlab.ui.container.ButtonGroup
        Playback_traceRadio3           matlab.ui.control.RadioButton
        Playback_traceRadio4           matlab.ui.control.RadioButton
        Playback_traceLabel1           matlab.ui.control.Label
        Playback_traceCheckBox2        matlab.ui.control.CheckBox
        Playback_traceCheckBox3        matlab.ui.control.CheckBox
        Playback_traceRadioGroup1      matlab.ui.container.ButtonGroup
        Playback_traceRadio1           matlab.ui.control.RadioButton
        Playback_traceRadio2           matlab.ui.control.RadioButton
        Playback_traceLabel0           matlab.ui.control.Label
        Playback_Grid2                 matlab.ui.container.GridLayout
        Playback_occCheckBox           matlab.ui.control.CheckBox
        Playback_occGrid1              matlab.ui.container.GridLayout
        Playback_occType               matlab.ui.control.DropDown
        FatordeintegraoLabel_2         matlab.ui.control.Label
        Playback_occValue              matlab.ui.control.Spinner
        ValordBmLabel                  matlab.ui.control.Label
        NveldeconfianaLabel            matlab.ui.control.Label
        Playback_occConfidenceLevel    matlab.ui.control.DropDown
        OffSetLabel                    matlab.ui.control.Label
        Playback_occOffset             matlab.ui.control.Spinner
        Playback_occTime               matlab.ui.control.Spinner
        ThresholdDropDownLabel         matlab.ui.control.Label
        Playback_occLabel              matlab.ui.control.Label
        Playback_occPanel              matlab.ui.container.Panel
        Playback_occGrid2              matlab.ui.container.GridLayout
        FunodetendnciacentralLabel_3   matlab.ui.control.Label
        AmostrasteisLabel_2            matlab.ui.control.Label
        Playback_occFcn                matlab.ui.control.DropDown
        Playback_occSamples            matlab.ui.control.Spinner
        Playback_wfCheckBox            matlab.ui.control.CheckBox
        Playback_Grid3                 matlab.ui.container.GridLayout
        Playback_Slider                matlab.ui.control.Slider
        Playback_FullScreen            matlab.ui.control.Button
        Playback_Hand                  matlab.ui.control.StateButton
        Playback_Zoom                  matlab.ui.control.StateButton
        Playback_Menu                  matlab.ui.control.StateButton
        Playback_Reload                matlab.ui.control.Button
        Playback_Play                  matlab.ui.control.Button
        Tab4_Report                    matlab.ui.container.Tab
        GridLayout6                    matlab.ui.container.GridLayout
        Grid3_Report                   matlab.ui.container.GridLayout
        Button_fiscaliza               matlab.ui.control.Button
        Report_tableView               matlab.ui.control.Button
        Report_specDataSave            matlab.ui.control.Button
        Report_Label0                  matlab.ui.control.Label
        Report_Panel0                  matlab.ui.container.Panel
        Report_Grid0                   matlab.ui.container.GridLayout
        Report_Grid2                   matlab.ui.container.GridLayout
        Report_Grid6                   matlab.ui.container.GridLayout
        Report_Grid9                   matlab.ui.container.GridLayout
        Report_Label7                  matlab.ui.control.Label
        LarguramnimaocupadakHzLabel_2  matlab.ui.control.Label
        Report_peakBW                  matlab.ui.control.Spinner
        DistnciamnimaentrepicoskHzLabel  matlab.ui.control.Label
        Report_peakDistance            matlab.ui.control.Spinner
        Report_Label8                  matlab.ui.control.Label
        ClassificaodafaixaLabel        matlab.ui.control.Label
        Report_peakClass               matlab.ui.control.DropDown
        Report_Label9                  matlab.ui.control.Label
        Report_Panel3                  matlab.ui.container.Panel
        Report_Grid11                  matlab.ui.container.GridLayout
        MnimaocupaomximaLabel          matlab.ui.control.Label
        Report_peakOCC                 matlab.ui.control.Spinner
        ProeminnciamnimadBLabel_2      matlab.ui.control.Label
        Report_peakProeminence_C2      matlab.ui.control.Spinner
        Report_Panel2                  matlab.ui.container.Panel
        Report_Grid10                  matlab.ui.container.GridLayout
        ProeminnciamnimadBLabel        matlab.ui.control.Label
        Report_peakProeminence_C1      matlab.ui.control.Spinner
        FunoestatsticaLabel_2          matlab.ui.control.Label
        Report_peakFcn                 matlab.ui.control.DropDown
        Report_Grid5                   matlab.ui.container.GridLayout
        Report_Grid7                   matlab.ui.container.GridLayout
        Report_Label6                  matlab.ui.control.Label
        Report_occConfLevel            matlab.ui.control.DropDown
        NveldeconfianaLabel_2          matlab.ui.control.Label
        Report_occTime                 matlab.ui.control.Spinner
        TempointegraominLabel          matlab.ui.control.Label
        OffSetLabel_2                  matlab.ui.control.Label
        Report_occOffset               matlab.ui.control.Spinner
        ValordBmLabel_2                matlab.ui.control.Label
        Report_occValue                matlab.ui.control.Spinner
        ThresholdDropDownLabel_2       matlab.ui.control.Label
        Report_occType                 matlab.ui.control.DropDown
        Report_Panel1                  matlab.ui.container.Panel
        Report_Grid8                   matlab.ui.container.GridLayout
        Report_uSamples                matlab.ui.control.Spinner
        AmostrasteisLabel              matlab.ui.control.Label
        Report_tSamples                matlab.ui.control.Spinner
        AmostrasdescartadasLabel       matlab.ui.control.Label
        FunoestatsticaLabel            matlab.ui.control.Label
        Report_occFcn                  matlab.ui.control.DropDown
        Report_Label5                  matlab.ui.control.Label
        Report_Label4                  matlab.ui.control.Label
        Report_Grid1                   matlab.ui.container.GridLayout
        Report_Grid4                   matlab.ui.container.GridLayout
        Report_Spinner4                matlab.ui.control.Spinner
        Report_Spinner3                matlab.ui.control.Spinner
        Report_DatePicker2             matlab.ui.control.DatePicker
        Report_Spinner2                matlab.ui.control.Spinner
        Report_Spinner1                matlab.ui.control.Spinner
        Report_DatePicker1             matlab.ui.control.DatePicker
        Report_deleteButton            matlab.ui.control.Image
        Report_addButton               matlab.ui.control.Image
        Report_Grid3                   matlab.ui.container.GridLayout
        Report_textLabel               matlab.ui.control.Label
        Report_freeText                matlab.ui.control.TextArea
        Report_CheckBox3               matlab.ui.control.CheckBox
        Report_CheckBox2               matlab.ui.control.CheckBox
        Report_CheckBox1               matlab.ui.control.CheckBox
        VersoLabel                     matlab.ui.control.Label
        Report_Version                 matlab.ui.control.DropDown
        InspeoFiscalizaLabel           matlab.ui.control.Label
        Report_Issue                   matlab.ui.control.NumericEditField
        Report_Label1                  matlab.ui.control.Label
        Report_Label3                  matlab.ui.control.Label
        Tree3                          matlab.ui.container.Tree
        Report_Label2                  matlab.ui.control.Label
        Report_previewButton           matlab.ui.control.Button
        Report_playButton              matlab.ui.control.Button
        Tab5_Edit                      matlab.ui.container.Tab
        Edit_Grid1                     matlab.ui.container.GridLayout
        Grid3_Info                     matlab.ui.container.GridLayout
        Info_Label1_2                  matlab.ui.control.Label
        Info_Button                    matlab.ui.control.Button
        Info_Panel2                    matlab.ui.container.Panel
        Info_Grid3                     matlab.ui.container.GridLayout
        Info_Panel1_2                  matlab.ui.container.Panel
        Info_Grid2_2                   matlab.ui.container.GridLayout
        Info_City                      matlab.ui.control.EditField
        LocalidadeLabel_2              matlab.ui.control.Label
        OcupaoDropDownLabel            matlab.ui.control.Label
        Info_OCC                       matlab.ui.control.DropDown
        Info_CheckBox                  matlab.ui.control.CheckBox
        LongitudeEditField_2Label      matlab.ui.control.Label
        Info_Longitude                 matlab.ui.control.NumericEditField
        Info_Latitude                  matlab.ui.control.NumericEditField
        LatitudeEditField_2Label       matlab.ui.control.Label
        Info_Panel1                    matlab.ui.container.Panel
        Info_Grid2                     matlab.ui.container.GridLayout
        Info_MetaData4                 matlab.ui.control.EditField
        LocalidadeLabel                matlab.ui.control.Label
        UnidaderesponsvelLabel_2       matlab.ui.control.Label
        Info_MetaData3                 matlab.ui.control.EditField
        LatitudeEditFieldLabel         matlab.ui.control.Label
        Info_MetaData5                 matlab.ui.control.NumericEditField
        Info_MetaData6                 matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel        matlab.ui.control.Label
        Info_Label2                    matlab.ui.control.Label
        Info_Label1                    matlab.ui.control.Label
        Tab6_Save                      matlab.ui.container.Tab
        Save_Grid1                     matlab.ui.container.GridLayout
        Grid3_Info_2                   matlab.ui.container.GridLayout
        Info_Label1_3                  matlab.ui.control.Label
        Info_Button_2                  matlab.ui.control.Button
        Info_Panel2_2                  matlab.ui.container.Panel
        Info_Grid3_2                   matlab.ui.container.GridLayout
        Panel_5                        matlab.ui.container.Panel
        Report_Grid4_2                 matlab.ui.container.GridLayout
        Report_Spinner4_2              matlab.ui.control.Spinner
        Report_Spinner3_2              matlab.ui.control.Spinner
        Report_DatePicker2_2           matlab.ui.control.DatePicker
        Report_Spinner2_2              matlab.ui.control.Spinner
        Report_Spinner1_2              matlab.ui.control.Spinner
        Report_DatePicker1_2           matlab.ui.control.DatePicker
        Info_Panel1_3                  matlab.ui.container.Panel
        Info_Grid2_3                   matlab.ui.container.GridLayout
        LocalidadeLabel_3              matlab.ui.control.Label
        Info_MetaData4_5               matlab.ui.control.EditField
        NomedoarquivoLabel             matlab.ui.control.Label
        Info_MetaData4_4               matlab.ui.control.EditField
        Info_OCC_2                     matlab.ui.control.DropDown
        FormatodoarquivodesadaLabel    matlab.ui.control.Label
        LatitudeEditFieldLabel_2       matlab.ui.control.Label
        Info_MetaData5_2               matlab.ui.control.NumericEditField
        Info_MetaData6_2               matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel_2      matlab.ui.control.Label
        Info_Label2_2                  matlab.ui.control.Label
        Info_Label1_4                  matlab.ui.control.Label
        File_ContextMenu1              matlab.ui.container.ContextMenu
        File_ContextMenu1A             matlab.ui.container.Menu
        File_ContextMenu2              matlab.ui.container.ContextMenu
        File_ContextMenu2A             matlab.ui.container.Menu
    end

    
    properties (Access = private)
        
        RootFolder    = [];
        
        OperationMode = 1;                                                  % 1: modo normal (RF Look Bin, CRFS Bin, SM1809 e CSV); 2: modo "relatório em edição" (MAT)
        
        SelectedFiles = {};
        FilesPath     = {};
        
        Samples  = [];
        metaData = [];
        specData = [];
        
        filtData   = [];
        reportInfo = [];

        DataTypes = [1, 60, 61, 63, 64, 67, 68, 167, 168, 1809];            % appColeta (RF Look Bin): 1; Logger (CRFS Bin): [60,61,63,64,67,68]; UIT (SM1809): 1809; e Argus (CSV): [167,168]
        % pyRedmine
        pyResource     = [];
        redmineObj     = [];
        
        Tree2_WarnCount = 0;
        
        nodesTable = [];
        
        htmlSet = [];
        
        fig     = [];
        axes1   = [];
        axes2   = [];
        axes3   = [];
        cBar1   = [];
        line1   = [];                                                       % WaterFall TimeStamp
        line2   = [];                                                       % OCC Threshold
        line3   = [];                                                       % Marker Frequency
        label1  = [];                                                       % OCC Threshold Label
        
        timeIndex = 0;
        
        dbFlag     = 0;
        occFlag    = 0;
        plotFlag   = 0;
        axesFlag   = 0;
        reloadFlag = 1;
        
        traceInfo = struct('SelectedNode', 1, 'Mode', [0, 0, 0], 'plotLayout', 0);
        
        cTraces = [0.07, 0.07, 0.75; ...                                    % color1 (MinHold)
                   0.39, 0.83, 0.07; ...                                    % color2 (Median or Mean)
                   1.00, 0.07, 0.65; ...                                    % color3 (MaxHold)
                   0.93, 0.69, 0.13];                                       % color4 (ClearWrite)
        
        x;
        Span;
        aCoef;
        bCoef;
        Unit;
        
        occTHR;                                                             % "OffSet Statistical OCC"
        locs = [];                                                          % Índices dos marcadores.
        
    end
    
    
    properties (Access = public)
        
        auxWin1 = [];
        auxWin2 = [];
        
        peaksTable    = [];
        exceptionList = [];
                
    end
    
    
    methods (Access = private)
        
        function startupError(app)
            
            delete(app.fig)
            delete(app)
            
        end
        
        
        function Layout_ModeOperation(app)
            
            if app.Button_Home.Value
                ind = 1;
                
            elseif app.Button_File.Value
                ind = 2;
            
            elseif app.Button_Playback.Value
                ind = 3;
                
                app.mainInfo_Grid.Parent      = app.GridLayout5;
                app.mainInfo_Tree.Multiselect = 0;
                    
                if numel(app.mainInfo_Tree.SelectedNodes) > 1
                    app.mainInfo_Tree.SelectedNodes = app.mainInfo_Tree.SelectedNodes(1);
                end
                
                if ~isempty(app.mainInfo_Tree.SelectedNodes) && isnumeric(app.mainInfo_Tree.SelectedNodes.NodeData)
                    app.Playback_Play.Enable        = 1;
                else
                    app.Playback_Play.Enable        = 0;
                    app.mainInfo_Tree.SelectedNodes = [];
                end

                Layout_Playback(app)
                Layout_expandTree(app)
                
            elseif app.Button_Report.Value
                ind = 4;
                
                app.mainInfo_Grid.Parent      = app.GridLayout6;
                app.mainInfo_Tree.Multiselect = 1;
                
                if ~isempty(app.filtData)
                    app.Report_playButton.Enable    = 1;
                    app.Report_previewButton.Enable = 1;
                else
                    app.Report_playButton.Enable    = 0;
                    app.Report_previewButton.Enable = 0;
                end
                
                Layout_expandTree(app)
                  
            elseif app.Button_EditInfo.Value
                ind = 5;
                
                app.mainInfo_Grid.Parent      = app.Edit_Grid1;
                app.mainInfo_Tree.Multiselect = 0;
                
                if numel(app.mainInfo_Tree.SelectedNodes) > 1
                    app.mainInfo_Tree.SelectedNodes = app.mainInfo_Tree.SelectedNodes(1);
                end
                
                if ~isempty(app.mainInfo_Tree.SelectedNodes) && isnumeric(app.mainInfo_Tree.SelectedNodes.NodeData)
                    Layout_Info(app, 'on')
                else
                    app.mainInfo_Tree.SelectedNodes = [];
                    Layout_Info(app, 'off')
                end
                
                Layout_expandTree(app)
                
            elseif app.Button_SaveFile.Value
                ind = 6;
                
                app.mainInfo_Grid.Parent      = app.Save_Grid1;
                app.mainInfo_Tree.Multiselect = 1;
                
                Layout_expandTree(app)
                
            end
            app.TabGroup.SelectedTab = app.TabGroup.Children(ind);
                
        end
        
        
        function Layout_expandTree(app)
            
            for ii = 1:numel(app.mainInfo_Tree.Children)
                expand(app.mainInfo_Tree.Children(ii));
            end
            
        end
        
        
        function Layout_Playback(app)
            
            selectedNode = app.mainInfo_Tree.SelectedNodes;
            
            if isempty(selectedNode)
                Layout_Playback_Spec(app)
                
            elseif isnumeric(selectedNode.NodeData)
                if ismember(app.specData(selectedNode.NodeData).MetaData.DataType, app.DataTypes)
                    if app.occFlag
                        app.occFlag = 0;
                        Layout_Playback_Spec(app)
                    end
                    
                else
                    if ~app.occFlag
                        app.occFlag = 1;
                        Layout_Playback_OCC(app)
                    end
                    
                    switch app.traceInfo.plotLayout
                        case 1; app.traceInfo.plotLayout = 0;
                        case 3; app.traceInfo.plotLayout = 2;
                    end
                end
                Fcn_Traces(app)
                
            else
                if app.occFlag
                    app.occFlag = 0;
                    Layout_Playback_Spec(app)
                end
            end
            
        end

        
        function Layout_Playback_Spec(app)
            
            % tracePanel
            app.Playback_traceLabel0.Enable = 1;
            app.Playback_traceLabel1.Enable = 1;
            
            set(app.Playback_traceRadioGroup1.Children, 'Enable', 0)
            set(app.Playback_traceRadioGroup2.Children, 'Enable', 0)
            
            set(app.Playback_traceRadioGroup1.Children, 'Enable', 1)
            set(app.Playback_traceRadioGroup2.Children, 'Enable', 0)
                        
            set(app.Playback_traceCheckBox1, 'Value', 0, 'Enable', 1)
            set(app.Playback_traceCheckBox2, 'Value', 0, 'Enable', 1)
            set(app.Playback_traceCheckBox3, 'Value', 0, 'Enable', 1)
            
            app.FatordeintegraoLabel.Enable = 0;
            app.Playback_traceFactor.Enable = 0;
            
            % peakPanel            
            app.Playback_peakRadio1.Value  = 1;
            app.Playback_peakRadio2.Enable = 1;
            
            if ~isempty(app.Playback_peakTree.Children)
                delete(app.Playback_peakTree.Children)
                app.locs = [];
            end
            
            app.FrequnciacentralLabel.Enable = 0;
            app.Playback_peakCF.Enable       = 0;
            app.Playback_peakUnit.Enable     = 0;
            
            % occPanel
            set(app.Playback_occCheckBox,       'Enable', 1, 'Value', 0)
            set(app.Playback_occGrid1.Children, 'Enable', 0)
            set(app.Playback_occGrid2.Children, 'Enable', 0)
            
        end
        
        
        function Layout_Playback_OCC(app)
                        
            % tracePanel
            app.Playback_traceLabel0.Enable = 0;
            app.Playback_traceLabel1.Enable = 0;
            
            set(app.Playback_traceRadioGroup1.Children, 'Enable', 0)
            set(app.Playback_traceRadioGroup2.Children, 'Enable', 0)
                        
            set(app.Playback_traceCheckBox1, 'Value', 0, 'Enable', 0)
            set(app.Playback_traceCheckBox2, 'Value', 0, 'Enable', 0)
            set(app.Playback_traceCheckBox3, 'Value', 0, 'Enable', 0)
            
            app.FatordeintegraoLabel.Enable = 0;
            app.Playback_traceFactor.Enable = 0;
            
            % peakPanel
            app.Playback_peakRadio1.Value  = 1;
            app.Playback_peakRadio2.Enable = 0;             
            Playback_RadioGroupSelectionChanged(app)

            % occPanel
            set(app.Playback_occCheckBox, 'Value', 0, 'Enable', 0)
            set(app.Playback_occGrid1.Children, 'Enable', 0)
            set(app.Playback_occGrid2.Children, 'Enable', 0)
            
        end
        
        
        function Layout_Info(app, Flag)
            
            switch Flag
                case 'on'
                    auxFlag = 0;
                    selectedNodes = app.mainInfo_Tree.SelectedNodes;
                    
% % % % % % % % % % Painel: "Informações extraídas de base de dados da Agência relacionadas ao sensor"
                    if isempty(app.nodesTable)
                        app.nodesTable = Misc_InstalledRFeyeInfo('FISF_editedData.csv');
                    end
                    
                    auxIndex = find(contains(app.nodesTable.Node, app.specData(selectedNodes.NodeData).Node));
                    if ~isempty(auxIndex)
                        set(app.Info_MetaData3, 'FontColor', [0,0,0], 'Value', app.nodesTable.Unit(auxIndex(1)))
                        set(app.Info_MetaData4, 'FontColor', [0,0,0], 'Value', app.nodesTable.City(auxIndex(1)))
                        set(app.Info_MetaData5, 'FontColor', [0,0,0], 'Value', app.nodesTable.Latitude(auxIndex(1)))
                        set(app.Info_MetaData6, 'FontColor', [0,0,0], 'Value', app.nodesTable.Longitude(auxIndex(1)))
                        
                        app.Info_Grid3.RowHeight = {22,88,22,132};
                    else
                        app.Info_Grid3.RowHeight = {0,0,22,132};
                    end
                    
% % % % % % % % % % % Painel: "Informações passíveis de edição relacionada ao fluxo selecionado"
                    % GPS
                    set(app.Info_Latitude,  'Enable', 0, 'FontColor', [0,0,0], 'Value', app.specData(selectedNodes.NodeData).gps.Latitude)
                    set(app.Info_Longitude, 'Enable', 0, 'FontColor', [0,0,0], 'Value', app.specData(selectedNodes.NodeData).gps.Longitude)
                    set(app.Info_City,      'Enable', 0, 'FontColor', [0,0,0], 'Value', app.specData(selectedNodes.NodeData).gps.Location)
                    set(app.Info_CheckBox,  'Enable', 0)
                        
                    if app.specData(selectedNodes.NodeData).gps.Status <= 0
                        auxFlag = 1;
                        
                        set(app.Info_Latitude,  'Enable', 1)
                        set(app.Info_Longitude, 'Enable', 1)
                        set(app.Info_City,      'Enable', 1)
                        set(app.Info_CheckBox,  'Enable', 1)
                        
                        if ~isempty(auxIndex) & (app.specData(selectedNodes.NodeData).gps.Status <= 0)
                            app.Info_Latitude.Value  = app.Info_MetaData5.Value;
                            app.Info_Longitude.Value = app.Info_MetaData6.Value;
                            app.Info_City.Value      = app.Info_MetaData4.Value;
                        end
                    end
                    
                    % Fluxo de ocupação
                    if isempty(app.specData(selectedNodes.NodeData).occRelated)
                        app.Info_OCC.Enable = 0;
                        app.Info_OCC.Items  = {'Ocupação a ser aferida'};
                        
                    else
                        auxFlag = 1;
                        
                        set(app.Info_OCC, 'Enable', 1, 'Items', {'Ocupação a ser aferida'})
                        
                        for ii = 1:length(app.specData(selectedNodes.NodeData).occRelated)
                            app.Info_OCC.Items{ii+1} = sprintf('Threshold: %d %s (ThreadID %d)', ...
                                                               app.specData(app.specData(selectedNodes.NodeData).occRelated(ii)).MetaData.Threshold, ...
                                                               app.specData(selectedNodes.NodeData).MetaData.metaString{1},                          ...
                                                               app.specData(app.specData(selectedNodes.NodeData).occRelated(ii)).ThreadID);
                        end
                        
                        if isempty(app.specData(selectedNodes.NodeData).occFlag)
                            app.Info_OCC.Value = app.Info_OCC.Items{1};
                        else
                            auxIndex = find(app.specData(selectedNodes.NodeData).occRelated == app.specData(selectedNodes.NodeData).occFlag, 1);
                            app.Info_OCC.Value = app.Info_OCC.Items{auxIndex+1};
                        end
                    end
                    
                    % Botão
                    if auxFlag; app.Info_Button.Enable   = 1;
                    else;       app.Info_Button.Enable   = 0;
                    end
                
                case 'off'
                    set(app.Info_MetaData3, 'FontColor', [.94,.94,.94])
                    set(app.Info_MetaData4, 'FontColor', [.94,.94,.94])
                    set(app.Info_MetaData5, 'FontColor', [.94,.94,.94])
                    set(app.Info_MetaData6, 'FontColor', [.94,.94,.94])
                    
                    set(app.Info_Latitude,  'Enable', 0, 'FontColor', [1,1,1])
                    set(app.Info_Longitude, 'Enable', 0, 'FontColor', [1,1,1])
                    set(app.Info_City,      'Enable', 0, 'FontColor', [1,1,1])
                    set(app.Info_OCC,       'Enable', 0, 'Items',     {'Ocupação a ser aferida'})
                    set(app.Info_CheckBox,  'Enable', 0, 'Value', 0)
                                        
                    app.Info_Button.Enable = 0;
            end
            
        end
        
        
        function File_Tree1SelectionChanged_Aux(app, Status)
            
            switch Status
                case 'Off'
                    app.File_Format.Value          = '';
                    app.File_Node.Value            = '';
                    app.File_City.Value            = '';
                    app.File_ObservationTime.Value = '';
                    
                    File_Tree2SelectionChanged_Aux(app, 'Off')
                    
                    set(app.File_GridA1.Children, 'Enable', 0)
                    
                    delete(app.File_Tree2.Children)
                    app.File_Tree2.Enable = 0;                    
                    
                case 'On'
                    set(app.File_GridA1.Children, 'Enable', 1)
                    app.File_Tree2.Enable = 1;
            end
            
        end
        
        
        function File_Tree2SelectionChanged_Aux(app, Status)
            
            switch Status
                case 'Off'
                    app.File_StepWidth.FontColor       = [.94,.94,.94];
                    app.File_DataPoints.FontColor      = [.94,.94,.94];
                    app.File_Resolution.FontColor      = [.94,.94,.94];
                    app.File_Samples.FontColor         = [.94,.94,.94];
                    app.File_EstimatedMemory.FontColor = [.94,.94,.94];
                    
                    app.File_Operation.Value   = '';
                    app.File_LevelUnit.Value   = '';
                    app.File_Description.Value = '';
                    
                    set(app.File_GridB2.Children, 'Enable', 0)
                    
                case 'On'
                    app.File_StepWidth.FontColor       = [0,0,0];
                    app.File_DataPoints.FontColor      = [0,0,0];
                    app.File_Resolution.FontColor      = [0,0,0];
                    app.File_Samples.FontColor         = [0,0,0];
                    app.File_EstimatedMemory.FontColor = [0,0,0];
                    
                    set(app.File_GridB2.Children, 'Enable', 1)
            end
            
        end
        
        
        function File_ReadError(app, msg)
            
            uialert(app.MainApp, msg, 'Alerta!', 'Icon', 'error');
            
            app.SelectedFiles = [];
            app.FilesPath     = [];
            app.metaData      = [];
            
            app.File_Button2.Enable = 0;
            
            File_Tree1_Building(app)
            
        end
                
        
        function File_metadataReader(app, Index)
            
            if isempty(app.metaData); auxIndex = 1;
            else;                     auxIndex = length(app.metaData)+1;
            end
            
            for ii = 1:length(app.SelectedFiles)
                app.metaData(ii).File = fullfile(app.FilesPath{ii}, app.SelectedFiles{ii});
            end
            
            d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on');
            
            for ii = auxIndex:length(app.metaData)
                if length(app.SelectedFiles{ii}) <= 50
                    d.Message = sprintf('Em andamento a leitura de metadados do arquivo:\n• %s\n\n%d de %d', app.SelectedFiles{ii}, ii, length(app.metaData));
                else
                    d.Message = sprintf('Em andamento a leitura de metadados do arquivo:\n• %s\n\n%d de %d', [app.SelectedFiles{ii}(1:48) '...'], ii, length(app.metaData));
                end
                
                switch Index
                    case 1
                        % MELHORAR CHAVEAMENTO. ABRIR ARQUIVO E LER O
                        % INÍCIO.
                        
                        % TESTAR TODOS OS LEITORES. RODAR TODAS AS
                        % APLICAÇÕES E AJUSTAR OS CÓDIGOS (EM ESPECIAL DO
                        % RELA´TORIO)
                        
                        
                        if contains(app.metaData(ii).File, 'appColeta'); app.metaData(ii).Data = FileReader_RFLookBin(app.metaData(ii).File, 'MetaData');
                        else;                                            app.metaData(ii).Data = FileReader_CRFSBin( app.metaData(ii).File, 'MetaData', []);
                        end
                    
                    case 2; app.metaData(ii).Data = FileReader_SM1809(  app.metaData(ii).File, 'MetaData', []);
                    case 3; app.metaData(ii).Data = FileReader_ArgusCSV(app.metaData(ii).File, 'MetaData', []);
                end
                
                app.metaData(ii).Samples = [app.metaData(ii).Data.Samples]';
                app.metaData(ii).Memory  = File_metadataReader_Aux(app, ii);
            end
            
            app.File_Memory.Value = sum([app.metaData.Memory]);
            
        end
        
        
        function EstimatedMemory = File_metadataReader_Aux(app, ii)
            
            EstimatedMemory = 0;
            for jj = 1:numel(app.metaData(ii).Data)
                EstimatedMemory = EstimatedMemory + 4 * app.metaData(ii).Data(jj).Samples .* app.metaData(ii).Data(jj).MetaData.DataPoints .* 1e-6;
            end
            
        end
        
        
        function File_ProjectReader(app)
            
            uiprogressdlg(app.MainApp, 'Message', sprintf('Em andamento a leitura do arquivo:\n• %s', app.SelectedFiles{1}), 'Indeterminate', 'on');
            
            load(app.FilesPath)
            
            app.metaData.File = app.FilesPath;
            app.metaData.Data = rmfield(ReportProject.specData, {'Data',    'statsData',      'occRelated', ...
                                                                 'occFlag', 'report_occInfo', 'report_peakInfo'});
            app.metaData.Samples  = [app.metaData.Data.Samples]';
            app.metaData.Memory   = File_metadataReader_Aux(app, 1);
            app.File_Memory.Value = sum([app.metaData.Memory]);
            
            app.specData      = ReportProject.specData;
            app.reportInfo    = ReportProject.reportInfo;
            app.peaksTable    = ReportProject.peaksTable;
            app.exceptionList = ReportProject.exceptionList;
            
            app.filtData = [];
            for ii = 1:length(app.specData)
                if ismember(app.specData(ii).MetaData.DataType, app.DataTypes)
                    app.filtData = [app.filtData; ii];
                end
            end
            
            set(app.menu_Grid1.Children, 'Enable', 1)
            app.Button_Report.Value = 1;
            mainMenu_Report(app)
            
            Layout_mainInfo_startup(app)
            
            % Panel 3
            app.Report_Issue.Value     = app.reportInfo.Issue;
            app.Report_CheckBox1.Value = app.reportInfo.CheckBoxes(1);
            app.Report_CheckBox2.Value = app.reportInfo.CheckBoxes(2);
            app.Report_CheckBox3.Value = app.reportInfo.CheckBoxes(3);
            
            if app.Report_CheckBox3.Value
                app.Report_freeText.Enable = 1;
                
                if isempty(app.htmlSet)
                    app.htmlSet = Misc_htmlSet('htmlEntityBasicSet.txt');
                end
                
                app.Report_freeText.Value = extractBetween(app.reportInfo.freeText, '<p class="Item_Nivel2">', '</p>');
                
                for ii = 2:size(app.htmlSet, 1)
                    app.Report_freeText.Value = replace(app.Report_freeText.Value, app.htmlSet.Entity{ii}, app.htmlSet.Char{ii});
                end
            end
            
            File_Tree1_Building(app)
            
            Fcn_Tree2(app)                    
            Fcn_Tree3(app)
            
            app.Report_specDataSave.Enable = 1;
            app.Report_tableView.Enable    = 1;
            
        end
        
        
        function File_Tree1_Building(app)

            delete(app.File_Tree1.Children)
            app.File_Button2.Enable = 0;
            
            if ~isempty(app.mainInfo_Tree.Children)
                app.mainInfo_Warning.Visible = 1;
            else
                app.mainInfo_Warning.Visible = 0;
            end
            
            app.File_Number.Value = length(app.SelectedFiles);
            if app.File_Number.Value
                if app.OperationMode == 1
                    app.File_Button2.Enable = 1;
                end
                
                for ii = 1:app.File_Number.Value
                    if contains(app.metaData(ii).Data(1).Node, 'RFeye', 'IgnoreCase', true)
                        imageIcon = 'CRFS.png';
                    elseif contains(app.metaData(ii).Data(1).Node, {'FSL', 'FSVR', 'EB500', 'ETM'})
                        imageIcon = 'R&S Icon.png';
                    elseif contains(app.metaData(ii).Data(1).Node, 'N9344C')
                        imageIcon = '_AgilentLogo.png';
                    else
                        imageIcon = 'treeAlarm2.png';
                    end
                    
                    uitreenode(app.File_Tree1, 'Text', app.SelectedFiles{ii}, 'NodeData', ii, 'Icon', imageIcon, 'ContextMenu', app.File_ContextMenu1);
                end
                app.File_Tree1.SelectedNodes = app.File_Tree1.Children(1);
                
            else
                delete(app.File_Tree2.Children)
            end
            
            File_Tree1SelectionChanged(app)
            
        end
        
        
        function File_Tree2_Building(app)
            
            delete(app.File_Tree2.Children);
            
            ind = app.File_Tree1.SelectedNodes.NodeData;
            for ii = 1:numel(app.metaData(ind).Data)
                if ismember(app.metaData(ind).Data(ii).MetaData.DataType, app.DataTypes)
                    uitreenode(app.File_Tree2, 'Text', sprintf('ThreadID %d: %.3f - %.3f MHz',                  ...
                                                               app.metaData(ind).Data(ii).ThreadID,             ...
                                                               app.metaData(ind).Data(ii).MetaData.FreqStart .* 1e-6,  ...
                                                               app.metaData(ind).Data(ii).MetaData.FreqStop .* 1e-6), ...
                                               'NodeData', ii, 'ContextMenu', app.File_ContextMenu2);
                    
                else
                    uitreenode(app.File_Tree2, 'Text', sprintf('ThreadID %d: %.3f - %.3f MHz (Ocupação)',       ...
                                                               app.metaData(ind).Data(ii).ThreadID,             ...
                                                               app.metaData(ind).Data(ii).MetaData.FreqStart .* 1e-6,  ...
                                                               app.metaData(ind).Data(ii).MetaData.FreqStop .* 1e-6), ... 
                                               'NodeData', ii, 'ContextMenu', app.File_ContextMenu2);
                end
            end
            
            app.File_Tree2.SelectedNodes = app.File_Tree2.Children(1);
            File_Tree2SelectionChanged(app)
            
        end
        
        
        function File_specReadError(app, msg)
            
            uialert(app.MainApp, msg, 'Alerta!', 'Icon', 'error');
            
        end
        
        
        function Fcn_SpecDataReader(app, d)
            
            % Organização dos fluxos de dados e pré-alocação
            Fcn_SpecDataReader_Aux1(app)
            
            % Leitura dos dados de espectro
            auxValue1 = length(app.metaData);
            for ii = 1:auxValue1
                if length(app.SelectedFiles{ii}) <= 50; d.Message = sprintf('Em andamento a leitura de dados do espectro do arquivo:\n• %s\n\n%d de %d', app.SelectedFiles{ii}, ii, auxValue1);
                else;                                   d.Message = sprintf('Em andamento a leitura de dados do espectro do arquivo:\n• %s\n\n%d de %d', [app.SelectedFiles{ii}(1:48) '...'], ii, auxValue1);
                end
                
                auxFile = strsplit(app.metaData(ii).File, '.');
                if strcmpi(auxFile{end}, 'bin')
                    if contains(app.metaData(ii).File, 'appColeta'); SpecInfo = FileReader_RFLookBin(app.metaData(ii).File, 'SingleFile');
                    else;                                            SpecInfo = FileReader_CRFSBin(app.metaData(ii).File, 'SpecData', app.metaData(ii));
                    end
                elseif strcmpi(auxFile{end}, 'sm1809'); SpecInfo = FileReader_SM1809(app.metaData(ii).File, 'SpecData', app.metaData(ii));
                elseif strcmpi(auxFile{end}, 'csv');    SpecInfo = FileReader_ArgusCSV(app.metaData(ii).File, 'SpecData', app.metaData(ii));
                end                
                
                auxSpecInfo = rmfield(SpecInfo, {'Samples', 'Data', 'gps', 'Blocks'});
                for mm = 1:length(auxSpecInfo)
                    auxSpecInfo(mm).MetaData = rmfield(auxSpecInfo(mm).MetaData, 'SampleTime');
                end
                
                auxSpecData = rmfield(app.specData, {'Samples', 'Data', 'gps', 'Blocks'});
                for nn = 1:length(auxSpecData)
                    auxSpecData(nn).MetaData = rmfield(auxSpecData(nn).MetaData, 'SampleTime');
                end
                
                for jj = 1:length(SpecInfo)
                    for kk = 1:length(app.specData)
                        if isequal(auxSpecData(kk), auxSpecInfo(jj))
                            Index1 = kk;
                            break
                        end
                    end
                    
                    % O formato CSV gerado pelo Argus não possibilita a
                    % identificação do nº de blocos de espectro sem que
                    % seja lido todo o seu arquivo. 
                    % No caso desse formato, então, não é possível a junção
                    % de dois ou mais arquivos num único fluxo. 
                    % Essa junção (se desejada) deve ocorrer fora do
                    % appAnálise.
                    totalSamples = sum(app.Samples(Index1,:), 2);
                    if totalSamples
                        auxValue2 = sum(app.Samples(Index1, 1:ii));
                        if ii == 1
                            Index2 = 1;
                        else
                            Index2 = sum(app.Samples(Index1, 1:(ii-1)))+1;
                        end
    
                        app.specData(Index1).Data{1}(1,Index2:auxValue2) = SpecInfo(jj).Data{1};
                        app.specData(Index1).Data{2}(:,Index2:auxValue2) = SpecInfo(jj).Data{2};
                        
                    else
                        app.specData(Index1).Samples = SpecInfo(jj).Samples;
                        
                        app.specData(Index1).Data{1} = SpecInfo(jj).Data{1};
                        app.specData(Index1).Data{2} = SpecInfo(jj).Data{2};
                        
                        if isempty(app.specData(Index1).MetaData.SampleTime)
                            app.specData(Index1).MetaData.SampleTime = SpecInfo(jj).MetaData.SampleTime;
                        end
                        
                    end
                end
            end
            app.specData = rmfield(app.specData, 'Blocks');            
            
            % Manipulações acessórias
            d.Message = sprintf('Em andamento manipulações acessórias, como aferição de parâmetros estatísticos e identificação de fluxo(s) de ocupação relacionado(s).');
            Fcn_SpecDataReader_Aux2(app)
            
        end
        
        
        function Fcn_SpecDataReader_Aux1(app)
            
            % Organização dos fluxos de dados
            for ii = 1:length(app.metaData)
                if ii == 1
                    app.specData = app.metaData(1).Data;
                    app.Samples  = app.metaData(1).Samples;
                    if isempty(app.Samples)
                        app.Samples = 0;
                    end
                    
                else
                    SpecInfo   = app.metaData(ii).Data;
                    auxSamples = app.metaData(ii).Samples;
                    if isempty(auxSamples)
                        auxSamples = 0;
                    end
                    
                    auxSpecInfo = rmfield(SpecInfo, {'Samples', 'gps', 'Blocks'});
                    for mm = 1:length(auxSpecInfo)
                        auxSpecInfo(mm).MetaData = rmfield(auxSpecInfo(mm).MetaData, 'SampleTime');
                    end
                    
                    for kk = 1:length(SpecInfo)
                        auxIndex2 = find([app.specData.ThreadID] == SpecInfo(kk).ThreadID);
                        
                        if isempty(auxIndex2)
                            app.specData(end+1)    = SpecInfo(kk);
                            app.Samples(end+1, ii) = auxSamples(kk);
                        else
                            for ll = auxIndex2
                                auxSpecData = rmfield(app.specData(ll), {'Samples', 'gps', 'Blocks'});
                                
                                for nn = 1:length(auxSpecData)
                                    auxSpecData(nn).MetaData = rmfield(auxSpecData(nn).MetaData, 'SampleTime');
                                end
                                
                                if isequal(auxSpecData, auxSpecInfo(kk))
                                    app.Samples(ll, ii) = auxSamples(kk);
                                    break
                                end
                                
                                if ll == auxIndex2(end)
                                    app.specData(end+1)    = SpecInfo(kk);
                                    app.Samples(end+1, ii) = auxSamples(kk);
                                end
                            end
                        end
                    end
                end
            end
            
            % Pré-alocação
            totalSamples = sum(app.Samples, 2);
            
            for jj = 1:length(app.specData)
                if totalSamples(jj) > 65536
                    totalSamples(jj) = 65536;
                end                
                
                app.specData(jj).Samples = totalSamples(jj);
                app.specData(jj).Data    = {repmat(datetime([0 0 0 0 0 0], 'Format', 'dd/MM/yyyy HH:mm:ss'), 1, totalSamples(jj)), ...
                                            zeros(app.specData(jj).MetaData.DataPoints,                              totalSamples(jj), 'single')};
            end
            
        end
        
        
        function Fcn_SpecDataReader_Aux2(app)

            % Fluxos de dados ordenados por ThreadID
            if ~issorted([app.specData.ThreadID])
                [~, ind1]     = sort([app.specData.ThreadID]);
                app.specData  = app.specData(ind1);
            end
            
            
            % Fluxos de dados ordenados temporalmente
            for ii = 1:length(app.specData)
                if ~issorted(app.specData(ii).Data{1})
                    [app.specData(ii).Data{1}, ind2] = sort(app.specData(ii).Data{1});
                    app.specData(ii).Data{2}         = app.specData(ii).Data{2}(:,ind2);
                end
            end
            
            
            % Estatística e mapeamento dos fluxos de ocupação
            for ii = 1:length(app.specData)
                if ismember(app.specData(ii).MetaData.DataType, app.DataTypes)
                    app.specData(ii).report_occInfo  = [];
                    app.specData(ii).report_peakInfo = [];
                    
                    % Estatística.
                    app.specData(ii).statsData = [min(app.specData(ii).Data{2}, [], 2), ...
                                                  median(app.specData(ii).Data{2}, 2),  ...
                                                  mean(app.specData(ii).Data{2}, 2),    ...
                                                  max(app.specData(ii).Data{2}, [], 2)];
        
            
                    % Fluxos de ocupação relacionados.
                    auxValue = [];
                    switch app.specData(ii).MetaData.DataType
                        case {60, 61}; auxValue = 62;
                        case {63, 64}; auxValue = 65;
                        case {67, 68}; auxValue = 69;
                    end
                    
                    ind4 = [];
                    for jj = 1:length(app.specData)
                        if app.specData(jj).MetaData.DataType == auxValue
                            ind4 = [ind4, jj];
                        end
                    end

                    if isempty(ind4)
                        continue
                    end
                    
                    app.specData(ii).occRelated = [];
                    app.specData(ii).occFlag = [];
                    for kk = ind4
                        logEvaluation = strcmp(app.specData(ii).Node, app.specData(kk).Node)                         & ...
                                        app.specData(ii).MetaData.FreqStart  == app.specData(kk).MetaData.FreqStart  & ...
                                        app.specData(ii).MetaData.FreqStop   == app.specData(kk).MetaData.FreqStop   & ...
                                        app.specData(ii).MetaData.DataPoints == app.specData(kk).MetaData.DataPoints & ...
                                        strcmp(app.specData(ii).TaskName, app.specData(kk).TaskName);
            
                        if logEvaluation
                            app.specData(ii).occRelated = [app.specData(ii).occRelated, kk];
                        end
                    end
                    
                    if ~isempty(app.specData(ii).occRelated)
                        app.specData(ii).occFlag = app.specData(ii).occRelated(1);
                    end
                    
                elseif ismember(app.specData(ii).MetaData.DataType, [62, 65, 69])
                        app.specData(ii).statsData = [min(app.specData(ii).Data{2}, [], 2), ...
                                                      mean(app.specData(ii).Data{2}, 2),    ...
                                                      max(app.specData(ii).Data{2}, [], 2)];
                end
            end
            
        end
                
        
        function Fcn_Tree2(app)
            
            delete(app.mainInfo_Tree.Children);
            app.Tree2_WarnCount = 0;
            
            Layout_mainInfo_startup(app)
            
            % Diferenciar fluxos que tenham o mesmo ThreadID, inserindo
            % letras. Por exemplo: ThreadID "10A", "10B" e "10C".
            virtualThreadID = repmat({''}, height(app.specData), 1);            
            lyricsSet       = 'A':'Z';
            
            [freqs, ~, ifreqs] = unique([app.specData.ThreadID]);
            cfreqs = accumarray(ifreqs, 1);
            
            ii = 0;
            while ii < numel(ifreqs)
                ii = ii+1;
                ind = find(freqs == app.specData(ii).ThreadID, 1);
                
                if cfreqs(ind) == 1
                    virtualThreadID{ii,1} = num2str(app.specData(ii).ThreadID);
                else
                    for jj = 1:cfreqs(ind)
                        virtualThreadID{ii+jj-1,1} = sprintf('%d%s', app.specData(ii+jj-1).ThreadID, lyricsSet(jj));
                    end
                    ii = ii+cfreqs(ind)-1;
                end
            end
            
            
            % Criação dos nós da árvore.
            for ii = 1:length(app.specData)
                allNodes{ii,1} = app.specData(ii).Node;
            end
            [uniqueNodes, ~, ic] = unique(allNodes);
            a_counts = accumarray(ic, 1);
            
            for ii = 1:length(uniqueNodes)
                if contains(uniqueNodes{ii}, 'RFeye', 'IgnoreCase', true)
                    imageIcon = 'CRFS.png';
                elseif contains(uniqueNodes{ii}, {'FSL', 'FSVR', 'EB500', 'ETM'})
                    imageIcon = 'R&S Icon.png';
                elseif contains(uniqueNodes{ii}, 'N9344C')
                    imageIcon = '_AgilentLogo.png';
                else
                    imageIcon = 'treeAlarm2.png';
                end
                Category(ii) = uitreenode(app.mainInfo_Tree, 'Text', uniqueNodes{ii}, 'NodeData', uniqueNodes{ii}, 'Icon', imageIcon);
                
                auxIndex = find(ic == ii);
                for jj = 1:a_counts(ii)
                    if ismember(app.specData(auxIndex(jj)).MetaData.DataType, app.DataTypes)
                        Attributes(jj) = uitreenode(Category(ii), 'Text', sprintf('ThreadID %s: %.3f - %.3f MHz', ...
                                                                          virtualThreadID{auxIndex(jj)}, ...
                                                                          1e-6*app.specData(auxIndex(jj)).MetaData.FreqStart, ...
                                                                          1e-6*app.specData(auxIndex(jj)).MetaData.FreqStop), 'NodeData', auxIndex(jj));
                        
                        % GPS
                        if app.specData(auxIndex(jj)).gps.Status == -1
                            uitreenode(Attributes(jj), 'Text', sprintf('GPS: %.6f, %.6f', app.specData(auxIndex(jj)).gps.Latitude, app.specData(auxIndex(jj)).gps.Longitude), 'NodeData', auxIndex(jj), 'Icon', 'Manual.png');
                        elseif app.specData(auxIndex(jj)).gps.Status == 0
                            app.Tree2_WarnCount = app.Tree2_WarnCount + 1;
                            uitreenode(Attributes(jj), 'Text', 'GPS', 'NodeData', auxIndex(jj), 'Icon', 'treeAlarm2.png');
                        else
                            uitreenode(Attributes(jj), 'Text', sprintf('GPS: %.6f, %.6f', app.specData(auxIndex(jj)).gps.Latitude, app.specData(auxIndex(jj)).gps.Longitude), 'NodeData', auxIndex(jj));
                        end
                            
                        % Fluxo(s) de Ocupação relacionado(s) ao de espectro
                        if ~isempty(app.specData(auxIndex(jj)).occRelated)
                        Temp2 = uitreenode(Attributes(jj), 'Text', 'Fluxo(s) de ocupação', 'NodeData', auxIndex(jj));

                            if isempty(app.specData(auxIndex(jj)).occFlag)
                                uitreenode(Temp2, 'Text', 'Ocupação a ser aferida', 'NodeData', auxIndex(jj), 'Icon', 'Manual.png');
                            end
                            
                            auxIndex2 = app.specData(auxIndex(jj)).occRelated;
                            for kk = auxIndex2
                                if (app.specData(auxIndex(jj)).occFlag == kk) & (kk == auxIndex2(1))
                                    uitreenode(Temp2, 'Text', sprintf('Threshold: %d %s (ThreadID %s)', ...
                                               app.specData(kk).MetaData.Threshold,                     ...
                                               app.specData(ii).MetaData.metaString{1},                 ...
                                               virtualThreadID{kk}), 'NodeData', auxIndex(jj), 'Icon', 'Auto.png');
                                else
                                    if app.specData(auxIndex(jj)).occFlag == kk
                                        uitreenode(Temp2, 'Text', sprintf('Threshold: %d %s (ThreadID %d)', ...
                                                   app.specData(kk).MetaData.Threshold,                     ...
                                                   app.specData(ii).MetaData.metaString{1},                 ...
                                                   virtualThreadID{kk}), 'NodeData', auxIndex(jj), 'Icon', 'Manual.png');
                                    else
                                        uitreenode(Temp2, 'Text', sprintf('Threshold: %d %s (ThreadID %d)', ...
                                                   app.specData(kk).MetaData.Threshold,                     ...
                                                   app.specData(ii).MetaData.metaString{1},                 ...
                                                   virtualThreadID{kk}), 'NodeData', auxIndex(jj));
                                    end
                                end                     
                            end
                        end
                        
                    else
                        Attributes(jj) = uitreenode(Category(ii), 'Text', sprintf('ThreadID %s: %.3f - %.3f MHz (Ocupação)', ...
                                                                          virtualThreadID{auxIndex(jj)},                     ...
                                                                          1e-6*app.specData(auxIndex(jj)).MetaData.FreqStart,       ...
                                                                          1e-6*app.specData(auxIndex(jj)).MetaData.FreqStop), 'NodeData', auxIndex(jj));
                        
                        % Threshold
                        uitreenode(Attributes(jj), 'Text', sprintf('Threshold: %d %s', app.specData(auxIndex(jj)).MetaData.Threshold, app.specData(ii).MetaData.metaString{1}), 'NodeData', auxIndex(jj));

                    end
                    % Varreduras
                    Temp1 = uitreenode(Attributes(jj), 'Text', sprintf('%d varreduras', app.specData(auxIndex(jj)).Samples), 'NodeData', auxIndex(jj));
                    uitreenode(Temp1, 'Text', sprintf('%s (Início)', datestr(app.specData(auxIndex(jj)).Data{1}(1), 'dd/mm/yyyy HH:MM:SS')), 'NodeData', auxIndex(jj));
                    uitreenode(Temp1, 'Text', sprintf('%s (Fim)', datestr(app.specData(auxIndex(jj)).Data{1}(end),  'dd/mm/yyyy HH:MM:SS')), 'NodeData', auxIndex(jj));
                    
                end
            end
            
            if app.Tree2_WarnCount == 0
                app.mainInfo_Label2.Text = sprintf('%d alerta(s)', app.Tree2_WarnCount);
                app.mainInfo_Label2.FontColor = 'black';
            else
                app.mainInfo_Label2.Text = sprintf('%d alerta(s) crítico(s)', app.Tree2_WarnCount);
                app.mainInfo_Label2.FontColor = 'red';
            end
            
        end
        
        
        function Fcn_Tree3(app)
            
            delete(app.Tree3.Children);
            
            app.Report_specDataSave.Enable = 0;
            app.Report_tableView.Enable    = 0;
            
            if ~isempty(app.filtData)
                if app.Button_Report.Value
                    app.Playback_Play.Enable = 1;
                end
            else
                return
            end
            
            for ii = 1:length(app.filtData)
                allNodes{ii,1} = app.specData(app.filtData(ii)).Node;
            end
            [uniqueNodes, ~, ic] = unique(allNodes);
            a_counts = accumarray(ic, 1);
            
            for ii = 1:length(uniqueNodes)
                if contains(uniqueNodes{ii}, 'RFeye', 'IgnoreCase', true)
                    imageIcon = 'CRFS.png';
                elseif contains(uniqueNodes{ii}, 'FSL', 'IgnoreCase', true)
                    imageIcon = 'R&S Icon.png';
                else
                    imageIcon = 'R&S Icon.png';
                end
                Category(ii) = uitreenode(app.Tree3, 'Text', uniqueNodes{ii}, 'NodeData', uniqueNodes{ii}, 'Icon', imageIcon);
                
                auxIndex1 = find(ic == ii);
                for jj = 1:a_counts(ii)
                    auxIndex2 = app.filtData(auxIndex1(jj));
                    
                    uitreenode(Category(ii), 'Text', sprintf('ThreadID %d: %.3f - %.3f MHz', ...
                                                     app.specData(auxIndex2).ThreadID, ...
                                                     1e-6*app.specData(auxIndex2).MetaData.FreqStart, ...
                                                     1e-6*app.specData(auxIndex2).MetaData.FreqStop), 'NodeData', auxIndex2);
                end
                
            end
            Fcn_Tree3_Aux1(app)
            
        end
        
        
        function Fcn_Tree3_Aux1(app)
            
            StartDate = [];
            StopDate  = [];
            for ii = 1:length(app.filtData)
                auxStartDate        = app.specData(app.filtData(ii)).Data{1}(1);
                auxStartDate.Format = 'dd-MMM-yyyy';
                
                auxStopDate         = app.specData(app.filtData(ii)).Data{1}(end);
                auxStopDate.Format  = 'dd-MMM-yyyy';
                
                StartDate = [StartDate auxStartDate];
                StopDate  = [StopDate  auxStopDate ];
            end
            
            app.Report_DatePicker1.Limits = [min(StartDate) max(StopDate)];
            app.Report_DatePicker1.Value  = app.Report_DatePicker1.Limits(1);
            
            app.Report_DatePicker2.Limits = app.Report_DatePicker1.Limits;
            app.Report_DatePicker2.Value  = app.Report_DatePicker2.Limits(2);
            
            app.Report_Spinner1.Value = 0;
            app.Report_Spinner2.Value = 0;
            app.Report_Spinner3.Value = 23;
            app.Report_Spinner4.Value = 59;
            
        end        
        
        
        function Fcn_Tree4(app)
            
            for ii = 1:length(app.locs)
                uitreenode(app.Playback_peakTree, 'Text', sprintf("M%d: %.3f MHz @", ii, app.x(app.locs(ii))/1e+6), 'NodeData', [ii, app.traceInfo.SelectedNode, app.locs(ii)]);
            end
            
            if ~isempty(app.locs)
                app.Playback_peakTree.SelectedNodes = app.Playback_peakTree.Children(1);
                app.Playback_deleteMarker.Enable = 1;
                
                app.Playback_peakCF.Value      = str2double(extractBetween(app.Playback_peakTree.SelectedNodes.Text, ": ", " MHz"));
                app.FrequnciacentralLabel.Enable = 1;
                app.Playback_peakCF.Enable     = 1;
                app.Playback_peakUnit.Enable = 1;
            end
            
        end
        
        
        function Fcn_Plot(app)
            
            Fcn_Plot_Layout1(app)
            Fcn_Plot_Layout2(app)
            Fcn_Plot_StartUp1(app)
            Fcn_Plot_StartUp2(app)
            
            while app.timeIndex < app.specData(app.traceInfo.SelectedNode).Samples
                if ~app.plotFlag
                    return
                elseif app.axesFlag
                    Fcn_Plot_Layout1(app)
                    Fcn_Plot_Layout2(app)
                    Fcn_Plot_StartUp1(app)
                    Fcn_Plot_StartUp2(app);
                end
                app.timeIndex = app.timeIndex+1;
                
% %             TimeStamp and Slider Update.    
                app.axes1.Subtitle.String = datestr(app.specData(app.traceInfo.SelectedNode).Data{1}(app.timeIndex), 'dd/mm/yyyy HH:MM:SS');
                app.Playback_Slider.Value  = 100 * app.timeIndex/app.specData(app.traceInfo.SelectedNode).Samples;
                
% %             Axes 1: Spectrum Plot.
                app.axes1.Children(end).YData = app.specData(app.traceInfo.SelectedNode).Data{2}(:, app.timeIndex)';
                
% %             Markers labels.
                jj = 0;
                if ~strcmp(app.axes1.Children(end).Marker, 'none')
                    for kk = 1:length(app.axes1.Children(end).MarkerIndices)
                        jj = jj+1;
                        app.axes1.Children(end-jj).Position(2) = app.axes1.Children(end).YData(app.axes1.Children(end).MarkerIndices(kk));
                        
                        if ~mod(app.timeIndex, 10)
                            auxStr = app.Playback_peakTree.Children(end-kk+1).Text(1:strfind(app.Playback_peakTree.Children(end-kk+1).Text, '@'));
                            app.Playback_peakTree.Children(end-kk+1).Text = sprintf('%s %.1f %s', auxStr, app.axes1.Children(end).YData(app.axes1.Children(end).MarkerIndices(kk)), app.Unit);
                        end
                    end
                    jj=jj+1;
                end
                
% %             Processed traces.               
                auxIndex = find(app.traceInfo.Mode ~= 0);
                if app.Playback_traceRadio2.Value
                    for ii = auxIndex
                        jj = jj+1;
                        
                        switch app.traceInfo.Mode(ii)
                            case 1; app.axes1.Children(end-jj).YData = min(app.axes1.Children(end-jj).YData, app.axes1.Children(end).YData);
                            case 3; app.axes1.Children(end-jj).YData = ((app.Playback_traceFactor.Value-1)*app.axes1.Children(end-jj).YData ...
                                                                       + app.axes1.Children(end).YData)/app.Playback_traceFactor.Value;
                            case 4; app.axes1.Children(end-jj).YData = max(app.axes1.Children(end-jj).YData, app.axes1.Children(end).YData);
                        end
                    end
                end
                
% %             Axes2: OCC Plot.
                if ~isempty(app.axes2.Children)
                    switch app.Playback_occType.Value
                        case 'Linear'
                            app.axes2.Children(1).YData = ((app.Playback_occTime.Value-1)*app.axes2.Children(1).YData ...
                                                           +100*single(app.axes1.Children(end).YData > app.Playback_occValue.Value))/app.Playback_occTime.Value;
                            app.axes2.Children(2).YData = max(app.axes2.Children(1).YData, app.axes2.Children(2).YData);
                        case 'Piso de ruído (Offset)'
                            app.axes2.Children(1).YData = ((app.Playback_occTime.Value-1)*app.axes2.Children(1).YData ...
                                                           +100*single(app.axes1.Children(end).YData > app.occTHR))/app.Playback_occTime.Value;
                            app.axes2.Children(2).YData = max(app.axes2.Children(1).YData, app.axes2.Children(2).YData);
                    end
                end
                                
% %             Axes3: WaterFall Plot.
%                 app.line1.Position(2) = app.timeIndex;
                if app.traceInfo.plotLayout == 2
                    app.line1.Position(2) = .1 + (.4/(app.specData(app.traceInfo.SelectedNode).Samples-1))*(app.timeIndex-1);
                elseif app.traceInfo.plotLayout == 3
                    app.line1.Position(2) = .1 + (.3/(app.specData(app.traceInfo.SelectedNode).Samples-1))*(app.timeIndex-1);
                end
              drawnow
%             pause(0.05)

                
% %             Reload Flag.
                if app.timeIndex == app.specData(app.traceInfo.SelectedNode).Samples
                    if app.reloadFlag
                        app.timeIndex = 0;
                    else
                        Playback_playButton(app)
                    end
                end
            end
            app.timeIndex      = 0;
            app.Playback_Slider.Value = 0;
            
        end
        
        
        function Fcn_Plot_Layout1(app)
            
            app.fig.Visible = 1;
            cla(app.axes1)
            cla(app.axes2)
            
% %         Limpa WaterFall caso tenha ocorrido uma alteração do fluxo selecionado.    
            if app.traceInfo.SelectedNode ~= app.mainInfo_Tree.SelectedNodes.NodeData
                app.timeIndex = 0;
                app.locs      = [];
                
                app.Playback_deleteMarker.Enable = 0;
                app.FrequnciacentralLabel.Enable = 0;
                app.Playback_peakCF.Enable     = 0;
                app.Playback_peakUnit.Enable = 0;
                
                delete(app.Playback_peakTree.Children)
                cla(app.axes3)
                drawnow nocallbacks
            end
            app.traceInfo.SelectedNode = app.mainInfo_Tree.SelectedNodes.NodeData;
            
% %         Desabilita WaterFall caso fluxo de dados tenha apenas uma amostra.    
            if app.specData(app.traceInfo.SelectedNode).Samples > 1
                app.Playback_wfCheckBox.Enable = 1;
            else
                app.Playback_wfCheckBox.Enable = 0;
                app.Playback_wfCheckBox.Value  = 0;
                if ismember(app.traceInfo.plotLayout, [2, 3])
                    app.traceInfo.plotLayout = 0;
                end
            end
            
        end
        
        
        function Fcn_Plot_Layout2(app)
            
            linkaxes([app.axes1, app.axes2, app.axes3], 'off')
                        
            switch app.traceInfo.plotLayout
                case 0
                    app.axes1.InnerPosition = [.08 .1 .85 .82];
                    app.axes2.InnerPosition = [0 0 0 0];
                    app.axes3.InnerPosition = [0 0 0 0];
                    
                    app.cBar1.Position = [0 0 0 0];
                    app.line1.Position(3) = 0;
                    
                    app.axes1.XTickLabelMode = 'auto';
                    
                    xlabel(app.axes1, 'Frequência (Hz)')
                    
                case 1
                    app.axes1.InnerPosition = [.08 .52 .85 .40];
                    app.axes2.InnerPosition = [.08 .1  .85 .40];
                    app.axes3.InnerPosition = [0 0 0 0];
                    
                    app.cBar1.Position = [0 0 0 0];
                    app.line1.Position(3) = 0;
                    
                    app.axes1.XTickLabel = {};
                    app.axes2.XTickLabelMode = 'auto';
                    
                    xlabel(app.axes1, '')
                    xlabel(app.axes2, 'Frequência (Hz)')
                    
                    linkaxes([app.axes1, app.axes2], 'x')

                case 2
                    app.axes1.InnerPosition = [.08 .52 .85 .40];
                    app.axes2.InnerPosition = [0 0 0 0];
                    app.axes3.InnerPosition = [.08 .1  .85 .40];
                    
                    app.cBar1.Position = [0.9405, 0.1, 0.0145, 0.1];
                    app.line1.Position(3) = .85;
                    
                    app.axes1.XTickLabel = {};
                    app.axes3.XTickLabelMode = 'auto';
                    
                    xlabel(app.axes1, '')
                    xlabel(app.axes2, '')
                    xlabel(app.axes3, 'Frequência (Hz)')
                    
                    linkaxes([app.axes1, app.axes3], 'x')

                case 3
                    app.axes1.InnerPosition = [.08 .62 .85 .30];
                    app.axes2.InnerPosition = [.08 .42 .85 .18];
                    app.axes3.InnerPosition = [.08 .1  .85 .30];
                    
                    app.cBar1.Position = [0.9405, 0.1, 0.0145, 0.1];
                    app.line1.Position(3) = .85;
                    
                    app.axes1.XTickLabel = {};
                    app.axes2.XTickLabel = {};
                    app.axes3.XTickLabelMode = 'auto';
                    
                    xlabel(app.axes1, '')
                    xlabel(app.axes2, '')
                    xlabel(app.axes3, 'Frequência (Hz)')
                    
                    linkaxes([app.axes1, app.axes2, app.axes3], 'x')
            end
            
        end
        
        
        function Fcn_Plot_StartUp1(app)
            
% %         Axes1 (Spectrum plot)
            app.fig.Name = app.specData(app.traceInfo.SelectedNode).Node;
            
            titleStr = '';
            if app.specData(app.traceInfo.SelectedNode).gps.Status
                titleStr = [titleStr sprintf('GPS: %.6f, %.6f. ', app.specData(app.traceInfo.SelectedNode).gps.Latitude, app.specData(app.traceInfo.SelectedNode).gps.Longitude)];
            end
            
            titleStr = [titleStr sprintf('Modo: %s. ', app.specData(app.traceInfo.SelectedNode).MetaData.metaString{3})];
            
            if ~isempty(app.specData(app.traceInfo.SelectedNode).MetaData.metaString{4})             
                titleStr = [titleStr sprintf('Detector: %s. ', app.specData(app.traceInfo.SelectedNode).MetaData.metaString{4})];
            end
                                    
            if ~isempty(app.specData(app.traceInfo.SelectedNode).MetaData.Resolution)
                titleStr = [titleStr sprintf('Resolução: %.3f kHz. ', app.specData(app.traceInfo.SelectedNode).MetaData.Resolution/1000)];
            end        
            
            titleStr    = [titleStr sprintf('%d pontos por traço.', app.specData(app.traceInfo.SelectedNode).MetaData.DataPoints)];
            subtitleStr = datestr(app.specData(app.traceInfo.SelectedNode).Data{1}(1), 'dd/mm/yyyy HH:MM:SS');
            title(app.axes1, titleStr, subtitleStr);
            
            if ~ismember(app.specData(app.traceInfo.SelectedNode).MetaData.DataType, [62, 65, 69])
                auxValue = min(app.specData(app.traceInfo.SelectedNode).statsData(:,1));
                downYLim = auxValue - mod(auxValue, 10);
            
                auxValue = max(app.specData(app.traceInfo.SelectedNode).statsData(:,end));
                upYLim   = auxValue - mod(auxValue, 10) + 10;
                
                app.Unit = app.specData(app.traceInfo.SelectedNode).MetaData.metaString{1};
                auxStr = ['Nível (' app.Unit ')'];
                app.ThresholddBVmLabel.Text{2} = sprintf('(%s):', app.Unit);
                
            else
                downYLim = 0;
                upYLim   = 100;
                
                auxStr = 'Ocupação (%)';
                app.Unit = '%';
            end
            set(app.axes1, 'XLim', [app.specData(app.traceInfo.SelectedNode).MetaData.FreqStart, app.specData(app.traceInfo.SelectedNode).MetaData.FreqStop], 'YLim', [downYLim, upYLim]);            
            ylabel(app.axes1, auxStr);
            
% %          Axes 2 (OCC plot)
            if app.Playback_occCheckBox.Value
                set(app.axes2, 'XLim', app.axes1.XLim, 'YLim' , [0, 100]);
                ylabel(app.axes2, 'Ocupação (%)');
            end
            
% %          Axes 2 (WaterFall plot)
            if app.Playback_wfCheckBox.Value
                if diff(app.axes1.YLim) > 100
                    Li = (app.axes1.YLim(1)+diff(app.axes1.YLim)-100);
                    Ls = app.axes1.YLim(2);
                else
                    Li = app.axes1.YLim(1);
                    Ls = app.axes1.YLim(2);
                end
                caxis(app.axes3, [Li Ls])
                colormap(app.axes3, 'jet');
                
                set(app.cBar1, 'Limits', [Li, Ls], 'Ticks', [Li, Ls], 'TickLabels', {num2str(Li), num2str(Ls)});                
                set(app.axes3, 'XLim', app.axes1.XLim);
                ylabel(app.axes3, 'Amostras');
            end
                
        end
        
        
        function Fcn_Plot_StartUp2(app)
            
            app.x = linspace(app.specData(app.traceInfo.SelectedNode).MetaData.FreqStart, ...
                             app.specData(app.traceInfo.SelectedNode).MetaData.FreqStop, ...
                             app.specData(app.traceInfo.SelectedNode).MetaData.DataPoints);
            
% %         Spectrum and its peaks.
            if isempty(app.locs)
                plot(app.axes1, app.x, app.specData(app.traceInfo.SelectedNode).Data{2}(:, app.timeIndex+1), 'Tag', 'ClearWrite', 'Color', app.cTraces(4,:));
            
            else
                plot(app.axes1, app.x, app.specData(app.traceInfo.SelectedNode).Data{2}(:, app.timeIndex+1), 'Tag', 'ClearWrite', 'Color', app.cTraces(4,:), ...
                     'Marker' , '.', 'MarkerIndices', app.locs, "MarkerFaceColor", [0.40,0.73,0.88], "MarkerEdgeColor", [0.40,0.73,0.88], 'MarkerSize', 14);
                
                auxStr = {};
                for ii = 1:length(app.locs)
                    auxStr = [auxStr {['  ' num2str(ii)]}];
                end
                text(app.axes1, app.x(app.locs), double(app.specData(app.traceInfo.SelectedNode).Data{2}(app.locs, app.timeIndex+1)), auxStr, "Color", [0.40,0.73,0.88], "FontSize", 8, "FontWeight", "bold", "FontName", 'Calibri', "FontSmoothing", "on");
                
                app.Playback_peakTree.SelectedNodes = app.Playback_peakTree.Children(1);
                app.line3  = images.roi.Line(app.axes1,'Position',[app.x(app.locs(1)) app.axes1.YLim(1); app.x(app.locs(1)) app.axes1.YLim(2)], ...
                                             'Color', [0.40,0.73,0.88], 'MarkerSize', 4, 'Deletable', 0, 'LineWidth', .5, 'InteractionsAllowed', 'translate', "Tag", 'mkrROI');
                
                addlistener(app.line3, 'MovingROI', @app.mkrLineROI);
                addlistener(app.line3, 'ROIMoved',  @app.mkrLineROI);
                
                app.Playback_peakCF.Value = round(app.line3.Position(1)/1e+6, 3);
                            
            end
            
% %         Trace processing.   
            auxIndex1 = find(app.traceInfo.Mode ~= 0);
            if ~isempty(auxIndex1)
                if app.Playback_traceRadio1.Value
                    for ii = auxIndex1                        
                        switch ii
                            case 1; auxStr = 'MinHold';
                            case 2
                                switch app.traceInfo.Mode(2)
                                    case 2; auxStr = 'Median';
                                    case 3; auxStr = 'Mean';
                                end
                            case 3; auxStr = 'MaxHold';
                        end
                        plot(app.axes1, app.x, app.specData(app.traceInfo.SelectedNode).statsData(:, app.traceInfo.Mode(ii)), 'Tag', auxStr, 'Color', app.cTraces(ii,:));
                        
                    end
                    
                else
                    for ii = auxIndex1
                        switch ii
                            case 1; auxStr = 'MinHold';
                            case 2; auxStr = 'Mean';
                            case 3; auxStr = 'MaxHold';
                        end
                        plot(app.axes1, app.x, app.specData(app.traceInfo.SelectedNode).Data{2}(:, app.timeIndex+1), 'Tag', auxStr, 'Color', app.cTraces(ii,:));
                        
                    end
                end
            end

% %         OCC.    
            if app.Playback_occCheckBox.Value
                if isempty(findobj('Type', 'line', 'Tag', 'thrOCC'))
                    if strcmp(app.specData(app.traceInfo.SelectedNode).MetaData.metaString{1}, 'dBm') && (app.Playback_occValue.Value > 0)
                        app.ValordBmLabel.Text      = 'Valor (dBm):';
                        app.Playback_occValue.Value = -80;

                    elseif strcmp(app.specData(app.traceInfo.SelectedNode).MetaData.metaString{1}, 'dBμV') && (app.Playback_occValue.Value < 0)
                        app.ValordBmLabel.Text      = 'Valor (dBμV):';
                        app.Playback_occValue.Value = 27;
                        
                    elseif strcmp(app.specData(app.traceInfo.SelectedNode).MetaData.metaString{1}, 'dBμV/m') && (app.Playback_occValue.Value < 0)
                        app.ValordBmLabel.Text      = 'Valor (dBμV/m):';
                        app.Playback_occValue.Value = 40;
                        
                    end
                    
                    if app.Playback_occValue.Value > app.axes1.YLim(2)
                        app.Playback_occValue.Value = double(app.axes1.YLim(2));
                    end
                    
                    switch app.Playback_occType.Value
                        case 'Linear'
                            app.line2  = images.roi.Line(app.axes1,'Position',[app.x(1) app.Playback_occValue.Value; app.x(end) app.Playback_occValue.Value], ...
                                                                   'Color', 'red', 'MarkerSize', 4, 'Deletable', 0, 'LineWidth', 1, 'InteractionsAllowed', 'translate', "Tag", 'occROI');
                            app.label1 = text(app.axes1, app.x(end), app.Playback_occValue.Value, ' thrOCC', 'FontName', 'Calibri', 'FontSize', 9, 'Color', 'red');
                            
                            addlistener(app.line2, 'MovingROI', @app.occLineROI);
                            addlistener(app.line2, 'ROIMoved',  @app.occLineROI);
                            
                        case 'Piso de ruído (Offset)'
                            switch app.Playback_occFcn.Value
                                case 'Mediana'      ; auxIndex2 = 2;
                                case 'Média (vídeo)'; auxIndex2 = 3;
                            end
                            app.occTHR = app.specData(app.traceInfo.SelectedNode).statsData(:, auxIndex2)';
                            
                            auxMatrix = sort(app.specData(app.traceInfo.SelectedNode).Data{2});
                            auxMatrix = auxMatrix(1:ceil(app.Playback_occSamples.Value * app.specData(app.traceInfo.SelectedNode).MetaData.DataPoints / 100), :);
                            noiseMean = mean(auxMatrix,    'all');
                            noiseStd  =  std(auxMatrix, 1, 'all');
                            
                            switch app.Playback_occConfidenceLevel.Value
                                case '68'; auxValue = 1;
                                case '95'; auxValue = 2;
                                case '99'; auxValue = 3;
                            end
                            app.occTHR(app.occTHR < noiseMean - auxValue*noiseStd) = noiseMean - auxValue*noiseStd;
                            app.occTHR(app.occTHR > noiseMean + auxValue*noiseStd) = noiseMean + auxValue*noiseStd;
                            
                            app.occTHR = app.occTHR + app.Playback_occOffset.Value;
                            
                            plot(app.axes1, app.x, app.occTHR, 'Tag', 'thrOCC', 'Color', 'red', 'LineStyle', '-', 'LineWidth', 1, ...
                                                               'Marker', 'o', 'MarkerSize', 4, 'MarkerIndices', [1, app.specData(app.traceInfo.SelectedNode).MetaData.DataPoints], ...
                                                               'MarkerFaceColor', 'r', 'MarkerEdgeColor', 'black');
                            app.label1 = text(app.axes1, app.x(end), double(app.occTHR(end)), ' thrOCC', 'FontName', 'Calibri', 'FontSize', 9, 'Color', 'red');
                    end
                    
                    plot(app.axes2, app.x, zeros(1, length(app.axes1.Children(end).YData), 'single'), 'Tag', 'maxOCC', 'Color', app.cTraces(3,:));   % MaxHold
                    plot(app.axes2, app.x, zeros(1, length(app.axes1.Children(end).YData), 'single'), 'Tag', 'medOCC', 'Color', app.cTraces(2,:));   % Média
                end
            end
            
% %         WaterFall.    
            if app.Playback_wfCheckBox.Value
                if isempty(findobj('Type', 'Surface', 'Tag', 'WaterFall'))
                    [auxRows, auxColumns] = size(app.specData(app.traceInfo.SelectedNode).Data{2});      
                    auxSize = auxRows .* auxColumns;
                    if auxSize > 1474560
                        Decimate = ceil(auxSize/1474560);
                    else
                        Decimate = 1;
                    end
                    
                    t = 1:Decimate:app.specData(app.traceInfo.SelectedNode).Samples;
                    if t(1) == t(end)
                        t(end) = t(1)+0.01;
                    end
                                                            
% %                 DateTimeRuler como opção a ser explorada.
%                     t = app.specData(app.traceInfo.SelectedNode).Data{1}(1:Decimate:end);
%                     if t(1) == t(end)
%                         t(end) = t(1)+seconds(1);
%                     end
                    
                    [X, Y] = meshgrid(app.x, t);
                    mesh(app.axes3, X, Y, app.specData(app.traceInfo.SelectedNode).Data{2}(:,1:Decimate:end)', 'Tag', 'WaterFall')
                    view(app.axes3, 0, 90);
                    
% %                 Já foram implantadas duas soluções. A line e a Cuboid (ROI). Ambas apresentaraão lentidão apenas p/ alguns fluxos de dados, não sendo compreendida
% %                 a relação entre a lentidão e os parâmetros DataPoints, Samples e Decimate.
%                     app.line1 = images.roi.Cuboid(app.axes3, 'Position', [app.specData(app.traceInfo.SelectedNode).MetaData.FreqStart, app.timeIndex+1, app.axes3.ZLim(2), ...
%                                 (app.specData(app.traceInfo.SelectedNode).MetaData.FreqStop - app.specData(app.traceInfo.SelectedNode).MetaData.FreqStart), 0, 0], ...
%                                 'Color', 'red');
%                    line(app.axes3, [x(1) x(end)], [t(1) t(1)], [app.axes1.YLim(2) app.axes1.YLim(2)], 'Color', 'red', 'LineWidth', 1);
                    
                    set(app.axes3, 'YLim' , [t(1); t(end)], 'YTick', [t(1); t(end)], 'YTickLabel', [1; app.specData(app.traceInfo.SelectedNode).Samples]);
                end
            end
            drawnow nocallbacks

            app.axesFlag = 0;
            
        end
        
        
        function Fcn_Traces(app)

            set(app.Playback_traceRadioGroup2.Children, 'Enable', 0)
            
            % median and mean
            if app.Playback_traceCheckBox1.Value
                ind1 = find(strcmp(app.Playback_peakTrace.Items, 'Average'), 1);
                if isempty(ind1)
                    app.Playback_peakTrace.Items{end+1} = 'Average';
                end
                
                if app.Playback_traceRadio1.Value
                    app.Playback_traceRadio3.Enable = 1;
                    app.Playback_traceRadio4.Enable = 1;
                else
                    app.Playback_traceRadio4.Value  = 1;
                    app.Playback_traceRadio4.Enable = 1;
                    
                    app.FatordeintegraoLabel.Enable = 1;
                    app.Playback_traceFactor.Enable = 1;
                end
                
                switch app.Playback_traceRadioGroup2.SelectedObject.Text
                    case 'Mediana'
                        app.traceInfo.Mode(2) = 2;
                    case 'Média (vídeo)'
                        app.traceInfo.Mode(2) = 3;
                end
            else
                ind1 = find(strcmp(app.Playback_peakTrace.Items, 'Average'), 1);
                if ~isempty(ind1)
                    app.Playback_peakTrace.Items(ind1) = [];
                end
                
                app.traceInfo.Mode(2) = 0;
            end
            
            % MinHold
            if app.Playback_traceCheckBox2.Value
                ind1 = find(strcmp(app.Playback_peakTrace.Items, 'MinHold'), 1);
                if isempty(ind1)
                    app.Playback_peakTrace.Items{end+1} = 'MinHold';
                end
                
                app.traceInfo.Mode(1) = 1;
            else
                ind1 = find(strcmp(app.Playback_peakTrace.Items, 'MinHold'), 1);
                if ~isempty(ind1)
                    app.Playback_peakTrace.Items(ind1) = [];
                end
                
                app.traceInfo.Mode(1) = 0;
            end

            % MaxHold
            if app.Playback_traceCheckBox3.Value
                ind1 = find(strcmp(app.Playback_peakTrace.Items, 'MaxHold'), 1);
                if isempty(ind1)
                    app.Playback_peakTrace.Items{end+1} = 'MaxHold';
                end
                
                app.traceInfo.Mode(3) = 4;
            else
                ind1 = find(strcmp(app.Playback_peakTrace.Items, 'MaxHold'), 1);
                if ~isempty(ind1)
                    app.Playback_peakTrace.Items(ind1) = [];
                end
                
                app.traceInfo.Mode(3) = 0;
            end
            
        end
        
        
        function Layout_Plot(app)
            
            if app.Playback_occCheckBox.Value && app.Playback_wfCheckBox.Value
                app.traceInfo.plotLayout = 3;
            elseif app.Playback_wfCheckBox.Value
                app.traceInfo.plotLayout = 2;
            elseif app.Playback_occCheckBox.Value
                app.traceInfo.plotLayout = 1;
            else
                app.traceInfo.plotLayout = 0;
            end
            
            Layout_PlotOCC(app)
            
        end
        
        
        function Layout_PlotOCC(app)
            
            if app.Playback_occCheckBox.Value
                Playback_occTypeValueChanged(app)
                
                app.ThresholdDropDownLabel.Enable = 1;
                app.Playback_occType.Enable        = 1;
                
            else
                set(app.Playback_occGrid1.Children, 'Enable', 0)
                set(app.Playback_occGrid2.Children, 'Enable', 0)
            end
            
        end
        
        
        function occLineROI(app, src, event)
            
            switch(event.EventName)
                case{'MovingROI'}
                    app.Playback_occValue.Value = event.CurrentPosition(1,2);
                    app.label1.Position(2)      = event.CurrentPosition(1,2);
                    
                case{'ROIMoved'}
                    app.axes2.Children(1).YData = zeros(1, length(app.axes1.Children(end).YData), 'single');
                    app.axes2.Children(2).YData = zeros(1, length(app.axes1.Children(end).YData), 'single');
            end
            
        end
        
        
        function mkrLineROI(app, src, event)
            
            switch(event.EventName)
                case{'MovingROI'}
                    auxValue1 = length(app.Playback_peakTree.SelectedNodes);
                    if  auxValue1 == 0
                        auxIndex1 = 1;
                    elseif auxValue1 == 1
                        auxIndex1 = app.Playback_peakTree.SelectedNodes.NodeData(1);
                    end
                    
                    auxValue2 = app.line3.Position(1);                      % New frequency.
                    app.Playback_peakCF.Value = round(auxValue2/1e+6, 3);
        
                    auxIndex2 = round((auxValue2 - app.bCoef)/app.aCoef);   % New frequency index.
                    auxValue3 = app.axes1.Children(end).YData(auxIndex2);   % Level related to new frequency.
                    
                    app.axes1.Children(end).MarkerIndices(auxIndex1) = auxIndex2;
                    app.axes1.Children(end-auxIndex1).Position(1:2)  = [auxValue2, auxValue3];
                    
                    set(app.Playback_peakTree.Children(auxIndex1), 'Text', sprintf("M%d: %.3f MHz @ %.1f %s", auxIndex1, app.Playback_peakCF.Value, auxValue3, app.Unit), ...
                                                        'NodeData', [auxIndex1, app.traceInfo.SelectedNode, auxIndex2]);
                    
                case{'ROIMoved'}
                    auxValue1 = length(app.Playback_peakTree.SelectedNodes);
                    if  auxValue1 == 0
                        auxIndex1 = 1;
                    elseif auxValue1 == 1
                        auxIndex1 = app.Playback_peakTree.SelectedNodes.NodeData(1);
                    end
                    auxValue2 = app.line3.Position(1);
                    auxIndex2 = round((auxValue2 - app.bCoef)/app.aCoef);
                    
                    app.locs(auxIndex1) = auxIndex2;
                    
            end
            
        end
        
        

        function [dateStatus, auxDate1, auxDate2] = Fcn_ReportGenerator_Aux1(app)
            
            app.reportInfo = struct('Issue',    [], 'Version',   '', 'CheckBoxes', [], ...
                                    'freeText', [], 'TimeStamp', []);
            
            app.reportInfo.Issue      = app.Report_Issue.Value;
            app.reportInfo.Version    = app.Report_Version.Value;
            app.reportInfo.CheckBoxes = [app.Report_CheckBox1.Value, ...
                                         app.Report_CheckBox2.Value, ...
                                         app.Report_CheckBox3.Value];
            
            if app.Report_CheckBox3.Value
                if isempty(app.htmlSet)
                    app.htmlSet = Misc_htmlSet('htmlEntityBasicSet.txt');
                end
                
                htmlStr = [];
                for ii = 1:length(app.Report_freeText.Value)
                    htmlStr = [htmlStr '<p class="Item_Nivel2">' app.Report_freeText.Value{ii} '</p>'];
                end
                
                for ii = 2:size(app.htmlSet, 1)
                    htmlStr = replace(htmlStr, app.htmlSet.Char{ii}, app.htmlSet.Entity{ii});
                end
                app.reportInfo.freeText = htmlStr;
            end
            
            auxDate1 = app.Report_DatePicker1.Value;
            auxDate1.Format = "dd/MM/yyyy HH:mm:ss";
            auxDate1 = auxDate1 + hours(app.Report_Spinner1.Value) + minutes(app.Report_Spinner2.Value);
            
            auxDate2 = app.Report_DatePicker2.Value;
            auxDate2.Format = "dd/MM/yyyy HH:mm:ss";
            auxDate2 = auxDate2 + hours(app.Report_Spinner3.Value) + minutes(app.Report_Spinner4.Value);
            
            if auxDate1 > auxDate2
                dateStatus = 'Os valores inseridos para aplicação do filtro de TimeStamp devem ser ajustados!';
                return
            else
                dateStatus = '';
            end
            app.reportInfo.TimeStamp = [auxDate1, auxDate2];
            
        end
        
        
        function ReportProject = Fcn_ReportGenerator_Aux2(app, filteredRows)
            
            global ReportProject
            
            ReportProject = struct('Latitude',        [], ...
                                   'Longitude',       [], ...
                                   'City',            [], ...
                                   'F0',              [], ...
                                   'F1',              [], ...
                                   'emissionsValue1', [], ...
                                   'emissionsValue2', [], ...
                                   'Services',        [], ...
                                   'tableJournal',    []);
            
            for ii = 1:numel(filteredRows)
                if ismember(app.specData(ii).MetaData.DataType, app.DataTypes)
                    if isempty(ReportProject.Latitude)
                        ReportProject.Latitude  = app.specData(filteredRows(ii)).gps.Latitude;
                        ReportProject.Longitude = app.specData(filteredRows(ii)).gps.Longitude;
                        ReportProject.City      = app.specData(filteredRows(ii)).gps.Location;
                        
                        ReportProject.F0 = app.specData(filteredRows(ii)).MetaData.FreqStart;
                        ReportProject.F1 = app.specData(filteredRows(ii)).MetaData.FreqStop;
                    
                    else
                        ReportProject.F0 = min(ReportProject.F0, app.specData(filteredRows(ii)).MetaData.FreqStart);
                        ReportProject.F1 = max(ReportProject.F1, app.specData(filteredRows(ii)).MetaData.FreqStop);
                    end
                end
            end
            
            
            % Juntar numa mesma variável a informação gerada pelo algoritmo
            % embarcado no appAnálise (app.peaksTable) com a informação
            % gerada pelo fiscal (app.exceptionList). Restrito ao campo
            % "regulatoryInfo", que pode assumir os valores 'Licenciada',
            % 'Não licenciada' e 'Não passível de licenciamento'.
            if ~isempty(app.peaksTable)
                countTable = app.peaksTable(:,[1, 4, 9, 10]);
                
                for ii = 1:height(app.exceptionList)
                    ind = find(countTable.Truncated == app.exceptionList.CF(ii));
                    
                    if ~isempty(ind)
                        countTable.regulatoryInfo{ind} = app.exceptionList.regulatoryInfo{ii};
                    end
                end
                
                
                % Criação da variável.
                infoTable1 = table('Size', [0, 5], 'VariableTypes', {'cell', 'int16', 'int16', 'int16', 'int16'});
                infoTable1.Properties.VariableNames = {'Faixa de frequência',          ...
                                                      'Qtd. emissões',                 ...
                                                      'Licenciada',                    ...
                                                      'Não licenciada',                ...
                                                      'Não passível de licenciamento'};
                
                
                % Organização dos dados (tableJournal).
                Bands = unique(countTable.Band, 'stable');            
                for ii = 1:numel(Bands)
                    infoTable1(ii,:) = {Bands{ii} 0 0 0 0};
                    
                    for jj = 1:height(countTable)
                        if ~strcmp(countTable.Band(jj), Bands(ii))
                            continue
                        end
                        
                        infoTable1.("Qtd. emissões")(ii) = infoTable1.("Qtd. emissões")(ii) + 1;
                        switch countTable.regulatoryInfo{jj}
                            case 'Licenciada'
                                infoTable1.("Licenciada")(ii) = infoTable1.("Licenciada")(ii) + 1;
                            case 'Não licenciada'
                                infoTable1.("Não licenciada")(ii) = infoTable1.("Não licenciada")(ii) + 1;
                            case 'Não passível de licenciamento'
                                infoTable1.("Não passível de licenciamento")(ii) = infoTable1.("Não passível de licenciamento")(ii) + 1;
                        end
                    end
                end
                ReportProject.emissionsValue1 = sum(infoTable1.("Qtd. emissões"));
                ReportProject.emissionsValue2 = sum(infoTable1.("Não licenciada"));
                ReportProject.tableJournal{1} = infoTable1;
                
                for ii = 1:height(countTable)
                    if ~strcmp(countTable.regulatoryInfo{ii}, 'Licenciada')
                        continue
                    end
                    str1 = extractBefore(countTable.Description{ii}, ', ');
                    str2 = extractBetween(countTable.Description{ii}, ', ', ', ');
                    str2 = str2{1}(1);
                    
                    if contains(str1, '-')
                        str1 = extractBefore(str1, '-');
                    else
                        if strcmp(str1, 'RADCOM')
                            str2 = 'RADCOM';
                        else
                            str2 = 'Não definido';
                        end
                    end
                    countTable.Service(ii) = {str1};
                    countTable.Class(ii)   = {str2};
                end
                Services = unique(countTable(strcmp(countTable.regulatoryInfo, {'Licenciada'}),:).Service, 'stable');
                
                % Tabela específica p/ o PRD-M.
                if app.Report_CheckBox2.Value
                    infoTable2 = table('Size', [0, 7], 'VariableTypes', {'cell', 'int16', 'int16', 'int16', 'int16', 'int16', 'int16'});
                    infoTable2.Properties.VariableNames = {'Serviço', 'Especial', 'A', 'B', 'C', 'RADCOM', 'Não definido'};
                    
                    for ii = 1:numel(Services)
                        infoTable2(ii,:) = {Services{ii} 0 0 0 0 0 0};
                        
                        for jj = 1:height(countTable)
                            if ~strcmp(countTable.Service(jj), Services(ii))
                                continue
                            end
                            
                            switch countTable.Class{jj}
                                case 'E';            infoTable2.Especial(ii)         = infoTable2.Especial(ii) + 1;
                                case 'A';            infoTable2.A(ii)                = infoTable2.A(ii) + 1;
                                case 'B';            infoTable2.B(ii)                = infoTable2.B(ii) + 1;
                                case 'C';            infoTable2.C(ii)                = infoTable2.C(ii) + 1;
                                case 'RADCOM';       infoTable2.RADCOM(ii)           = infoTable2.RADCOM(ii) + 1;
                                case 'Não definido'; infoTable2.("Não definido")(ii) = infoTable2.("Não definido")(ii) + 1;
                            end
                        end
                    end
                    ReportProject.tableJournal{2} = infoTable2;
                    
                end
                
                if isempty(Services)
                    if app.Report_CheckBox1.Value
                        ReportProject.Services = {'019', '507', '230', '231'};
                    elseif app.Report_CheckBox2.Value
                        ReportProject.Services = {'230', '231', '247', '248', '800', '801'};
                    else
                        ReportProject.Services = {'019'};                    
                    end
                    
                else
                    if any(contains(Services, 'FM')) | any(contains(Services, 'RADCOM'))
                        ReportProject.Services = {'230', '231'};
                    end
                    
                    if any(contains(Services, 'TV'))
                        ReportProject.Services = [ReportProject.Services, '247', '248', '800', '801'];
                    end
                    
                    for ii = 1:numel(Services)
                        switch Services{ii}
                            case {'FM', 'RADCOM', 'TV'}
                                continue
                            otherwise
                                ReportProject.Services = [ReportProject.Services, Services{ii}];
                        end
                    end
                end
            end
            
        end
        
        
       function Fcn_ReportClassValueChanged_Aux1(app)
            
            switch app.Report_peakClass.Value
                case 'Radiodifusão (FM)'
                    app.Report_Label8.Text = ['Emissões com alta ocupação, cujas portadoras ' ...
                                              'são moduladas em frequência e ocupam canais '  ...
                                              'espaçados em 200 kHz.'];
                    
                case 'Radiodifusão (TV)'
                    app.Report_Label8.Text = ['Emissões com alta ocupação, cujas portadoras '     ...
                                              'são moduladas por sinais analógicos ou digitais. ' ...
                                              'Canais espaçados em 6 MHz.'];
                    
                case 'Radiocomunicação'
                    app.Report_Label8.Text = ['Radiocomunicação. Portadoras moduladas ' ...
                                              'em amplitude ou frequência, ocupando '   ...
                                              'canais espaçados em 25 kHz.'];
                case 'Genérico'
                    app.Report_Label8.Text = 'Não definido.';
            end
            
        end
        
        
        function Fcn_ReportClassValueChanged_Aux2(app)
            
            ind = app.Tree3.SelectedNodes.NodeData;
            
            app.specData(ind).report_peakInfo.minDistance       = app.Report_peakDistance.Value * 1000;
            app.specData(ind).report_peakInfo.minBW             = app.Report_peakBW.Value * 1000;
            app.specData(ind).report_peakInfo.minProeminence_C1 = app.Report_peakProeminence_C1.Value;
            app.specData(ind).report_peakInfo.minProeminence_C2 = app.Report_peakProeminence_C2.Value;
            app.specData(ind).report_peakInfo.statsFcn          = app.Report_peakFcn.Value;
            app.specData(ind).report_peakInfo.minOCC            = app.Report_peakOCC.Value;
            
        end


        function Layout_mainMenu(app)
            
            set(app.menu_Grid1.Children, 'Enable', 1)
            
            app.Button_Playback.Value = 1;
            mainMenu_Playback(app)

            Layout_mainInfo_startup(app)
            Layout_ModeOperation(app)
            
        end
        
        
        function Layout_mainInfo_startup(app)
            
%             set(app.mainInfo_Grid.Children, 'Enable', 0)
%             app.mainInfo_Label1.Enable    = 1;
%             app.mainInfo_Tree.Enable      = 1;
%             app.mainInfo_Label2.Enable    = 1;
%             app.mainInfo_Label3.Enable    = 1;
%             app.mainInfo_DeleteAll.Enable = 1;
            
            app.mainInfo_Warning.Visible = 0;
            
            app.mainInfo_Expand.ImageSource  = 'ExpandA1.png';
%             app.mainInfo_Expand.Enable       = 1;
            
            app.mainInfo_Text.Value = '';
            
        end
        
        
        function Layout_Report_startup(app)
            
            % occPanel
            set(app.Report_Grid7.Children, 'Enable', 0)
            set(app.Report_Grid8.Children, 'Enable', 0)
            
            % peakPanel
            set(app.Report_Grid9.Children,  'Enable', 0)
            set(app.Report_Grid10.Children, 'Enable', 0)
            set(app.Report_Label9,          'Enable', 0)
            set(app.Report_Grid11.Children, 'Enable', 0)
            
        end
        
        
        function SaveFile_Fcn1(app)
        
            [filename, pathname, index] = uiputfile({'*.bin',   'CRFS Logger (*.bin)';            ...
                                                     '*.sm1809', 'UIT SM1809 (*.sm1809)'},    ...
                                                     'Selecione a pasta e o nome do arquivo', ...
                                                     ['appAnalise_' datestr(now,'yymmdd_THHMMSS')]);
            
            if index
                % Validações. 
                % (1) O arquivo no CRFS BIN e no SM1809 deve ser obrigatoriamente gerado por um único 
                % sensor. Isso porque a informação de GPS é única (ou num bloco comum a todos os fluxos, 
                % no caso do CRFS BIN, ou no cabeçalho, no caso do SM1809).
                % E, além disso, o SM1809 não aceita fluxos de ocupação.
                
                selectedNode = app.mainInfo_Tree.SelectedNodes;
                filteredRows = [];
                
                if isempty(selectedNode)
                    uialert(app.MainApp, 'Selecione ao menos um fluxo de dados.', 'Alerta!', 'Icon', 'warning');
                    return
                    
                elseif numel(selectedNode) == 1
                    if isnumeric(selectedNode.NodeData)
                        filteredRows = selectedNode.NodeData;
                    else
                        [~, filteredRows] = find(cellfun(@(x) strcmp(x, selectedNode.NodeData), {app.specData.Node}));
                    end
                    
                else
                    for ii = 1:numel(selectedNode)
                        if isnumeric(selectedNode(ii).NodeData)
                            ind = selectedNode(ii).NodeData;
                        else
                            [~, ind] = find(cellfun(@(x) strcmp(x, selectedNode(ii).NodeData), {app.specData.Node}));
                        end
                        filteredRows = [filteredRows, ind];
                    end
                    filteredRows = unique(filteredRows);
                    
                    Node = app.specData(filteredRows(1)).Node;
                    for ii = 1:length(filteredRows)
                        if ~strcmp(app.specData(filteredRows(ii)).Node, Node)
                            uialert(app.MainApp, 'Os fluxos de dados devem estar relacionados ao mesmo sensor.', 'Alerta!', 'Icon', 'warning');
                            return
                        end
                        
                        if ismember(app.specData(filteredRows(ii)).MetaData.DataType, [62, 65, 69]) & (index == 2)
                            uialert(app.MainApp, 'O formato SM1809 não aceita fluxos de ocupação.', 'Alerta!', 'Icon', 'warning');
                            return
                        end
                        
                    end
                end
                
                % (2) A informação de GPS é obrigatória e deve ser editada no próprio appAnálise antes da 
                % criação do arquivo CRFS BIN ou SM1809.
                if ~app.specData(filteredRows(1)).gps.Status
                    uialert(app.MainApp, 'Obrigatória a informação de GPS.', 'Alerta!', 'Icon', 'warning');
                    return
                end
                
                uiprogressdlg(app.MainApp, 'Indeterminate', 'on', 'Message', 'Em andamento a escrita do arquivo.');
                
                filename = fullfile(pathname, filename);                
                switch index
                    case 1; FileWriter_CRFSBin(app.specData(filteredRows), filename);
                    case 2; FileWriter_SM1809(app.specData(filteredRows), min([app.specData(filteredRows).Samples]), filename);
                end
            end
        
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
                
            Flag = 1;
            
            % Project/App Path and UserPath
            prjPath = matlab.project.rootProject;
            appPath = sprintf('%s\\appAnalise_v1', char(com.mathworks.appmanagement.MlappinstallUtil.getAppInstallationFolder));
            
            if ~isempty(prjPath) & strcmp(prjPath.Name, 'appAnalise')
                app.RootFolder          = char(prjPath.RootFolder);
                app.menu_userPath.Items = {[app.RootFolder '\Temp']};
                
            elseif isfolder(appPath)
                app.RootFolder = appPath;
                
                username = getenv('USERNAME');
                if isfolder(sprintf('C:\\Users\\%s\\Downloads', username))
                    userPath = sprintf('C:\\Users\\%s\\Downloads', username);
                else
                    userPath = 0;
                    while ~userPath
                        userPath = uigetdir('', 'Escolha a pasta de trabalho');
                    end
                end
                app.menu_userPath.Items = {userPath};
                
            else
                Flag = 0;
                
                auxStr = 'O appAnálise somente poderá ser executado se o projeto "appAnalise.prj" estiver aberto ou instalado no Matlab.';
                uialert(app.MainApp, auxStr, 'Alerta!', 'Icon', 'error', 'CloseFcn', @(~,~)startupError(app));
            end
            
            
            if Flag
                %% Visibilidade dos elementos principais do app.
                app.mainInfo_Warning.Visible = 0;
                    
                %% Busca pela planilha com a base de dados.                
                fileList = dir([app.RootFolder '\DataBase']);
                filename = extractBetween([fileList.name], 'AnatelDB', 'xlsx', 'Boundaries', 'inclusive');
                if isempty(filename)
                    app.dbFlag = 0;
                    
                    auxStr = sprintf(['A planilha que consolida extração de bases de dados da Anatel (Mosaico, SRD e STEL) ' ...
                                     'não foi encontrada na pasta "%s".\n\nFormato do nome do arquivo:\n'        ...
                                     '"AnatelDB_YYYYMMDD_THHMMSS.xlsx".'], [app.RootFolder '\DataBase']);
                    uialert(app.MainApp, auxStr, 'Alerta!', 'Icon', 'warning')
                    
                else
                    app.dbFlag = 1;
                end
                                
                
                %% Posição da janela principal do app.
                mainMonitor = get(0, 'MonitorPositions');
                mainMonitor = mainMonitor(mainMonitor(:,1) == 1,:);
                
                if height(mainMonitor) > 1
                    mainMonitor(2:end,:) = [];
                end
                
                if mainMonitor(3) >= 1016; x0 = 1016;
                else;                      x0 = MainMonitor(3);
                end
                
                if mainMonitor(4) > 768; y0 = (mainMonitor(4) - 726);
                else;                    y0 = 42;
                end
                app.MainApp.Position = [1, y0, x0, 696];
                
                
                % Figure, axes
                close(findobj('Type', 'Figure', '-not', {'Name', 'appColeta', '-or', 'Name', 'appAnálise'}))
                
                if mainMonitor(3) > x0 + 852
                    x1 = x0+6;
                else
                    x1 = (mainMonitor(3)-852);                
                end
                
                app.fig   = figure('Name', 'appAnálise: Playback', 'NumberTitle', 'off',   ...
                                   'Position', [x1, app.MainApp.Position(2), 852, 622],    ...
                                   'MenuBar', 'none', 'ToolBar', 'none', 'Visible', 'off', ...
                                   'Color', [0.94,0.94,0.94],                              ...
                                   'CloseRequestFcn', "set(gcf, 'Visible', 'off')");
    
                app.axes1 = axes(app.fig, 'Box', 'on', 'Color', [0,0,0], 'FontName', 'Calibri', 'FontSize', 9, 'FontSmoothing', 'on', ...
                                          'XColor', [0,0,0], 'XGrid', 'on', 'XMinorGrid', 'on',  ...
                                          'YColor', [0,0,0], 'YGrid', 'on', 'YMinorGrid', 'on',  ...
                                          'XTickLabel', {}, ...
                                          'GridAlpha'     , .25, 'GridColor'     , [0.94,0.94,0.94], ...
                                          'MinorGridAlpha', .2 , 'MinorGridColor', [0.94,0.94,0.94], ...
                                          'Unit', 'normalized', 'InnerPosition', [.08 .1 .85 .82],   ...
                                          'TitleHorizontalAlignment', 'left', 'Toolbar', []);
                
                set(app.axes1.Title,    'FontSize', 10, 'FontWeight',   'bold', 'Color', [0,0,0], 'FontSmoothing', 'on');
                set(app.axes1.Subtitle, 'FontSize',  9, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on');
                set(app.axes1.XLabel,   'FontSize',  9, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on');
                set(app.axes1.YLabel,   'FontSize',  9, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on');
                
                
                app.axes2 = axes(app.fig, 'Box', 'on', 'Color', [0,0,0], 'FontName', 'Calibri', 'FontSize', 9, 'FontSmoothing', 'on', ...
                                          'XColor', [0,0,0], 'XGrid', 'on', 'XMinorGrid', 'on',  ...
                                          'YColor', [0,0,0], 'YGrid', 'on', 'YMinorGrid', 'on',  ...
                                          'XTickLabel', {}, ...
                                          'GridAlpha', .25, 'GridColor', [0.94,0.94,0.94],  ...
                                          'MinorGridAlpha', .2 , 'MinorGridColor', [0.94,0.94,0.94], ...
                                          'Unit', 'normalized', 'InnerPosition', [0 0 0 0], ...
                                          'Layer', 'top', 'Toolbar', []);
                
                set(app.axes2.XLabel,   'FontSize',  9, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on');
                set(app.axes2.YLabel,   'FontSize',  9, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on');
                
                app.axes3 = axes(app.fig, 'Box', 'on', 'Color', 'none', 'FontName', 'Calibri', 'FontSize', 9, 'FontSmoothing', 'on', ...
                                          'XColor', [0,0,0], 'YColor', [0,0,0], ...
                                          'XTickLabel', {}, ...
                                          'Unit', 'normalized', 'InnerPosition', [0 0 0 0], 'Toolbar', []);
                
                set(app.axes3.XLabel,   'FontSize',  9, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on');
                set(app.axes3.YLabel,   'FontSize',  9, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on');
                
                app.axes1.XAxis.Exponent = 6;
                app.axes2.XAxis.Exponent = 6;
                app.axes3.XAxis.Exponent = 6;
                
                app.cBar1 = colorbar('Location', 'manual', 'Units', 'normalized', 'Position', [0.935, 0.1, 0.01, 0.1], ...
                                     'AxisLocation', 'out', 'FontSize', 9, 'Color', [0,0,0]);
                
                app.line1 = annotation(app.fig, 'line', 'X', [.08 .93], 'Y', [.1 .1], 'Position', [.08 .1 0 0], 'LineWidth', .5,'Color', 'red');
                
                hold(app.axes1, 'on')
                hold(app.axes2, 'on')
                hold(app.axes3, 'on')
                
                disableDefaultInteractivity(app.axes1)
                disableDefaultInteractivity(app.axes2)
                disableDefaultInteractivity(app.axes3)
            end
            
        end

        % Close request function: MainApp
        function closeRequest(app, event)
            
            delete(app.auxWin1)
            delete(app.auxWin2)
            
            delete(app.fig)
            delete(findobj('Type', 'Figure'))
            
            delete(app)
            
        end

        % Value changed function: Button_Home
        function mainMenu_Home(app, event)
            
            if app.Button_Home.Value
                app.Button_File.Value     = 0;
                app.Button_Playback.Value = 0;
                app.Button_Report.Value   = 0;
                app.Button_EditInfo.Value = 0;
                app.Button_SaveFile.Value = 0;
            else
                app.Button_Home.Value     = 1;
                return
            end
            
            Layout_ModeOperation(app)
            
        end

        % Value changed function: Button_File
        function mainMenu_File(app, event)

            if app.Button_File.Value
                app.Button_Home.Value     = 0;
                app.Button_Playback.Value = 0;
                app.Button_Report.Value   = 0;
                app.Button_EditInfo.Value = 0;
                app.Button_SaveFile.Value = 0;
            else
                app.Button_File.Value     = 1;
                return
            end
            
            Layout_ModeOperation(app)
            
        end

        % Value changed function: Button_Playback
        function mainMenu_Playback(app, event)
            
            if app.Button_Playback.Value
                app.Button_Home.Value     = 0;
                app.Button_File.Value     = 0;
                app.Button_Report.Value   = 0;
                app.Button_EditInfo.Value = 0;
                app.Button_SaveFile.Value = 0;
            else
                app.Button_Playback.Value = 1;
                return
            end
            
            Layout_ModeOperation(app)
            
        end

        % Value changed function: Button_Report
        function mainMenu_Report(app, event)
            
            if app.Button_Report.Value
                app.Button_Home.Value     = 0;
                app.Button_File.Value     = 0;
                app.Button_Playback.Value = 0;
                app.Button_EditInfo.Value = 0;
                app.Button_SaveFile.Value = 0;
            else
                app.Button_Report.Value   = 1;
                return
            end
            
            Layout_ModeOperation(app)
            
        end

        % Value changed function: Button_EditInfo
        function mainMenu_EditInfo(app, event)
            
            if app.Button_EditInfo.Value
                app.Button_Home.Value     = 0;
                app.Button_File.Value     = 0;
                app.Button_Playback.Value = 0;
                app.Button_Report.Value   = 0;
                app.Button_SaveFile.Value = 0;
            else
                app.Button_EditInfo.Value = 1;
                return
            end
            
            Layout_ModeOperation(app)
            
        end

        % Value changed function: Button_SaveFile
        function mainMenu_SaveFile(app, event)
            
            if app.Button_SaveFile.Value
                app.Button_Home.Value     = 0;
                app.Button_File.Value     = 0;
                app.Button_Playback.Value = 0;
                app.Button_Report.Value   = 0;
                app.Button_EditInfo.Value = 0;
            else
                app.Button_SaveFile.Value = 1;
                return
            end
            
            Layout_ModeOperation(app)
            
        end

        % Button pushed function: File_Button1
        function File_Button1Pushed(app, event)
            
            [Files, Path, Index] = uigetfile({'*.bin',           'appColeta, Logger (*.bin)'; ...
                                              '*.sm1809',        'SM1809 (*.sm1809)';         ...
                                              '*.csv',           'Argus (*.csv)';             ...
                                              'appAnalise*.mat', 'appAnálise (*.mat)'},       ...
                                              'Selecione um ou mais arquivos', 'MultiSelect', 'on');
            
% %         Modo de operação (app.OperationMode):
% %         - 1: leitura de múltiplos arquivos de espectro nos formatos
% %              BIN, SM1809 e CSV.
% %         - 2: leitura de um único arquivo de projeto de espectro no
% %              formato MAT.
            
            switch Index
                case {1, 2, 3}
                    if ~iscell(Files)
                        Files = {Files};
                    end
                    
                    repeteadFiles = {};
                    switch app.OperationMode
                        case 1
                            if isempty(app.SelectedFiles)
                                app.SelectedFiles = Files;
                                app.FilesPath     = repmat({Path}, 1, length(Files));
                                
                            else
                                for ii = length(Files):-1:1
                                    if any(cellfun(@(x) ~isempty(strfind(Files{ii}, x)), app.SelectedFiles))
                                        repeteadFiles = [repeteadFiles; Files{ii}];
                                        Files(ii)     = [];
                                    end
                                end

                                app.SelectedFiles(end+1:end+length(Files)) = Files;
                                app.FilesPath(end+1:end+length(Files))     = repmat({Path}, 1, length(Files));      
                            end
                        
                        case 2
                            msg = ['Um projeto appAnálise somente pode ser executado isolamente. Ao tentar abrir ' ...
                                   'um ou mais arquivos gerados no appColeta, Logger ou Argus você confirma o fechamento do projeto?'];
                            
                            selection = uiconfirm(app.MainApp, msg, 'Alerta', 'Options', {'OK', 'Cancelar'}, ...
                                                                    'DefaultOption', 1, 'CancelOption', 2);
                            
                            if strcmp(selection, 'Cancelar')
                                return
                            end
                            
                            app.SelectedFiles = Files;
                            app.FilesPath     = repmat({Path}, 1, length(Files));
                            app.metaData      = [];
                    end
                    
%                     try
                        File_metadataReader(app, Index)
%                     catch ME
%                         File_ReadError(app, ME.message)
%                         return
%                     end
                    app.OperationMode = 1;
                    
                    if ~isempty(repeteadFiles)
                        msg = 'Os metadados do(s) arquivo(s) indicado(s) a seguir já tinham sido lidos.';
                        
                        for ii = 1:length(repeteadFiles)
                            msg = sprintf('%s\n• %s', msg, repeteadFiles{ii});
                        end
                        uialert(app.MainApp, msg, 'Alerta!', 'Icon', 'warning');
                    end
                    
                case 4
                    if iscell(Files)
                        Files = Files{1};
                    end
                    
                    if ~isempty(app.metaData)
                        msg = ['Um projeto appAnálise somente poderá ser executado isolamente. ' ...
                               'Confirma o fechamento do(s) outro(s) arquivo(s)?'];
                        
                        selection = uiconfirm(app.MainApp, msg, 'Alerta', 'Options', {'OK', 'Cancelar'}, ...
                                              'DefaultOption', 1, 'CancelOption', 2);
                        
                        if strcmp(selection, 'Cancelar')
                            return
                        end
                    end
                    app.SelectedFiles = {Files};
                    app.FilesPath     = fullfile(Path, Files);
                    app.metaData      = [];

                    try
                        File_ProjectReader(app)
                    catch ME
                        File_ReadError(app, ME.message)
                        return
                    end
                    app.OperationMode = 2;
                    
            end
            File_Tree1_Building(app)
            
        end

        % Button pushed function: File_Button2
        function File_Button2Pushed(app, event)
            
            d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on');
            
            if ~isempty(app.filtData)
                app.filtData = [];
                delete(app.Tree3.Children);
            end
            
            app.traceInfo.SelectedNode     = 1;
            app.timeIndex                  = 0;
            app.peaksTable                 = [];
            app.Report_specDataSave.Enable = 0;
            app.Report_tableView.Enable    = 0;
            
%             try
                Fcn_SpecDataReader(app, d)
%             catch ME
%                 File_specReadError(app, ME.message)
%                 
%                 app.specData = [];
%                 delete(app.mainInfo_Tree.Children);
%                 set(app.mainInfo_Grid.Children, 'Enable', 0)
%                 Layout_Report_startup(app)
% 
%                 return
%             end
            
            Fcn_Tree2(app)
            Layout_mainMenu(app)
            Layout_Report_startup(app)
            
            if ~isempty(app.Playback_peakTree.Children)
                app.locs = [];
                delete(app.Playback_peakTree.Children)
            end
            
        end

        % Selection changed function: File_Tree1
        function File_Tree1SelectionChanged(app, event)
            
            if numel(app.File_Tree1.SelectedNodes) == 1
                File_Tree1SelectionChanged_Aux(app, 'On')
                
                ind1 = app.File_Tree1.SelectedNodes.NodeData;
                
                app.File_Format.Value = app.metaData(ind1).Data(1).FileFormat;
                app.File_Node.Value   = app.metaData(ind1).Data(1).Node;
                
                if ~isempty(app.metaData(ind1).Data(1).gps.Location)
                    set(app.File_City, 'Enable', 1, 'Value', app.metaData(ind1).Data(1).gps.Location)
                else
                    set(app.File_City, 'Enable', 0, 'Value', '(desconhecido)')
                end
                
                if ~isempty(app.metaData(ind1).Data(1).ObservationTime)
                    set(app.File_ObservationTime, 'Enable', 1, 'Value', app.metaData(ind1).Data(1).ObservationTime)
                else
                    set(app.File_ObservationTime, 'Enable', 0, 'Value', '(pendente de implementação)')
                end
                
                File_Tree2_Building(app)
            
            else
                File_Tree1SelectionChanged_Aux(app, 'Off')
            end            
            
        end

        % Selection changed function: File_Tree2
        function File_Tree2SelectionChanged(app, event)
            
            if numel(app.File_Tree2.SelectedNodes) == 1
                File_Tree2SelectionChanged_Aux(app, 'On')
                
                ind1 = app.File_Tree1.SelectedNodes.NodeData;
                ind2 = app.File_Tree2.SelectedNodes.NodeData;
                
                app.File_StepWidth.Value  = ((app.metaData(ind1).Data(ind2).MetaData.FreqStop - app.metaData(ind1).Data(ind2).MetaData.FreqStart) ./ ...
                                            (app.metaData(ind1).Data(ind2).MetaData.DataPoints - 1)) .* 1e-3;
                app.File_DataPoints.Value = app.metaData(ind1).Data(ind2).MetaData.DataPoints;
                
                if isempty(app.metaData(ind1).Data(ind2).MetaData.metaString{4})
                    app.File_Operation.Value = app.metaData(ind1).Data(ind2).MetaData.metaString{3};
                else
                    app.File_Operation.Value = sprintf('%s (Modo), %s (Detector)',                           ...
                                                       app.metaData(ind1).Data(ind2).MetaData.metaString{3}, ...
                                                       app.metaData(ind1).Data(ind2).MetaData.metaString{4});
                end
                
                if ~isempty(app.metaData(ind1).Data(ind2).MetaData.Resolution)
                    set(app.File_Resolution, 'Enable', 1, 'Value', app.metaData(ind1).Data(ind2).MetaData.Resolution ./ 1e+3, 'ValueDisplayFormat', '%.3f')
                else
                    set(app.File_Resolution, 'Enable', 0, 'Value', -1, 'ValueDisplayFormat', '%d')
                end
                
                app.File_LevelUnit.Value       = app.metaData(ind1).Data(ind2).MetaData.metaString{1};
                app.File_Description.Value     = app.metaData(ind1).Data(ind2).Description;
                
                set(app.File_Label4_2, 'Visible', 0)
                if ~isempty(app.metaData(ind1).Data(ind2).Samples)
                    app.File_Samples.Value         = app.metaData(ind1).Data(ind2).Samples;
                    app.File_EstimatedMemory.Value = 4 .* app.metaData(ind1).Data(ind2).Samples .* app.metaData(ind1).Data(ind2).MetaData.DataPoints .* 1e-6; % float32 (4 bytes)
                else
                    app.File_Samples.Value         = 1;
                    app.File_EstimatedMemory.Value = 4 .* app.metaData(ind1).Data(ind2).MetaData.DataPoints .* 1e-6; % float32 (4 bytes)
                    
                    set(app.File_Label4_2, 'Visible', 1, 'Text', ['Pendente de implementação que possibilite que nessa fase da leitura dos metadados do arquivo ' ...
                                                                  'seja obtido o número de traços.'])
                end
                
                if app.File_Samples.Value == 65536
                    set(app.File_Label4_2, 'Visible', 1, 'Text', ['Como foi atingido o limite do número de traços por fluxo de dados (65536) é provável que o ' ...
                                                                  'arquivo selecionado possua outras informações deste fluxo.'])
                end
                
            else
                File_Tree2SelectionChanged_Aux(app, 'Off')
            end
            
        end

        % Menu selected function: File_ContextMenu1A
        function File_Tree1Delete(app, event)
            
            ind1 = [];
            for ii = 1:numel(app.File_Tree1.SelectedNodes)
                ind1 = [ind1, app.File_Tree1.SelectedNodes(ii).NodeData];
            end
            
            app.SelectedFiles(ind1) = [];
            app.FilesPath(ind1)     = [];
            app.metaData(ind1)      = [];
            
            if isempty(app.SelectedFiles)
                app.metaData            = [];
                app.File_Button2.Enable = 0;
                app.File_Memory.Value   = 0;
            else
                app.File_Memory.Value = sum([app.metaData.Memory]);
            end
            
            File_Tree1_Building(app)
                        
        end

        % Menu selected function: File_ContextMenu2A
        function File_Tree2Delete(app, event)
            
            ind1 = app.File_Tree1.SelectedNodes.NodeData;
            
            ind2 = [];
            for ii = 1:numel(app.File_Tree2.SelectedNodes)
                ind2 = [ind2, app.File_Tree2.SelectedNodes(ii).NodeData];
            end
            
            if numel(app.metaData(ind1).Data) > numel(ind2)
                app.metaData(ind1).Data(ind2)    = [];
                app.metaData(ind1).Samples(ind2) = [];
            else
                msg = 'O arquivo selecionado deve possuir ao menos um fluxo de dados.';
                uialert(app.MainApp, msg, 'Alerta!', 'Icon', 'warning')
            end
            
            app.metaData(ind1).Memory = File_metadataReader_Aux(app, ind1);
            app.File_Memory.Value     = sum([app.metaData.Memory]);
            
            File_Tree2_Building(app)
            
        end

        % Image clicked function: mainInfo_Expand
        function mainInfo_ExpandCollapseTreeNodes(app, event)
            
            if strcmpi(app.mainInfo_Expand.ImageSource, 'ExpandA1.png')
                for ii = 1:numel(app.mainInfo_Tree.Children)
                    expand(app.mainInfo_Tree.Children(ii));
                end
                app.mainInfo_Expand.ImageSource = 'Colapse0A.png';
                
            else
                collapse(app.mainInfo_Tree, 'all');
                app.mainInfo_Expand.ImageSource = 'ExpandA1.png';
            end
            
        end

        % Image clicked function: menu_searchPath
        function General_searchPathImageClicked(app, event)
            
            newUserPath = uigetdir(app.menu_userPath.Value, 'Escolha a pasta de trabalho');
            
            if newUserPath
                ind = find(strcmp(app.menu_userPath.Items, newUserPath));
                
                if isempty(ind)
                    app.menu_userPath.Items(end+1) = {newUserPath};
                    app.menu_userPath.Value        = newUserPath;
                else
                    app.menu_userPath.Value        = app.menu_userPath.Items(ind);
                end
            end
            
        end

        % Selection changed function: Tree3
        function Tree3_SelectionChanged(app, event)
            
            app.mainInfo_Tree.SelectedNodes = [];
            
            app.Report_addButton.Enable    = 0;
            app.Report_deleteButton.Enable = 1;
            
            ind = app.Tree3.SelectedNodes.NodeData;
            if (numel(app.Tree3.SelectedNodes) == 1) && isnumeric(ind)
                
                % peakInfo
                set(app.Report_Grid9.Children,  'Enable', 1)
                set(app.Report_Grid10.Children, 'Enable', 1)
                set(app.Report_Label9,          'Enable', 1)
                set(app.Report_Grid11.Children, 'Enable', 1)
                
                if contains(app.specData(ind).report_peakInfo.Band, 'FM')
                    app.Report_peakClass.Value = 'Radiodifusão (FM)';
                elseif contains(app.specData(ind).report_peakInfo.Band, 'TV')
                    app.Report_peakClass.Value = 'Radiodifusão (TV)';
                elseif contains(app.specData(ind).report_peakInfo.Band, 'Aircraft')
                    app.Report_peakClass.Value = 'Radiocomunicação';
                else
                    app.Report_peakClass.Value = 'Genérico';
                end
                Fcn_ReportClassValueChanged_Aux1(app)
                
                app.Report_peakDistance.Value       = app.specData(ind).report_peakInfo.minDistance / 1000;
                app.Report_peakBW.Value             = app.specData(ind).report_peakInfo.minBW / 1000;
                app.Report_peakProeminence_C1.Value = app.specData(ind).report_peakInfo.minProeminence_C1;
                app.Report_peakProeminence_C2.Value = app.specData(ind).report_peakInfo.minProeminence_C2;
                app.Report_peakFcn.Value            = app.specData(ind).report_peakInfo.statsFcn;
                app.Report_peakOCC.Value            = app.specData(ind).report_peakInfo.minOCC;
                
                % occInfo
                if isfield(app.specData(ind), 'report_occInfo') && ~isempty(app.specData(ind).report_occInfo)
                    
                    Report_occTypeValueChanged(app)
                    
                    app.Report_occTime.Value = app.specData(ind).report_occInfo.Time;
                    switch app.Report_occType.Value
                        case 'Linear fixo'
                            app.Report_occValue.Value = app.specData(ind).report_occInfo.Value;
                            
                        case 'Linear adaptativo'
                            app.Report_occOffset.Value = app.specData(ind).report_occInfo.Offset;
                            app.Report_occFcn.Value    = app.specData(ind).report_occInfo.statsFcn;
                            app.Report_tSamples.Value  = app.specData(ind).report_occInfo.Samples(1);
                            app.Report_uSamples.Value  = app.specData(ind).report_occInfo.Samples(2);
                    
                        case 'Piso de ruído (Offset)'
                            app.Report_occOffset.Value    = app.specData(ind).report_occInfo.Offset;
                            app.Report_occConfLevel.Value = app.specData(ind).report_occInfo.ConfLevel;
                            app.Report_occFcn.Value       = app.specData(ind).report_occInfo.statsFcn;
                            app.Report_tSamples.Value     = app.specData(ind).report_occInfo.Samples(1);
                            app.Report_uSamples.Value     = app.specData(ind).report_occInfo.Samples(2);
                    end
                    
                else
                    set(app.Report_Grid7.Children, 'Enable', 0)
                    set(app.Report_Grid8.Children, 'Enable', 0)
                end
                
            else
                % occPanel
                set(app.Report_Grid7.Children, 'Enable', 0)
                set(app.Report_Grid8.Children, 'Enable', 0)
                
                % peakPanel
                set(app.Report_Grid9.Children,  'Enable', 0)
                set(app.Report_Grid10.Children, 'Enable', 0)
                set(app.Report_Label9,          'Enable', 0)
                set(app.Report_Grid11.Children, 'Enable', 0)
                
            end
            
        end

        % Selection changed function: Playback_peakTree
        function Tree4_SelectionChanged(app, event)
            
            auxValue = length(app.Playback_peakTree.SelectedNodes);
            if auxValue == 0
                app.FrequnciacentralLabel.Enable = 0;
                app.Playback_peakCF.Enable   = 0;
                app.Playback_peakUnit.Enable = 0;
                
                app.Playback_deleteMarker.Enable = 0;
                
            elseif auxValue == 1
                app.FrequnciacentralLabel.Enable = 1;
                app.Playback_peakCF.Value    = str2double(extractBetween(app.Playback_peakTree.SelectedNodes.Text, ": ", " MHz"));
                app.Playback_peakCF.Enable   = 1;
                app.Playback_peakUnit.Enable = 1;
                
                app.Playback_deleteMarker.Enable = 1;
                
                app.line3.Position(:, 1) = repmat(app.x(app.Playback_peakTree.SelectedNodes.NodeData(3)), 2, 1);
            
            else
                app.FrequnciacentralLabel.Enable = 0;
                app.Playback_peakCF.Enable   = 0;
                app.Playback_peakUnit.Enable = 0;
                
                app.Playback_deleteMarker.Enable = 1;
                
            end
            
        end

        % Button pushed function: Info_Button
        function Info_ButtonPushed(app, event)
            
            uiprogressdlg(app.MainApp, 'Indeterminate', 'on', 'Message', 'Em andamento a edição e a organização dos fluxos de dados.');
            
            selectedNodes = app.mainInfo_Tree.SelectedNodes;
            
            if app.specData(selectedNodes.NodeData).gps.Status <= 0
                app.specData(selectedNodes.NodeData).gps.Status    = -1;
                app.specData(selectedNodes.NodeData).gps.Latitude  = app.Info_Latitude.Value;
                app.specData(selectedNodes.NodeData).gps.Longitude = app.Info_Longitude.Value;
                app.specData(selectedNodes.NodeData).gps.Location  = app.Info_City.Value;
            end
            
            if app.Info_CheckBox.Value
                for ii = 1:length(app.specData)
                    if ii == selectedNodes.NodeData
                        continue
                    end
                    
                    if strcmp(app.specData(selectedNodes.NodeData).Node, app.specData(ii).Node) & (app.specData(ii).gps.Status <= 0)
                        app.specData(ii).gps.Status    = -1;
                        app.specData(ii).gps.Latitude  = app.Info_Latitude.Value;
                        app.specData(ii).gps.Longitude = app.Info_Longitude.Value;
                        app.specData(ii).gps.Location  = app.Info_City.Value;
                    end
                end
            end
            
            if ~isempty(app.specData(selectedNodes.NodeData).occRelated)
                if strcmp(app.Info_OCC.Items{1}, app.Info_OCC.Value)
                    app.specData(selectedNodes.NodeData).occFlag = [];
                else
                    auxIndex = find(strcmp(app.Info_OCC.Items, app.Info_OCC.Value))-1;
                    app.specData(selectedNodes.NodeData).occFlag = app.specData(selectedNodes.NodeData).occRelated(auxIndex);
                end
            end
            
            Fcn_Tree2(app)
            Layout_ModeOperation(app)
            
        end

        % Button pushed function: Playback_Play
        function Playback_playButton(app, event)

            % Playback.
            app.Playback_Slider.Enable = 1;
            if ~app.plotFlag
                app.plotFlag = 1;
                
                app.Playback_Play.Text = 'Parar escrita';
                app.Playback_Play.Icon = 'Media_PauseWhite.png';
                
                % Menu principal e painéis 1 e 2
                set(app.menu_Grid1.Children, 'Enable', 0);
                
                app.Playback_addMarker.Enable = 1;
                drawnow nocallbacks
                
                Fcn_Plot(app)
            
            else
                app.plotFlag = 0;
                
                app.Playback_Play.Text = 'Iniciar escrita';
                app.Playback_Play.Icon = 'Media_PlayWhite.png';
                
                set(app.menu_Grid1.Children, 'Enable', 1)
                
                if ~isempty(app.SelectedFiles)
                    app.File_Button2.Enable = 1;
                end
                
                app.Playback_addMarker.Enable = 0;
                drawnow nocallbacks
            end
            
        end

        % Button pushed function: Playback_Reload
        function Playback_ReloadPushed(app, event)
            
            if app.reloadFlag
                app.Playback_Reload.Icon = 'notreloadIcon.png';
                app.reloadFlag = 0;
            else
                app.Playback_Reload.Icon = 'reloadIcon.png';
                app.reloadFlag = 1;
            end
            
        end

        % Value changed function: Playback_Menu
        function Playback_chartEdit(app, event)

            if app.Playback_Menu.Value
                set(app.fig, 'MenuBar', 'figure')
                pan(app.fig, 'off')
                zoom(app.fig, 'off')
                
                app.Playback_Hand.Value = 0;
                app.Playback_Zoom.Value = 0;
            else
                set(app.fig, 'MenuBar', 'none')
            end
            
        end

        % Value changed function: Playback_occCheckBox
        function Playback_occCheckBoxValueChanged(app, event)
            
            Layout_Plot(app)
            
            if app.plotFlag
                app.axesFlag = 1;
            end
            
        end

        % Value changing function: Playback_Slider
        function Playback_SliderValueChanging(app, event)
            
            app.timeIndex = round(event.Value/100 * app.specData(app.traceInfo.SelectedNode).Samples);
            
            if app.timeIndex < 1
                app.timeIndex = 1;
            elseif app.timeIndex > app.specData(app.traceInfo.SelectedNode).Samples
                app.timeIndex = app.specData(app.traceInfo.SelectedNode).Samples;
            end
            
            app.axes1.Subtitle.String = datestr(app.specData(app.traceInfo.SelectedNode).Data{1}(app.timeIndex), 'dd/mm/yyyy HH:MM:SS');
            app.axes1.Children(end).YData = app.specData(app.traceInfo.SelectedNode).Data{2}(:, app.timeIndex)';
            
            if app.traceInfo.plotLayout == 2
                app.line1.Position(2) = .1 + (.4/(app.specData(app.traceInfo.SelectedNode).Samples-1))*(app.timeIndex-1);
            elseif app.traceInfo.plotLayout == 3
                app.line1.Position(2) = .1 + (.3/(app.specData(app.traceInfo.SelectedNode).Samples-1))*(app.timeIndex-1);
            end
            drawnow
            
        end

        % Selection changed function: Playback_traceRadioGroup1
        function Playback_integrationTypeCallback(app, event)
            
            app.Playback_traceCheckBox1.Value = 0;
            app.Playback_traceCheckBox2.Value = 0;
            app.Playback_traceCheckBox3.Value = 0;
            
            app.Playback_traceRadio3.Enable = 0;
            app.Playback_traceRadio4.Enable = 0;
            
            app.Playback_traceFactor.Enable = 0;
            
            Fcn_Traces(app)
            
            Playback_ValueChanged(app)
            
        end

        % Callback function: Playback_traceCheckBox1, 
        % Playback_traceCheckBox2, Playback_traceCheckBox3, 
        % Playback_traceFactor, Playback_traceRadioGroup2
        function Playback_axesFlagCallback(app, event)
            
            Fcn_Traces(app)
            
            Playback_ValueChanged(app)
            
        end

        % Value changed function: Playback_occType
        function Playback_occTypeValueChanged(app, event)
            
            app.FatordeintegraoLabel_2.Enable = 1;
            app.Playback_occTime.Enable = 1;
            
            switch app.Playback_occType.Value
                case 'Linear'
                    app.ValordBmLabel.Enable      = 1;
                    app.Playback_occValue.Enable = 1;
                    
                    app.FunodetendnciacentralLabel_3.Enable = 0;
                    app.OffSetLabel.Enable         = 0;
                    app.AmostrasteisLabel_2.Enable = 0;
                    app.NveldeconfianaLabel.Enable = 0;
                    
                    app.Playback_occFcn.Enable     = 0;
                    app.Playback_occOffset.Enable  = 0;
                    app.Playback_occSamples.Enable = 0;
                    app.Playback_occConfidenceLevel.Enable = 0;
                    
                    app.Playback_occLabel.Enable = 0;
                    
                case 'Piso de ruído (Offset)'
                    app.ValordBmLabel.Enable     = 0;
                    app.Playback_occValue.Enable = 0;
                    
                    app.FunodetendnciacentralLabel_3.Enable = 1; 
                    app.OffSetLabel.Enable         = 1;
                    app.AmostrasteisLabel_2.Enable = 1;
                    app.NveldeconfianaLabel.Enable = 1;
                    
                    app.Playback_occFcn.Enable     = 1;
                    app.Playback_occOffset.Enable  = 1;
                    app.Playback_occSamples.Enable = 1;
                    app.Playback_occConfidenceLevel.Enable = 1;
                    
                    app.Playback_occLabel.Enable = 1;
            end
            
            Playback_ValueChanged(app)
            
        end

        % Button pushed function: Button_fiscaliza
        function AJUSTARNOME_Fiscaliza(app, event)
            
            if isempty(app.auxWin2)
                app.auxWin2 = winFiscaliza(app);
            end
            
        end

        % Value changed function: Playback_occConfidenceLevel, 
        % Playback_occFcn, Playback_occOffset, Playback_occSamples, 
        % Playback_occTime, Playback_occValue
        function Playback_ValueChanged(app, event)

            if app.plotFlag
                app.axesFlag = 1;
            end
            
        end

        % Value changed function: Playback_wfCheckBox
        function Playback_wfCheckBoxValueChanged(app, event)
            
            Layout_Plot(app)
            
            if app.plotFlag
                if event.PreviousValue
                    Fcn_Plot_Layout2(app)
                else
                    app.axesFlag = 1;
                end
            end
            
        end

        % Selection changed function: mainInfo_Tree
        function mainInfo_Tree_SelectionChanged(app, event)
            
            selectedNodes = app.mainInfo_Tree.SelectedNodes;
            
            % Caixa de texto com metadados.
            value1 = numel(selectedNodes);
            if value1 == 1 && isnumeric(selectedNodes.NodeData)
                app.mainInfo_Text.Enable = 1;
                
                string1 = {sprintf('Formato: %s\nScript: "%s"\nDescrição: "%s"',  ...
                           app.specData(selectedNodes.NodeData).FileFormat,      ...
                           app.specData(selectedNodes.NodeData).TaskName,       ...
                           app.specData(selectedNodes.NodeData).Description); ''; ...
                           sprintf('Pontos por traço: %d\nUnidade: %s\nOperação: %s', ...
                           app.specData(selectedNodes.NodeData).MetaData.DataPoints,       ...
                           app.specData(selectedNodes.NodeData).MetaData.metaString{1},  ...
                           app.specData(selectedNodes.NodeData).MetaData.metaString{3})};
                
                if ~isempty(app.specData(selectedNodes.NodeData).MetaData.metaString{4})
                    string1{3} = sprintf('%s (Modo), %s (Detector)', string1{3}, app.specData(selectedNodes.NodeData).MetaData.metaString{4});
                end
                
                if ~isempty(app.specData(selectedNodes.NodeData).MetaData.metaString{2})
                    string1{3} = sprintf('%s\nResolução: %s', string1{3}, app.specData(selectedNodes.NodeData).MetaData.metaString{2});
                end
                
                string1{4} = sprintf('Antena: %s', app.specData(selectedNodes.NodeData).MetaData.metaString{5});
                
                app.mainInfo_Text.Value  = string1;
                
            else
                app.mainInfo_Text.Enable = 0;
                app.mainInfo_Text.Value  = '';
            end
            
            
            % Especificidades de cada funcionalidade.
            app.Report_addButton.Enable = 1;
            if app.Button_Report.Value 
                app.Tree3.SelectedNodes = [];
                
                app.Report_deleteButton.Enable = 0;
                
            elseif app.Button_Playback.Value
                Layout_Playback(app)
                
                if app.plotFlag
                    app.Playback_addMarker.Enable = 1;
                    if isnumeric(app.mainInfo_Tree.SelectedNodes.NodeData)
                        app.axesFlag = 1;
                    end
                    
                else
                    if isnumeric(app.mainInfo_Tree.SelectedNodes.NodeData)                        
                        app.Playback_Play.Enable = 1;
                    else
                        app.Playback_Play.Enable = 0;
                    end
                end
                
            elseif app.Button_EditInfo.Value
                if isnumeric(app.mainInfo_Tree.SelectedNodes.NodeData)
                    Layout_Info(app, 'on')
                else
                    app.mainInfo_Tree.SelectedNodes  = [];
                    Layout_Info(app, 'off')
                end
            end
            
        end

        % Selection changed function: Playback_peakRadioGroup
        function Playback_RadioGroupSelectionChanged(app, event)
            
            switch app.Playback_peakRadioGroup.SelectedObject.Text
                case 'Inclusão manual'
                    set(app.Playback_peakGrid.Children, 'Enable', 0);

                case 'Inclusão automática (busca de picos)'
                    set(app.Playback_peakGrid.Children, 'Enable', 1);
                    
                    app.TraoDropDownLabel.Enable  = 0;                      % Pendente implementação que possibilite
                    app.Playback_peakTrace.Enable = 0;                      % identificar picos num traço específico.
            end
            
        end

        % Image clicked function: Playback_addMarker
        function Playback_addMarkerPushed(app, event)

            app.Span  = (app.specData(app.traceInfo.SelectedNode).MetaData.FreqStop - app.specData(app.traceInfo.SelectedNode).MetaData.FreqStart);
            app.aCoef = app.Span ./ (app.specData(app.traceInfo.SelectedNode).MetaData.DataPoints - 1);
            app.bCoef = app.specData(app.traceInfo.SelectedNode).MetaData.FreqStart - app.aCoef;
            
            if app.Playback_peakRadio1.Value
                app.locs = [round(app.specData(app.traceInfo.SelectedNode).MetaData.DataPoints/2); app.locs];
                
            else
                [~, auxIndex] = findpeaks(app.specData(app.traceInfo.SelectedNode).Data{2}(:, app.timeIndex+1), ...
                                          'NPeaks',            app.Playback_peakNumbers.Value, ...
                                          "MinPeakHeight",     app.Playback_peakTHR.Value, ...
                                          'MinPeakProminence', app.Playback_peakProeminence.Value, ...
                                          "MinPeakDistance",   1000*app.Playback_peakDistance.Value/app.aCoef, ...
                                          "MinPeakWidth",      1000*app.Playback_peakBW.Value/app.aCoef, ...
                                          "SortStr",           "descend");
                
                app.locs = [auxIndex; app.locs];
            end
            
            delete(app.Playback_peakTree.Children)
            Fcn_Tree4(app)
            
            if app.plotFlag
                app.axesFlag = 1;
            end
            
        end

        % Image clicked function: Playback_deleteMarker
        function Playback_deleteMarkerPushed(app, event)
            
            for ii = length(app.Playback_peakTree.SelectedNodes):-1:1
                app.locs(app.Playback_peakTree.SelectedNodes(ii).NodeData(1)) = [];
            end
            
            delete(app.Playback_peakTree.Children)
            if ~isempty(app.locs)
                Fcn_Tree4(app)
            else
                app.Playback_deleteMarker.Enable = 0;
                
                app.FrequnciacentralLabel.Enable = 0;
                app.Playback_peakCF.Enable   = 0;
                app.Playback_peakUnit.Enable = 0;
            end
            
            if app.plotFlag
                app.axesFlag = 1;
            end
            
        end

        % Value changed function: Playback_peakCF
        function Playback_peakCFValueChanged(app, event)
            
            if (app.Playback_peakCF.Value*1e+6 < app.specData(app.traceInfo.SelectedNode).MetaData.FreqStart) || ...
               (app.Playback_peakCF.Value*1e+6 > app.specData(app.traceInfo.SelectedNode).MetaData.FreqStop)
            
               app.Playback_peakCF.Value = str2double(extractBetween(app.Playback_peakTree.SelectedNodes.Text, ": ", " MHz"));
               return
            end
            
            auxIndex1 = app.Playback_peakTree.SelectedNodes.NodeData(1);
            auxIndex2 = round((app.Playback_peakCF.Value*1e+6 - app.bCoef)/app.aCoef);    % Index of the new frequency.
            
            auxValue1 = app.axes1.Children(end).YData(auxIndex2);                   % Level related to the new frequency.
            
            app.locs(auxIndex1) = auxIndex2;
            app.axes1.Children(end).MarkerIndices(auxIndex1) = auxIndex2;
            app.axes1.Children(end-auxIndex1).Position(1:2)  = [app.Playback_peakCF.Value*1e+6, auxValue1];
            
            app.Playback_peakCF.Value = round(app.x(auxIndex2)/1e+6, 3);
            
            set(app.Playback_peakTree.Children(auxIndex1), 'Text', sprintf("M%d: %.3f MHz @ %.1f %s", auxIndex1, app.Playback_peakCF.Value, auxValue1, app.Unit), ...
                                                'NodeData', [auxIndex1, app.traceInfo.SelectedNode, auxIndex2]);
            
            app.line3.Position(:,1) = repmat(app.x(auxIndex2), 2, 1);


        end

        % Value changed function: Playback_Hand
        function Playback_PanInterativity(app, event)
            
            if app.Playback_Hand.Value
                pan(app.fig, 'on')
                zoom(app.fig, 'off')
                
                app.Playback_Zoom.Value = 0;
            else
                pan(app.fig, 'off')
            end
            
        end

        % Value changed function: Playback_Zoom
        function Playback_ZoomInterativity(app, event)
            
            if app.Playback_Zoom.Value
                pan(app.fig, 'off')
                zoom(app.fig, 'on')
                
                app.Playback_Hand.Value = 0;
            else
                zoom(app.fig, 'off')
            end
            
        end

        % Button pushed function: Playback_FullScreen
        function Playback_FullScreenMode(app, event)
            
            pan(app.fig, 'off')
            zoom(app.fig, 'off')
            
            switch app.fig.WindowState
                case 'normal'
                    app.fig.WindowState = 'fullscreen';
                    app.Playback_FullScreen.Icon = 'minimizar.png';
                case 'fullscreen'
                    app.fig.WindowState = 'normal';
                    app.Playback_FullScreen.Icon = 'telacheia.png';
            end
            
        end

        % Button pushed function: Report_playButton
        function Report_playButtonPushed(app, event)
            
            app.Report_specDataSave.Enable = 0;
            app.Report_tableView.Enable    = 0;
            
            %% Nova verificação da existência da planilha com a base de dados...
            if ~app.dbFlag
                fileList = dir([char(app.RootFolder) '\DataBase']);
                filename = extractBetween([fileList.name], 'AnatelDB', 'xlsx', 'Boundaries', 'inclusive');
                
                if isempty(filename)
                    auxStr = sprintf(['A planilha que consolida extração de bases de dados da Anatel (Mosaico, SRD e STEL) ' ...
                                     'não foi encontrada na pasta "%s".\n\nFormato do nome do arquivo:\n'        ...
                                     '"AnatelDB_YYYYMMDD_THHMMSS.xlsx".'], [app.RootFolder '\DataBase']);
                    uialert(app.MainApp, auxStr, 'Alerta!', 'Icon', 'warning')
                else
                    app.dbFlag = 1;
                end
            end
            
            
            % Geração de relatório.
            if app.dbFlag
                [dateStatus, auxDate1, auxDate2] = Fcn_ReportGenerator_Aux1(app);
                                
                if isempty(dateStatus)
                    d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on');
                    d.Message = sprintf(['Em andamento a elaboração do relatório de monitoração, o que inclui diversas manipulações ' ...
                                         'nos dados, como, por exemplo, a busca de emissões, a comparação com a base de dados da Agência ' ...
                                         'e a geração de plots.']);
                    
                    filteredRows = sort([app.filtData; unique([app.specData(app.filtData).occFlag]')]);
                    
                    auxValue1 = length([app.specData(app.filtData).occFlag]);
                    if auxValue1
                        auxValue2 = zeros(auxValue1, 2);
                        
                        jj = 0;
                        for ii = 1:length(filteredRows)
                            if ~isempty(app.specData(filteredRows(ii)).occFlag)
                                jj=jj+1;
                                auxValue2(jj,:) = [filteredRows(ii), app.specData(filteredRows(ii)).occFlag];
                                app.specData(filteredRows(ii)).occFlag = find(filteredRows == auxValue2(jj,2));
                            end
                        end
                    end
                    
                    [Message, app.peaksTable, filename] = ReportGenerator('Report',                    ...
                                                                           app.specData(filteredRows), ...
                                                                           [auxDate1, auxDate2],       ...
                                                                           app.RootFolder,             ...
                                                                           app.reportInfo,             ...
                                                                           app.menu_userPath.Value,    ...
                                                                           app.exceptionList);
                    
                    if ~isempty(app.auxWin1)
                        delete(app.auxWin1)
                        app.auxWin1 = [];
                    end
                    
                    if isempty(Message)
                        if auxValue1
                            for jj = 1:size(auxValue2, 1)
                                app.specData(auxValue2(jj,1)).occFlag = auxValue2(jj,2);
                            end
                        end
                        
                        if strcmp(app.reportInfo.Version, 'Definitiva')
                            ReportProject = Fcn_ReportGenerator_Aux2(app, filteredRows);
                            
                            filename = replace(filename, '.html', '.mat');
                            save(filename, 'ReportProject', '-mat', '-v7.3')
                        end
                        app.Report_specDataSave.Enable = 1;
                        app.Report_tableView.Enable    = 1;
                        
                    else
                        uialert(app.MainApp, Message, 'Alerta!', 'Icon', 'error')
                    end
                
                else
                    uialert(app.MainApp, dateStatus, 'Alerta!', 'Icon', 'error')
                end
            end
            
        end

        % Button pushed function: Report_previewButton
        function Report_previewButtonPushed(app, event)
             
            app.Report_specDataSave.Enable = 0;
            app.Report_tableView.Enable    = 0;
            
            %% Nova verificação da existência da planilha com a base de dados...
            if ~app.dbFlag
                fileList = dir([char(app.RootFolder) '\DataBase']);
                filename = extractBetween([fileList.name], 'AnatelDB', 'xlsx', 'Boundaries', 'inclusive');
                
                if isempty(filename)
                    auxStr = sprintf(['A planilha que consolida extração de bases de dados da Anatel (Mosaico, SRD e STEL) ' ...
                                     'não foi encontrada na pasta "%s".\n\nFormato do nome do arquivo:\n'        ...
                                     '"AnatelDB_YYYYMMDD_THHMMSS.xlsx".'], [app.RootFolder '\DataBase']);
                    uialert(app.MainApp, auxStr, 'Alerta!', 'Icon', 'warning')
                else
                    app.dbFlag = 1;
                end
            end
            
            
            % Geração de prévia do relatório.
            if app.dbFlag
                [dateStatus, auxDate1, auxDate2] = Fcn_ReportGenerator_Aux1(app);
                
                if isempty(dateStatus)
                   d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on');
                    d.Message = sprintf(['Em andamento a busca de emissões, a comparação com a base de dados da Agência ' ...
                                         'e a geração de plots.']);
                    
                    filteredRows = sort([app.filtData; unique([app.specData(app.filtData).occFlag]')]);
                    
                    auxValue1 = length([app.specData(app.filtData).occFlag]);
                    if auxValue1
                        auxValue2 = zeros(auxValue1, 2);
                        
                        jj = 0;
                        for ii = 1:length(filteredRows)
                            if ~isempty(app.specData(filteredRows(ii)).occFlag)
                                jj=jj+1;
                                auxValue2(jj,:) = [filteredRows(ii), app.specData(filteredRows(ii)).occFlag];
                                app.specData(filteredRows(ii)).occFlag = find(filteredRows == auxValue2(jj,2));
                            end
                        end
                    end
                    
                    [Message, app.peaksTable] = ReportGenerator('Preview', app.specData(filteredRows), [auxDate1, auxDate2], app.RootFolder, [], [], []);
                    
                    if ~isempty(app.auxWin1)
                        delete(app.auxWin1)
                        app.auxWin1 = [];
                    end
                    
                    if isempty(Message)
                        if auxValue1
                            for jj = 1:size(auxValue2, 1)
                                app.specData(auxValue2(jj,1)).occFlag = auxValue2(jj,2);
                            end
                        end
                        app.Report_specDataSave.Enable = 1;
                        app.Report_tableView.Enable    = 1;
                    else
                        uialert(app.MainApp, Message, 'Alerta!', 'Icon', 'error')
                    end
                    
                else
                    uialert(app.MainApp, dateStatus, 'Alerta!', 'Icon', 'error')
                end

            end
            
        end

        % Button pushed function: Report_specDataSave
        function Report_specDataSaveValueChanged(app, event)
            
            [filename, pathname, index] = uiputfile({'*.mat', 'Projeto appAnálise (*.mat)'}, ...
                                                    'Selecione a pasta e o nome do arquivo', ...
                                                    fullfile(app.menu_userPath.Value, ['appAnalise_' datestr(now,'yymmdd_THHMMSS')]));
            
            if index
                uiprogressdlg(app.MainApp, 'Indeterminate', 'on', 'Message', 'Em andamento a criação do projeto.');
                
                % specData
                filteredRows = sort([app.filtData; unique([app.specData(app.filtData).occFlag]')]);
                
                auxValue1 = length([app.specData(app.filtData).occFlag]);
                if auxValue1
                    auxValue2 = zeros(auxValue1, 2);
                    
                    jj = 0;
                    for ii = 1:length(filteredRows)
                        if ~isempty(app.specData(filteredRows(ii)).occFlag)
                            jj=jj+1;
                            auxValue2(jj,:) = [filteredRows(ii), app.specData(filteredRows(ii)).occFlag];
                            app.specData(filteredRows(ii)).occFlag = find(filteredRows == auxValue2(jj,2));
                        end
                    end
                end
                
                % reportInfo
                Fcn_ReportGenerator_Aux1(app);
                
                % Estrutura que será salva no arquivo.
                ReportProject = struct('specData',      app.specData(filteredRows), ...
                                       'reportInfo',    app.reportInfo,             ...
                                       'peaksTable',    app.peaksTable,             ...
                                       'exceptionList', app.exceptionList);
                
                %  Será gravado um único fluxo de OCC (caso existente).
                %  Ajuste de "specData.occRelated" p/ contemplar isso.
                if ~isempty([ReportProject.specData.occFlag])
                    for ii = 1:length(ReportProject.specData)
                        ReportProject.specData(ii).occRelated = ReportProject.specData(ii).occFlag;
                    end
                end
                
                filename = fullfile(pathname, filename);
                save(filename, 'ReportProject', '-mat', '-v7.3')
                
                if auxValue1
                    for jj = 1:size(auxValue2, 1)
                        app.specData(auxValue2(jj,1)).occFlag = auxValue2(jj,2);
                    end
                end
            end
            
        end

        % Button pushed function: Report_tableView
        function Report_tableViewValueChanged(app, event)
            
            if isempty(app.auxWin1)
                app.auxWin1 = winTreeEdit(app);
            end
            
        end

        % Value changed function: Report_CheckBox1
        function Report_CheckBox1ValueChanged(app, event)
            
            value = app.Report_CheckBox1.Value;
            
            if ~value && ~app.Report_CheckBox2.Value && ~app.Report_CheckBox3.Value
                app.Report_CheckBox1.Value = 1;  
            end
            
        end

        % Value changed function: Report_CheckBox2
        function Report_CheckBox2ValueChanged(app, event)

            value = app.Report_CheckBox2.Value;
            
            if ~value && ~app.Report_CheckBox1.Value && ~app.Report_CheckBox3.Value
                app.Report_CheckBox2.Value = 1;  
            end
            
        end

        % Value changed function: Report_CheckBox3
        function Report_CheckBox3ValueChanged(app, event)
            
            if app.Report_CheckBox3.Value
                app.Report_freeText.Enable = 1;
            else
                if ~app.Report_CheckBox1.Value && ~app.Report_CheckBox2.Value
                    app.Report_CheckBox3.Value = 1;
                else
                    app.Report_freeText.Enable = 0;
                end
            end
            
        end

        % Image clicked function: Report_addButton
        function Report_addButtonPushed(app, event)
            
            peaksReference = struct('Description', {'TV_VHF1',           'FM',               'Aircraft',        'TV_VHF2',           'TV_UHF'},         ...
                                    'Band',        {[  54e+6  88e+6],    [87.8e+6 108e+6],   [108e+6 137e+6],   [ 174e+6 216e+6],    [ 470e+6 698e+6]}, ...
                                    'Settings',    {[4.45e+6 150e+3 67], [200e+3  15e+3 67], [ 25e+3  10e+3 1], [4.45e+6 150e+3 67], [4.45e+6 150e+3 67]});
            
            auxIndex = [];
            for ii = 1:length(app.mainInfo_Tree.SelectedNodes)
                selectedNodes = app.mainInfo_Tree.SelectedNodes(ii);
                
                if isnumeric(selectedNodes.NodeData)
                    auxIndex = [auxIndex, selectedNodes.NodeData];
                else
                    for jj = 1:length(app.specData)
                        if strcmp(app.specData(jj).Node, selectedNodes.NodeData)
                            auxIndex = [auxIndex, jj];
                        end
                    end
                end
            end
            auxIndex = sort(unique(auxIndex));
            
            % Algumas validações...
            for kk = 1:length(auxIndex)
                if ismember(app.specData(auxIndex(kk)).MetaData.DataType, [62, 65, 69])
                    auxStr = sprintf('É possível a inclusão apenas de fluxo de espectro. O fluxo de ocupação automaticamente será incluso na elaboração do relatório, desde que selecionado no próprio fluxo de espectro.');            
                    uialert(app.MainApp, auxStr, 'Alerta!', 'Icon', 'error');
                    return
                    
                elseif ~app.specData(auxIndex(kk)).gps.Status
                    auxStr = sprintf('Necessária a edição de informações de coordenadas geográficas de fluxo(s) de dado(s).');            
                    uialert(app.MainApp, auxStr, 'Alerta!', 'Icon', 'error');
                    return
                end
                
                if isempty(find(app.filtData == auxIndex(kk), 1))
                    app.filtData = sort([app.filtData; auxIndex(kk)]);
                end
            end
            
            Fcn_Tree3(app)
            
            % Criação dos campos "report_occInfo" e "report_peakInfo", caso
            % aplicável.
            if ~isempty(app.filtData)
                app.Report_playButton.Enable    = 1;
                app.Report_previewButton.Enable = 1;
                
                for ii = 1:numel(app.filtData)
                    ind1 = app.filtData(ii);
                    
                    % peakInfo
                    if isempty(app.specData(ind1).report_peakInfo)
                        app.specData(ind1).report_peakInfo = struct('Band',             '', ...
                                                                  'statsFcn',          '', ...
                                                                  'minProeminence_C1', [], ...
                                                                  'minProeminence_C2', [], ...
                                                                  'minBW',             [], ...
                                                                  'minDistance',       [], ...
                                                                  'minOCC',            []);
                            
                        Band = 'none';
                        FrequencyBand = (app.specData(ind1).MetaData.FreqStart + app.specData(ind1).MetaData.FreqStop)/2;
                        SpanBand      =  app.specData(ind1).MetaData.FreqStop - app.specData(ind1).MetaData.FreqStart;
                        
                        for ll = 1:length(peaksReference)
                            if (FrequencyBand > peaksReference(ll).Band(1)) && (FrequencyBand < peaksReference(ll).Band(2)) && (SpanBand >= 10e+6)
                                Band = peaksReference(ll).Description;
                                ind2 = find(ismember({peaksReference.Description}, {Band}));
                                break
                            end
                        end
                        app.specData(ind1).report_peakInfo.Band              =  Band;
                        app.specData(ind1).report_peakInfo.statsFcn          = 'Mediana';
                        app.specData(ind1).report_peakInfo.minProeminence_C1 = 10;
                        app.specData(ind1).report_peakInfo.minProeminence_C2 = 30;
                            
                        if strcmp(Band, 'none')
                            app.specData(ind1).report_peakInfo.minDistance = 25e+3;
                            app.specData(ind1).report_peakInfo.minBW       = 10e+3;
                            app.specData(ind1).report_peakInfo.minOCC      = 10;
                        else
                            app.specData(ind1).report_peakInfo.minDistance = peaksReference(ind2).Settings(1);
                            app.specData(ind1).report_peakInfo.minBW       = peaksReference(ind2).Settings(2);
                            app.specData(ind1).report_peakInfo.minOCC      = peaksReference(ind2).Settings(3);
                        end        
                    end
                    
                    % occInfo
                    if ~isempty(app.specData(ind1).occFlag)
                        app.specData(ind1).report_occInfo = [];
                        continue
                        
                    elseif ~isempty(app.specData(ind1).report_occInfo)
                        continue
                        
                    else
                        app.specData(ind1).report_occInfo = struct('Type', 'Linear adaptativo',    ...
                                                                          'Value',     [],        ...
                                                                          'Time',      15,        ...
                                                                          'Offset',    12,        ...
                                                                          'ConfLevel', [],        ...
                                                                          'statsFcn',  'Mediana', ...
                                                                          'Samples',   [0, 20]);
                    end
                end
                
            else
                app.Report_playButton.Enable    = 0;
                app.Report_previewButton.Enable = 0;
            end
    
        end

        % Image clicked function: Report_deleteButton
        function Report_deleteButtonPushed(app, event)
            
            auxValue = length(app.Tree3.SelectedNodes);
            
            for ii = 1:auxValue
                selectedNodes = app.Tree3.SelectedNodes(ii);
                
                if isnumeric(selectedNodes.NodeData)
                    app.filtData(find(app.filtData == selectedNodes.NodeData, 1)) = [];
                else
                    auxIndex1 = [];
                    for jj = 1:length(app.specData)
                        if strcmp(app.specData(jj).Node, selectedNodes.NodeData)
                            auxIndex1 = [auxIndex1, jj];
                        end
                    end
                    
                    for kk = 1:length(auxIndex1)
                        auxIndex2 = find(app.filtData == auxIndex1(kk), 1);
                        
                        if ~isempty(auxIndex2)
                            app.filtData(auxIndex2) = [];
                        end
                    end
                end
            end
            
            Fcn_Tree3(app)
            
            if isempty(app.filtData)
                app.Report_deleteButton.Enable  = 0;
                app.Report_playButton.Enable    = 0;
                app.Report_previewButton.Enable = 0;
            end
            
        end

        % Value changed function: Report_occType
        function Report_occTypeValueChanged(app, event)
            
            switch app.Report_occType.Value
                case 'Linear fixo'
                    set(app.Report_Grid7.Children, 'Enable', 1)
                    set(app.Report_Grid8.Children, 'Enable', 0)
                    
                    app.Report_occConfLevel.Enable   = 0;
                    app.NveldeconfianaLabel_2.Enable = 0;
                    
                    app.Report_occOffset.Enable = 0;
                    app.OffSetLabel_2.Enable    = 0;
                    
                case 'Linear adaptativo'
                    set(app.Report_Grid7.Children, 'Enable', 1)
                    set(app.Report_Grid8.Children, 'Enable', 1)
                    
                    app.Report_occConfLevel.Enable   = 0;
                    app.NveldeconfianaLabel_2.Enable = 0;
                    
                    app.Report_occValue.Enable = 0;
                    app.ValordBmLabel_2.Enable = 0;
                    
                case 'Piso de ruído (Offset)'
                    set(app.Report_Grid7.Children, 'Enable', 1)
                    set(app.Report_Grid8.Children, 'Enable', 1)
                    
                    app.Report_occValue.Enable = 0;
                    app.ValordBmLabel_2.Enable = 0;
            end
            
        end

        % Value changed function: Report_occValue
        function Report_occValueValueChanged(app, event)
            
            app.specData(app.Tree3.SelectedNodes.NodeData).report_occInfo.Value = app.Report_occValue.Value;
            
        end

        % Value changed function: Report_occOffset
        function Report_occOffsetValueChanged(app, event)
            
            app.specData(app.Tree3.SelectedNodes.NodeData).report_occInfo.Offset = app.Report_occOffset.Value;
            
        end

        % Value changed function: Report_occTime
        function Report_occTimeValueChanged(app, event)
            
            app.specData(app.Tree3.SelectedNodes.NodeData).report_occInfo.Time = app.Report_occTime.Value;
            
        end

        % Value changed function: Report_occConfLevel
        function Report_occConfLevelValueChanged(app, event)

            app.specData(app.Tree3.SelectedNodes.NodeData).report_occInfo.ConfLevel = app.Report_occConfLevel.Value;
            
        end

        % Value changed function: Report_occFcn
        function Report_occFcnValueChanged(app, event)

            app.specData(app.Tree3.SelectedNodes.NodeData).report_occInfo.statsFcn = app.Report_occFcn.Value;
            
        end

        % Value changed function: Report_tSamples
        function Report_tSamplesValueChanged(app, event)
            
            app.specData(app.Tree3.SelectedNodes.NodeData).report_occInfo.Samples(1) = app.Report_tSamples.Value;
            
        end

        % Value changed function: Report_uSamples
        function Report_uSamplesValueChanged(app, event)
            
            app.specData(app.Tree3.SelectedNodes.NodeData).report_occInfo.Samples(2) = app.Report_uSamples.Value;
            
        end

        % Value changed function: Report_peakClass
        function Report_peakClassValueChanged(app, event)

            switch app.Report_peakClass.Value
                case 'Radiodifusão (FM)'
                    %% Critério 1:
                    app.Report_peakProeminence_C1.Value = 10;               % Proeminência mínima
                    app.Report_peakBW.Value             = 15;               % BW mínima
                    app.Report_peakDistance.Value       = 200;              % Distância mínima entre picos
                    app.Report_peakFcn.Value            = 'Mediana';
                    
                    %% Critério 2:
                    app.Report_peakProeminence_C2.Value = 30;               % Proeminência mínima
                    app.Report_peakOCC.Value            = 67;               % Mínima ocupação máxima
                    
                case 'Radiodifusão (TV)'
                    %% Critério 1:
                    app.Report_peakProeminence_C1.Value = 10;               % Proeminência mínima
                    app.Report_peakBW.Value             = 150;              % BW mínima
                    app.Report_peakDistance.Value       = 4450;             % Distância mínima entre picos
                    app.Report_peakFcn.Value            = 'Mediana';
                    
                    %% Critério 2:
                    app.Report_peakProeminence_C2.Value = 30;               % Proeminência mínima
                    app.Report_peakOCC.Value            = 67;               % Mínima ocupação máxima
                    
                case 'Radiocomunicação'
                    %% Critério 1:
                    app.Report_peakProeminence_C1.Value = 10;               % Proeminência mínima
                    app.Report_peakBW.Value             = 10;               % BW mínima
                    app.Report_peakDistance.Value       = 25;               % Distância mínima entre picos
                    app.Report_peakFcn.Value            = 'Mediana';
                    
                    %% Critério 2:
                    app.Report_peakProeminence_C2.Value = 30;               % Proeminência mínima
                    app.Report_peakOCC.Value            = 1;                % Mínima ocupação máxima
                    
                case 'Genérico'
                    %% Critério 1:
                    app.Report_peakProeminence_C1.Value = 10;               % Proeminência mínima
                    app.Report_peakBW.Value             = 10;               % BW mínima
                    app.Report_peakDistance.Value       = 25;               % Distância mínima entre picos
                    app.Report_peakFcn.Value            = 'Mediana';
                    
                    %% Critério 2:
                    app.Report_peakProeminence_C2.Value = 30;               % Proeminência mínima
                    app.Report_peakOCC.Value            = 1;                % Mínima ocupação máxima
            end
            
            Fcn_ReportClassValueChanged_Aux1(app)                          % Atualiza a descrição da classe
            Fcn_ReportClassValueChanged_Aux2(app)                          % Atualiza a variável "specData"
            
        end

        % Value changed function: Report_peakDistance
        function Report_peakDistanceValueChanged(app, event)

            app.specData(app.Tree3.SelectedNodes.NodeData).report_peakInfo.minDistance = app.Report_peakDistance.Value * 1000;
            
        end

        % Value changed function: Report_peakBW
        function Report_peakBWValueChanged(app, event)

            app.specData(app.Tree3.SelectedNodes.NodeData).report_peakInfo.minBW = app.Report_peakBW.Value * 1000;
            
        end

        % Value changed function: Report_peakProeminence_C1
        function Report_peakProeminence_C1ValueChanged(app, event)

            app.specData(app.Tree3.SelectedNodes.NodeData).report_peakInfo.minProeminence_C1 = app.Report_peakProeminence_C1.Value;
            
        end

        % Value changed function: Report_peakFcn
        function Report_peakFcnValueChanged(app, event)
            
            app.specData(app.Tree3.SelectedNodes.NodeData).report_peakInfo.statsFcn = app.Report_peakFcn.Value;
            
        end

        % Value changed function: Report_peakProeminence_C2
        function Report_peakProeminence_C2ValueChanged(app, event)
            
            app.specData(app.Tree3.SelectedNodes.NodeData).report_peakInfo.minProeminence_C2 = app.Report_peakProeminence_C2.Value;
            
        end

        % Value changed function: Report_peakOCC
        function Report_peakOCCValueChanged(app, event)
            
            app.specData(app.Tree3.SelectedNodes.NodeData).report_peakInfo.minOCC = app.Report_peakOCC.Value;
            
        end

        % Button pushed function: ToolBar_Button1
        function ToolBar_Button1Pushed(app, event)
            
            global metaData1
            global specData1
            global filtData1
            global reportInfo1
            global nodesTable1
            global traceInfo1
            global peaksTable1
            global exceptionList1
            
            metaData1 = app.metaData;
            specData1 = app.specData;
            
            filtData1      = app.filtData;
            reportInfo1    = app.reportInfo;
            nodesTable1    = app.nodesTable;
            traceInfo1     = app.traceInfo;
            peaksTable1    = app.peaksTable;
            exceptionList1 = app.exceptionList;
            
            msg = sprintf(['As principais variáveis do appAnálise - "metaData1" e "specData1" - foram ' ...
                           'definidas como globais, sendo possível a sua importação na área de trabalho do Matlab por meio do '    ...
                           'comando:\n\n• global <NomeVariável>']);
            
            uialert(app.MainApp, msg, 'Variáveis globais', 'Icon', 'info');
            
        end

        % Button pushed function: ToolBar_Button2
        function ToolBar_Button2Pushed(app, event)
            
            msg = sprintf(['appAnálise v.0.90\n2021\n\nAplicação de pós-processamento de dados gerados em monitorações ' ...
                           'desenvolvida no âmbito dos projetos 3A e RFLOOK - Processos nºs 53554.000939/2020-26 e '     ...
                           '53500.063493/2020-76.']);
            
            uialert(app.MainApp, msg, 'Informações', 'Icon', 'info');
            
        end

        % Button pushed function: ToolBar_Button3
        function ToolBar_Button3Pushed(app, event)
            
            msg = sprintf(['appAnálise v.0.90\n2021\n\nPendente elaboração documental (manuais do usuário e do desenvolvedor), ' ...
                           'de vídeos tutoriais e de treinamentos.\n\nReportes de bugs devem ser direcionados ao servidor Eric ' ...
                           'Magalhães Delgado (GR08).']);
            
            uialert(app.MainApp, msg, 'Ajuda', 'Icon', 'info');
            
        end

        % Value changed function: Info_Latitude, Info_Longitude
        function Info_LongitudeValueChanged(app, event)
            
            if (app.Info_Latitude.Value ~= -1) & (app.Info_Longitude.Value ~= -1)
                gps = struct('Latitude',  app.Info_Latitude.Value, ...
                             'Longitude', app.Info_Longitude.Value);
                
                app.Info_City.Value = FindCity(gps);
            end
            
        end

        % Value changed function: Info_City
        function Info_CityValueChanged(app, event)
            
            [Latitude, Longitude] = FindCoordinates(event.Value);
            
            if ~isempty(Latitude) | ~isempty(Longitude)
                app.Info_Latitude.Value  = Latitude;
                app.Info_Longitude.Value = Longitude;
            else
                msg = sprintf(['Não encontrada em base do IBGE o município "%s". Favor '  ...
                               'corrigir eventual erro na grafia, inserindo os acentos, ' ...
                               'no formato Município/UF'], event.Value);
                
                uialert(app.MainApp, msg, 'Alerta!', 'Icon', 'warning');
                
                app.Info_City.Value = event.PreviousValue;
            end
            
        end

        % Button pushed function: mainInfo_DeleteAll
        function mainInfo_DeleteAllButtonPushed(app, event)
            
            msg = ['Confirma a exclusão de todos os fluxos de dados e as informações auxiliares, ' ...
                   'dentre elas a lista de exceção?'];

            selection = uiconfirm(app.MainApp, msg, 'Alerta', 'Options', {'OK', 'Cancelar'}, ...
                                                    'DefaultOption', 1, 'CancelOption', 2);
            
            if strcmp(selection, 'OK')
                app.specData      = [];
                
                app.filtData      = [];
                app.peaksTable    = [];
                app.exceptionList = [];
                
                if app.OperationMode == 2
                    app.metaData      = [];
                    app.OperationMode = 1;
                    
                    delete(app.File_Tree1.Children)
                    File_Tree1SelectionChanged(app)                    
                end
                
                app.Button_File.Value = 1;
                
                app.Button_Playback.Enable = 0;
                app.Button_Report.Enable   = 0;
                app.Button_EditInfo.Enable = 0;
                app.Button_SaveFile.Enable = 0;
                
                mainMenu_File(app)
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainApp = uifigure('Visible', 'off');
            app.MainApp.Name = 'appAnálise';
            app.MainApp.Icon = 'Bluemine.png';
            app.MainApp.CloseRequestFcn = createCallbackFcn(app, @closeRequest, true);
            app.Grid1 = uigridlayout(app.MainApp);
            app.TabGroup = uitabgroup(app.Grid1);
            app.Tab1_Home = uitab(app.TabGroup);
            app.Home_Grid1 = uigridlayout(app.Tab1_Home);
            app.Home_mainTitle = uilabel(app.Home_Grid1);
            app.Home_mainTitle.Text = 'appAnálise';
            app.Home_subTitle1 = uilabel(app.Home_Grid1);
            app.Home_subTitle1.Text = 'v.0.9 (Homologação)';
            app.Home_subTitle2 = uilabel(app.Home_Grid1);
            app.Home_subTitle2.Text = 'Em desenvolvimento no Matlab v. 2021a (Update 1)';
            app.Home_ProjectLogo = uiimage(app.Home_Grid1);
            app.Home_ProjectLogo.ImageSource = 'RFLook3A.png';
            app.Tab2_File = uitab(app.TabGroup);
            app.File_Grid1 = uigridlayout(app.Tab2_File);
            app.File_GridA = uigridlayout(app.File_Grid1);
            app.File_Label1 = uilabel(app.File_GridA);
            app.File_Label1.Text = 'Arquivo(s)';
            app.File_Tree1 = uitree(app.File_GridA);
            app.File_Tree1.Multiselect = 'on';
            app.File_Tree1.SelectionChangedFcn = createCallbackFcn(app, @File_Tree1SelectionChanged, true);
            app.File_Button1 = uibutton(app.File_GridA, 'push');
            app.File_Button1.ButtonPushedFcn = createCallbackFcn(app, @File_Button1Pushed, true);
            app.File_Button1.Icon = 'OpenFile.png';
            app.File_Button1.Text = 'Metadados';
            app.File_Label2 = uilabel(app.File_GridA);
            app.File_Label2.Text = 'Informações gerais';
            app.File_Panel1 = uipanel(app.File_GridA);
            app.File_GridA1 = uigridlayout(app.File_Panel1);
            app.File_Format = uieditfield(app.File_GridA1, 'text');
            app.File_Format.Editable = 'off';
            app.File_Format.Enable = 'off';
            app.LocaldamonitoraoLabel = uilabel(app.File_GridA1);
            app.LocaldamonitoraoLabel.Enable = 'off';
            app.LocaldamonitoraoLabel.Text = 'Local da monitoração:';
            app.File_Node = uieditfield(app.File_GridA1, 'text');
            app.File_Node.Editable = 'off';
            app.File_Node.Enable = 'off';
            app.File_City = uieditfield(app.File_GridA1, 'text');
            app.File_City.Editable = 'off';
            app.File_City.Enable = 'off';
            app.FormatodoarquivoLabel = uilabel(app.File_GridA1);
            app.FormatodoarquivoLabel.Enable = 'off';
            app.FormatodoarquivoLabel.Text = 'Formato do arquivo:';
            app.SensorLabel = uilabel(app.File_GridA1);
            app.SensorLabel.Enable = 'off';
            app.SensorLabel.Text = 'Sensor:';
            app.PerododeobservaoLabel = uilabel(app.File_GridA1);
            app.PerododeobservaoLabel.Enable = 'off';
            app.PerododeobservaoLabel.Text = 'Período de observação:';
            app.File_ObservationTime = uieditfield(app.File_GridA1, 'text');
            app.File_ObservationTime.Editable = 'off';
            app.File_ObservationTime.Enable = 'off';
            app.udpLogger_Label3 = uilabel(app.File_GridA);
            app.udpLogger_Label3.Text = 'Fluxo(s) de dados';
            app.File_Tree2 = uitree(app.File_GridA);
            app.File_Tree2.Multiselect = 'on';
            app.File_Tree2.SelectionChangedFcn = createCallbackFcn(app, @File_Tree2SelectionChanged, true);
            app.File_Tree2.Enable = 'off';
            app.File_GridB = uigridlayout(app.File_Grid1);
            app.File_Button2 = uibutton(app.File_GridB, 'push');
            app.File_Button2.ButtonPushedFcn = createCallbackFcn(app, @File_Button2Pushed, true);
            app.File_Button2.Icon = 'Media_PlayWhite.png';
            app.File_Button2.Enable = 'off';
            app.File_Button2.Text = 'Leitura espectro';
            app.File_Label4 = uilabel(app.File_GridB);
            app.File_Label4.Text = 'Informações complementares relacionadas ao fluxo selecionado';
            app.File_GridB1 = uigridlayout(app.File_GridB);
            app.File_Number = uieditfield(app.File_GridB1, 'numeric');
            app.File_Number.Limits = [0 Inf];
            app.File_Number.ValueDisplayFormat = '%.0f';
            app.File_Number.Editable = 'off';
            app.QuantidadedearquivosLabel = uilabel(app.File_GridB1);
            app.QuantidadedearquivosLabel.Text = {'Quantidade de '; 'arquivos:'};
            app.File_Memory = uieditfield(app.File_GridB1, 'numeric');
            app.File_Memory.Limits = [0 Inf];
            app.File_Memory.ValueDisplayFormat = '%.3f';
            app.File_Memory.Editable = 'off';
            app.EstimativadeusodamemriaapsleituradoespectroMBLabel_2 = uilabel(app.File_GridB1);
            app.EstimativadeusodamemriaapsleituradoespectroMBLabel_2.Text = 'Estimativa de uso da memória após leitura do espectro (MB):';
            app.Panel_4 = uipanel(app.File_GridB);
            app.File_GridB2 = uigridlayout(app.Panel_4);
            app.File_Description = uieditfield(app.File_GridB2, 'text');
            app.File_Description.Editable = 'off';
            app.File_Description.Enable = 'off';
            app.DescrioEditFieldLabel = uilabel(app.File_GridB2);
            app.DescrioEditFieldLabel.Enable = 'off';
            app.DescrioEditFieldLabel.Text = 'Descrição:';
            app.File_StepWidth = uieditfield(app.File_GridB2, 'numeric');
            app.File_StepWidth.Limits = [1 Inf];
            app.File_StepWidth.ValueDisplayFormat = '%.3f';
            app.File_StepWidth.Editable = 'off';
            app.File_StepWidth.Enable = 'off';
            app.File_StepWidth.Value = 25;
            app.PassovarredurakHzLabel = uilabel(app.File_GridB2);
            app.PassovarredurakHzLabel.Enable = 'off';
            app.PassovarredurakHzLabel.Text = 'Passo varredura (kHz):';
            app.File_Resolution = uieditfield(app.File_GridB2, 'numeric');
            app.File_Resolution.Limits = [-1 Inf];
            app.File_Resolution.ValueDisplayFormat = '%.3f';
            app.File_Resolution.Editable = 'off';
            app.File_Resolution.Enable = 'off';
            app.File_Resolution.Value = 30;
            app.ResoluokHzLabel = uilabel(app.File_GridB2);
            app.ResoluokHzLabel.Enable = 'off';
            app.ResoluokHzLabel.Text = 'Resolução (kHz):';
            app.File_Operation = uieditfield(app.File_GridB2, 'text');
            app.File_Operation.Editable = 'off';
            app.File_Operation.Enable = 'off';
            app.OperaoLabel = uilabel(app.File_GridB2);
            app.OperaoLabel.Enable = 'off';
            app.OperaoLabel.Text = 'Operação:';
            app.File_LevelUnit = uieditfield(app.File_GridB2, 'text');
            app.File_LevelUnit.Editable = 'off';
            app.File_LevelUnit.Enable = 'off';
            app.UnidadeLabel = uilabel(app.File_GridB2);
            app.UnidadeLabel.Enable = 'off';
            app.UnidadeLabel.Text = 'Unidade:';
            app.PontosportraoLabel = uilabel(app.File_GridB2);
            app.PontosportraoLabel.Enable = 'off';
            app.PontosportraoLabel.Text = 'Pontos por traço:';
            app.File_DataPoints = uieditfield(app.File_GridB2, 'numeric');
            app.File_DataPoints.Limits = [1 Inf];
            app.File_DataPoints.ValueDisplayFormat = '%.0f';
            app.File_DataPoints.Editable = 'off';
            app.File_DataPoints.Enable = 'off';
            app.File_DataPoints.Value = 501;
            app.NmerodetraosLabel = uilabel(app.File_GridB2);
            app.NmerodetraosLabel.Enable = 'off';
            app.NmerodetraosLabel.Text = 'Número de traços:';
            app.File_Samples = uieditfield(app.File_GridB2, 'numeric');
            app.File_Samples.Limits = [1 Inf];
            app.File_Samples.ValueDisplayFormat = '%.0f';
            app.File_Samples.Editable = 'off';
            app.File_Samples.Enable = 'off';
            app.File_Samples.Value = 1;
            app.EstimativadeusodamemriaapsleituradoespectroMBLabel = uilabel(app.File_GridB2);
            app.EstimativadeusodamemriaapsleituradoespectroMBLabel.Enable = 'off';
            app.EstimativadeusodamemriaapsleituradoespectroMBLabel.Text = 'Estimativa de uso da memória após leitura do espectro (MB):';
            app.File_EstimatedMemory = uieditfield(app.File_GridB2, 'numeric');
            app.File_EstimatedMemory.Limits = [0 Inf];
            app.File_EstimatedMemory.ValueDisplayFormat = '%.3f';
            app.File_EstimatedMemory.Editable = 'off';
            app.File_EstimatedMemory.Enable = 'off';
            app.File_Label4_2 = uilabel(app.File_GridB2);
            app.File_Label4_2.Visible = 'off';
            app.File_Label4_2.Text = 'Como foi atingido o limite do número de traços por fluxo de dados (65536) é provável que o arquivo selecionado possua outras informações deste fluxo.';
            app.Tab3_Playback = uitab(app.TabGroup);
            app.GridLayout5 = uigridlayout(app.Tab3_Playback);
            app.Grid3_Playback = uigridlayout(app.GridLayout5);
            app.Playback_Play = uibutton(app.Grid3_Playback, 'push');
            app.Playback_Play.ButtonPushedFcn = createCallbackFcn(app, @Playback_playButton, true);
            app.Playback_Play.Icon = 'Media_PlayWhite.png';
            app.Playback_Play.Enable = 'off';
            app.Playback_Play.Text = 'Iniciar escrita';
            app.Playback_Reload = uibutton(app.Grid3_Playback, 'push');
            app.Playback_Reload.ButtonPushedFcn = createCallbackFcn(app, @Playback_ReloadPushed, true);
            app.Playback_Reload.Icon = 'reloadIcon.png';
            app.Playback_Reload.Text = '';
            app.Playback_Menu = uibutton(app.Grid3_Playback, 'state');
            app.Playback_Menu.ValueChangedFcn = createCallbackFcn(app, @Playback_chartEdit, true);
            app.Playback_Menu.Tooltip = {'Análise de faixa de frequência.'};
            app.Playback_Menu.Icon = 'aa.png';
            app.Playback_Menu.Text = '';
            app.Playback_Zoom = uibutton(app.Grid3_Playback, 'state');
            app.Playback_Zoom.ValueChangedFcn = createCallbackFcn(app, @Playback_ZoomInterativity, true);
            app.Playback_Zoom.Tooltip = {'Análise de faixa de frequência.'};
            app.Playback_Zoom.Icon = 'ZoomIcon.png';
            app.Playback_Zoom.Text = '';
            app.Playback_Hand = uibutton(app.Grid3_Playback, 'state');
            app.Playback_Hand.ValueChangedFcn = createCallbackFcn(app, @Playback_PanInterativity, true);
            app.Playback_Hand.Tooltip = {'Análise de faixa de frequência.'};
            app.Playback_Hand.Icon = 'HandIcon.png';
            app.Playback_Hand.Text = '';
            app.Playback_FullScreen = uibutton(app.Grid3_Playback, 'push');
            app.Playback_FullScreen.ButtonPushedFcn = createCallbackFcn(app, @Playback_FullScreenMode, true);
            app.Playback_FullScreen.Icon = 'telacheia.png';
            app.Playback_FullScreen.Text = '';
            app.Playback_Panel0 = uipanel(app.Grid3_Playback);
            app.Playback_Grid0 = uigridlayout(app.Playback_Panel0);
            app.Playback_Grid3 = uigridlayout(app.Playback_Grid0);
            app.Playback_Slider = uislider(app.Playback_Grid3);
            app.Playback_Slider.MajorTicks = [0 25 50 75 100];
            app.Playback_Slider.MajorTickLabels = {'0', '25', '50', '75', '100'};
            app.Playback_Slider.ValueChangingFcn = createCallbackFcn(app, @Playback_SliderValueChanging, true);
            app.Playback_Slider.Enable = 'off';
            app.Playback_Grid2 = uigridlayout(app.Playback_Grid0);
            app.Playback_wfCheckBox = uicheckbox(app.Playback_Grid2);
            app.Playback_wfCheckBox.ValueChangedFcn = createCallbackFcn(app, @Playback_wfCheckBoxValueChanged, true);
            app.Playback_wfCheckBox.Text = 'Waterfall';
            app.Playback_occPanel = uipanel(app.Playback_Grid2);
            app.Playback_occGrid2 = uigridlayout(app.Playback_occPanel);
            app.Playback_occSamples = uispinner(app.Playback_occGrid2);
            app.Playback_occSamples.Step = 10;
            app.Playback_occSamples.Limits = [10 100];
            app.Playback_occSamples.RoundFractionalValues = 'on';
            app.Playback_occSamples.ValueDisplayFormat = '%.0f';
            app.Playback_occSamples.ValueChangedFcn = createCallbackFcn(app, @Playback_ValueChanged, true);
            app.Playback_occSamples.Enable = 'off';
            app.Playback_occSamples.Value = 20;
            app.Playback_occFcn = uidropdown(app.Playback_occGrid2);
            app.Playback_occFcn.Items = {'Mediana', 'Média (vídeo)'};
            app.Playback_occFcn.ValueChangedFcn = createCallbackFcn(app, @Playback_ValueChanged, true);
            app.Playback_occFcn.Enable = 'off';
            app.Playback_occFcn.Value = 'Mediana';
            app.AmostrasteisLabel_2 = uilabel(app.Playback_occGrid2);
            app.AmostrasteisLabel_2.Enable = 'off';
            app.AmostrasteisLabel_2.Text = {'Amostras úteis '; '(%):'};
            app.FunodetendnciacentralLabel_3 = uilabel(app.Playback_occGrid2);
            app.FunodetendnciacentralLabel_3.Enable = 'off';
            app.FunodetendnciacentralLabel_3.Text = 'Função de tendência central:';
            app.Playback_occGrid1 = uigridlayout(app.Playback_Grid2);
            app.Playback_occLabel = uilabel(app.Playback_occGrid1);
            app.Playback_occLabel.Enable = 'off';
            app.Playback_occLabel.Text = 'Parâmetros relacionados à estimativa do piso de ruído:';
            app.ThresholdDropDownLabel = uilabel(app.Playback_occGrid1);
            app.ThresholdDropDownLabel.Enable = 'off';
            app.ThresholdDropDownLabel.Text = 'Threshold:';
            app.Playback_occTime = uispinner(app.Playback_occGrid1);
            app.Playback_occTime.Limits = [2 Inf];
            app.Playback_occTime.ValueDisplayFormat = '%d';
            app.Playback_occTime.ValueChangedFcn = createCallbackFcn(app, @Playback_ValueChanged, true);
            app.Playback_occTime.Enable = 'off';
            app.Playback_occTime.Value = 100;
            app.Playback_occOffset = uispinner(app.Playback_occGrid1);
            app.Playback_occOffset.Limits = [0 12];
            app.Playback_occOffset.ValueChangedFcn = createCallbackFcn(app, @Playback_ValueChanged, true);
            app.Playback_occOffset.Enable = 'off';
            app.Playback_occOffset.Value = 12;
            app.OffSetLabel = uilabel(app.Playback_occGrid1);
            app.OffSetLabel.Enable = 'off';
            app.OffSetLabel.Text = 'OffSet:';
            app.Playback_occConfidenceLevel = uidropdown(app.Playback_occGrid1);
            app.Playback_occConfidenceLevel.Items = {'68', '95', '99'};
            app.Playback_occConfidenceLevel.ValueChangedFcn = createCallbackFcn(app, @Playback_ValueChanged, true);
            app.Playback_occConfidenceLevel.Enable = 'off';
            app.Playback_occConfidenceLevel.Value = '95';
            app.NveldeconfianaLabel = uilabel(app.Playback_occGrid1);
            app.NveldeconfianaLabel.Enable = 'off';
            app.NveldeconfianaLabel.Text = 'Nível de confiança (%):';
            app.ValordBmLabel = uilabel(app.Playback_occGrid1);
            app.ValordBmLabel.Enable = 'off';
            app.ValordBmLabel.Text = 'Valor (dBm):';
            app.Playback_occValue = uispinner(app.Playback_occGrid1);
            app.Playback_occValue.RoundFractionalValues = 'on';
            app.Playback_occValue.ValueDisplayFormat = '%d';
            app.Playback_occValue.ValueChangedFcn = createCallbackFcn(app, @Playback_ValueChanged, true);
            app.Playback_occValue.Enable = 'off';
            app.Playback_occValue.Value = -80;
            app.FatordeintegraoLabel_2 = uilabel(app.Playback_occGrid1);
            app.FatordeintegraoLabel_2.Enable = 'off';
            app.FatordeintegraoLabel_2.Text = 'Fator de integração:';
            app.Playback_occType = uidropdown(app.Playback_occGrid1);
            app.Playback_occType.Items = {'Linear', 'Piso de ruído (Offset)'};
            app.Playback_occType.ValueChangedFcn = createCallbackFcn(app, @Playback_occTypeValueChanged, true);
            app.Playback_occType.Enable = 'off';
            app.Playback_occType.Value = 'Linear';
            app.Playback_occCheckBox = uicheckbox(app.Playback_Grid2);
            app.Playback_occCheckBox.ValueChangedFcn = createCallbackFcn(app, @Playback_occCheckBoxValueChanged, true);
            app.Playback_occCheckBox.Text = 'Ocupação em "tempo real"';
            app.Playback_Grid1 = uigridlayout(app.Playback_Grid0);
            app.Playback_traceLabel0 = uilabel(app.Playback_Grid1);
            app.Playback_traceLabel0.Text = 'Processamento dos traços';
            app.Playback_traceRadioGroup1 = uibuttongroup(app.Playback_Grid1);
            app.Playback_traceRadioGroup1.SelectionChangedFcn = createCallbackFcn(app, @Playback_integrationTypeCallback, true);
            app.Playback_traceRadio2 = uiradiobutton(app.Playback_traceRadioGroup1);
            app.Playback_traceRadio2.Text = '"Tempo real"';
            app.Playback_traceRadio1 = uiradiobutton(app.Playback_traceRadioGroup1);
            app.Playback_traceRadio1.Text = 'Integraçao infinita';
            app.Playback_traceRadio1.Value = true;
            app.Playback_Panel1 = uipanel(app.Playback_Grid1);
            app.Playback_traceGrid = uigridlayout(app.Playback_Panel1);
            app.Playback_traceCheckBox3 = uicheckbox(app.Playback_traceGrid);
            app.Playback_traceCheckBox3.ValueChangedFcn = createCallbackFcn(app, @Playback_axesFlagCallback, true);
            app.Playback_traceCheckBox3.Text = 'MaxHold';
            app.Playback_traceCheckBox2 = uicheckbox(app.Playback_traceGrid);
            app.Playback_traceCheckBox2.ValueChangedFcn = createCallbackFcn(app, @Playback_axesFlagCallback, true);
            app.Playback_traceCheckBox2.Text = 'MinHold';
            app.Playback_traceLabel1 = uilabel(app.Playback_traceGrid);
            app.Playback_traceLabel1.Text = 'Limites:';
            app.Playback_traceRadioGroup2 = uibuttongroup(app.Playback_traceGrid);
            app.Playback_traceRadioGroup2.SelectionChangedFcn = createCallbackFcn(app, @Playback_axesFlagCallback, true);
            app.Playback_traceRadio4 = uiradiobutton(app.Playback_traceRadioGroup2);
            app.Playback_traceRadio4.Enable = 'off';
            app.Playback_traceRadio4.Text = 'Média (vídeo)';
            app.Playback_traceRadio3 = uiradiobutton(app.Playback_traceRadioGroup2);
            app.Playback_traceRadio3.Enable = 'off';
            app.Playback_traceRadio3.Text = 'Mediana';
            app.Playback_traceRadio3.Value = true;
            app.Playback_traceCheckBox1 = uicheckbox(app.Playback_traceGrid);
            app.Playback_traceCheckBox1.ValueChangedFcn = createCallbackFcn(app, @Playback_axesFlagCallback, true);
            app.Playback_traceCheckBox1.Text = 'Tendência central:';
            app.FatordeintegraoLabel = uilabel(app.Playback_traceGrid);
            app.FatordeintegraoLabel.Enable = 'off';
            app.FatordeintegraoLabel.Text = 'Fator de integração:';
            app.Playback_traceFactor = uispinner(app.Playback_traceGrid);
            app.Playback_traceFactor.Limits = [2 Inf];
            app.Playback_traceFactor.ValueDisplayFormat = '%d';
            app.Playback_traceFactor.ValueChangedFcn = createCallbackFcn(app, @Playback_axesFlagCallback, true);
            app.Playback_traceFactor.Enable = 'off';
            app.Playback_traceFactor.Value = 100;
            app.Playback_peakLabel0 = uilabel(app.Playback_Grid1);
            app.Playback_peakLabel0.Text = 'Marcadores';
            app.Playback_peakRadioGroup = uibuttongroup(app.Playback_Grid1);
            app.Playback_peakRadioGroup.SelectionChangedFcn = createCallbackFcn(app, @Playback_RadioGroupSelectionChanged, true);
            app.Playback_peakRadio2 = uiradiobutton(app.Playback_peakRadioGroup);
            app.Playback_peakRadio2.Text = 'Inclusão automática (busca de picos)';
            app.Playback_peakRadio1 = uiradiobutton(app.Playback_peakRadioGroup);
            app.Playback_peakRadio1.Text = 'Inclusão manual';
            app.Playback_peakRadio1.Value = true;
            app.Playback_Panel2 = uipanel(app.Playback_Grid1);
            app.Playback_peakGrid = uigridlayout(app.Playback_Panel2);
            app.LarguramnimaocupadakHzLabel = uilabel(app.Playback_peakGrid);
            app.LarguramnimaocupadakHzLabel.Enable = 'off';
            app.LarguramnimaocupadakHzLabel.Text = {'Largura mínima '; 'ocupada (kHz):'};
            app.Marker_Label3 = uilabel(app.Playback_peakGrid);
            app.Marker_Label3.Enable = 'off';
            app.Marker_Label3.Text = {'Distância mínima '; 'entre picos (kHz):'};
            app.Marker_Label2 = uilabel(app.Playback_peakGrid);
            app.Marker_Label2.Enable = 'off';
            app.Marker_Label2.Text = {'Proeminência '; 'mínima (dB):'};
            app.ThresholddBVmLabel = uilabel(app.Playback_peakGrid);
            app.ThresholddBVmLabel.Enable = 'off';
            app.ThresholddBVmLabel.Text = {'Threshold'; '(dBμV/m):'};
            app.Playback_peakBW = uispinner(app.Playback_peakGrid);
            app.Playback_peakBW.Step = 10;
            app.Playback_peakBW.Limits = [0 Inf];
            app.Playback_peakBW.RoundFractionalValues = 'on';
            app.Playback_peakBW.ValueDisplayFormat = '%.0f';
            app.Playback_peakBW.Enable = 'off';
            app.Playback_peakBW.Value = 10;
            app.Playback_peakDistance = uispinner(app.Playback_peakGrid);
            app.Playback_peakDistance.Step = 25;
            app.Playback_peakDistance.Limits = [0 Inf];
            app.Playback_peakDistance.RoundFractionalValues = 'on';
            app.Playback_peakDistance.ValueDisplayFormat = '%.0f';
            app.Playback_peakDistance.Enable = 'off';
            app.Playback_peakDistance.Value = 25;
            app.Playback_peakProeminence = uispinner(app.Playback_peakGrid);
            app.Playback_peakProeminence.Step = 10;
            app.Playback_peakProeminence.Limits = [0 Inf];
            app.Playback_peakProeminence.RoundFractionalValues = 'on';
            app.Playback_peakProeminence.ValueDisplayFormat = '%.0f';
            app.Playback_peakProeminence.Enable = 'off';
            app.Playback_peakProeminence.Value = 30;
            app.Playback_peakTHR = uispinner(app.Playback_peakGrid);
            app.Playback_peakTHR.Step = 10;
            app.Playback_peakTHR.RoundFractionalValues = 'on';
            app.Playback_peakTHR.ValueDisplayFormat = '%.0f';
            app.Playback_peakTHR.Enable = 'off';
            app.Playback_peakTHR.Value = -Inf;
            app.Playback_peakNumbers = uispinner(app.Playback_peakGrid);
            app.Playback_peakNumbers.Limits = [1 10];
            app.Playback_peakNumbers.RoundFractionalValues = 'on';
            app.Playback_peakNumbers.ValueDisplayFormat = '%.0f';
            app.Playback_peakNumbers.Enable = 'off';
            app.Playback_peakNumbers.Value = 1;
            app.NmerodepicosLabel = uilabel(app.Playback_peakGrid);
            app.NmerodepicosLabel.Enable = 'off';
            app.NmerodepicosLabel.Text = 'Número de picos:';
            app.TraoDropDownLabel = uilabel(app.Playback_peakGrid);
            app.TraoDropDownLabel.Enable = 'off';
            app.TraoDropDownLabel.Text = 'Traço:';
            app.Playback_peakTrace = uidropdown(app.Playback_peakGrid);
            app.Playback_peakTrace.Items = {'ClearWrite'};
            app.Playback_peakTrace.Enable = 'off';
            app.Playback_peakTrace.Value = 'ClearWrite';
            app.Playback_addMarker = uiimage(app.Playback_Grid1);
            app.Playback_addMarker.ImageClickedFcn = createCallbackFcn(app, @Playback_addMarkerPushed, true);
            app.Playback_addMarker.Enable = 'off';
            app.Playback_addMarker.ImageSource = 'Check1.png';
            app.Playback_deleteMarker = uiimage(app.Playback_Grid1);
            app.Playback_deleteMarker.ImageClickedFcn = createCallbackFcn(app, @Playback_deleteMarkerPushed, true);
            app.Playback_deleteMarker.Enable = 'off';
            app.Playback_deleteMarker.ImageSource = 'Check2.png';
            app.Playback_peakTree = uitree(app.Playback_Grid1);
            app.Playback_peakTree.Multiselect = 'on';
            app.Playback_peakTree.SelectionChangedFcn = createCallbackFcn(app, @Tree4_SelectionChanged, true);
            app.Playback_peakCF = uieditfield(app.Playback_Grid1, 'numeric');
            app.Playback_peakCF.ValueDisplayFormat = '%.3f';
            app.Playback_peakCF.ValueChangedFcn = createCallbackFcn(app, @Playback_peakCFValueChanged, true);
            app.Playback_peakCF.Enable = 'off';
            app.FrequnciacentralLabel = uilabel(app.Playback_Grid1);
            app.FrequnciacentralLabel.Enable = 'off';
            app.FrequnciacentralLabel.Text = 'Frequência central:';
            app.Playback_peakUnit = uilabel(app.Playback_Grid1);
            app.Playback_peakUnit.Enable = 'off';
            app.Playback_peakUnit.Text = 'MHz';
            app.Playback_mainLabel = uilabel(app.Grid3_Playback);
            app.Playback_mainLabel.Text = 'Playback';
            app.mainInfo_Grid = uigridlayout(app.GridLayout5);
            app.mainInfo_Text = uitextarea(app.mainInfo_Grid);
            app.mainInfo_Text.Editable = 'off';
            app.mainInfo_Label3 = uilabel(app.mainInfo_Grid);
            app.mainInfo_Label3.Text = 'Metadados';
            app.mainInfo_Expand = uiimage(app.mainInfo_Grid);
            app.mainInfo_Expand.ImageClickedFcn = createCallbackFcn(app, @mainInfo_ExpandCollapseTreeNodes, true);
            app.mainInfo_Expand.ImageSource = 'ExpandA1.png';
            app.mainInfo_Label2 = uilabel(app.mainInfo_Grid);
            app.mainInfo_Label2.Text = '0 alerta(s)';
            app.mainInfo_Tree = uitree(app.mainInfo_Grid);
            app.mainInfo_Tree.Multiselect = 'on';
            app.mainInfo_Tree.SelectionChangedFcn = createCallbackFcn(app, @mainInfo_Tree_SelectionChanged, true);
            app.mainInfo_Warning = uiimage(app.mainInfo_Grid);
            app.mainInfo_Warning.ImageSource = 'treeAlarm2.png';
            app.mainInfo_Label1 = uilabel(app.mainInfo_Grid);
            app.mainInfo_Label1.Text = 'Fluxo(s) de dados';
            app.mainInfo_DeleteAll = uibutton(app.mainInfo_Grid, 'push');
            app.mainInfo_DeleteAll.ButtonPushedFcn = createCallbackFcn(app, @mainInfo_DeleteAllButtonPushed, true);
            app.mainInfo_DeleteAll.Icon = 'DeleteAll.png';
            app.mainInfo_DeleteAll.Text = '';
            app.Tab4_Report = uitab(app.TabGroup);
            app.GridLayout6 = uigridlayout(app.Tab4_Report);
            app.Grid3_Report = uigridlayout(app.GridLayout6);
            app.Report_playButton = uibutton(app.Grid3_Report, 'push');
            app.Report_playButton.ButtonPushedFcn = createCallbackFcn(app, @Report_playButtonPushed, true);
            app.Report_playButton.Icon = 'Media_PlayWhite.png';
            app.Report_playButton.Enable = 'off';
            app.Report_playButton.Text = 'Gerar relatório';
            app.Report_previewButton = uibutton(app.Grid3_Report, 'push');
            app.Report_previewButton.ButtonPushedFcn = createCallbackFcn(app, @Report_previewButtonPushed, true);
            app.Report_previewButton.Icon = 'RFWhite.png';
            app.Report_previewButton.Enable = 'off';
            app.Report_previewButton.Text = 'Gerar prévia';
            app.Report_Panel0 = uipanel(app.Grid3_Report);
            app.Report_Grid0 = uigridlayout(app.Report_Panel0);
            app.Report_Grid1 = uigridlayout(app.Report_Grid0);
            app.Report_Label2 = uilabel(app.Report_Grid1);
            app.Report_Label2.Text = 'Fluxo(s) de dados a processar';
            app.Tree3 = uitree(app.Report_Grid1);
            app.Tree3.Multiselect = 'on';
            app.Tree3.SelectionChangedFcn = createCallbackFcn(app, @Tree3_SelectionChanged, true);
            app.Report_Label3 = uilabel(app.Report_Grid1);
            app.Report_Label3.Text = 'Período de observação a processar';
            app.Report_Label1 = uilabel(app.Report_Grid1);
            app.Report_Label1.Text = 'Aspectos gerais';
            app.Report_Grid3 = uigridlayout(app.Report_Grid1);
            app.Report_Issue = uieditfield(app.Report_Grid3, 'numeric');
            app.Report_Issue.Limits = [0 Inf];
            app.Report_Issue.ValueDisplayFormat = '%d';
            app.InspeoFiscalizaLabel = uilabel(app.Report_Grid3);
            app.InspeoFiscalizaLabel.Text = 'Inspeção (Fiscaliza):';
            app.Report_Version = uidropdown(app.Report_Grid3);
            app.Report_Version.Items = {'Preliminar', 'Definitiva'};
            app.Report_Version.Value = 'Preliminar';
            app.VersoLabel = uilabel(app.Report_Grid3);
            app.VersoLabel.Text = 'Versão:';
            app.Report_CheckBox1 = uicheckbox(app.Report_Grid3);
            app.Report_CheckBox1.ValueChangedFcn = createCallbackFcn(app, @Report_CheckBox1ValueChanged, true);
            app.Report_CheckBox1.Text = 'Incluir detalhes acerca do PMEC.';
            app.Report_CheckBox1.Value = true;
            app.Report_CheckBox2 = uicheckbox(app.Report_Grid3);
            app.Report_CheckBox2.ValueChangedFcn = createCallbackFcn(app, @Report_CheckBox2ValueChanged, true);
            app.Report_CheckBox2.Text = 'Incluir detalhes acerca do PRD.';
            app.Report_CheckBox3 = uicheckbox(app.Report_Grid3);
            app.Report_CheckBox3.ValueChangedFcn = createCallbackFcn(app, @Report_CheckBox3ValueChanged, true);
            app.Report_CheckBox3.Text = 'Incluir a informação textual indicada a seguir.';
            app.Report_freeText = uitextarea(app.Report_Grid3);
            app.Report_freeText.Enable = 'off';
            app.Report_freeText.Value = {'O Ministério das Comunicações solicitou, por meio do Ofício nº XX/2021-MC, a realização de monitoração da faixa 450-470 MHz.'};
            app.Report_textLabel = uilabel(app.Report_Grid3);
            app.Report_textLabel.Text = 'Em relação à mascara textual da introdução:';
            app.Report_addButton = uiimage(app.Report_Grid1);
            app.Report_addButton.ImageClickedFcn = createCallbackFcn(app, @Report_addButtonPushed, true);
            app.Report_addButton.Enable = 'off';
            app.Report_addButton.Tooltip = {''};
            app.Report_addButton.ImageSource = 'adicionar3a.png';
            app.Report_deleteButton = uiimage(app.Report_Grid1);
            app.Report_deleteButton.ImageClickedFcn = createCallbackFcn(app, @Report_deleteButtonPushed, true);
            app.Report_deleteButton.Enable = 'off';
            app.Report_deleteButton.Tooltip = {''};
            app.Report_deleteButton.ImageSource = 'adicionar3d.png';
            app.Report_Grid4 = uigridlayout(app.Report_Grid1);
            app.Report_DatePicker1 = uidatepicker(app.Report_Grid4);
            app.Report_DatePicker1.DisplayFormat = 'dd/MMM/uuuu';
            app.Report_Spinner1 = uispinner(app.Report_Grid4);
            app.Report_Spinner1.Limits = [0 23];
            app.Report_Spinner1.RoundFractionalValues = 'on';
            app.Report_Spinner1.ValueDisplayFormat = '%.0f';
            app.Report_Spinner2 = uispinner(app.Report_Grid4);
            app.Report_Spinner2.Step = 10;
            app.Report_Spinner2.Limits = [0 59];
            app.Report_Spinner2.RoundFractionalValues = 'on';
            app.Report_Spinner2.ValueDisplayFormat = '%.0f';
            app.Report_DatePicker2 = uidatepicker(app.Report_Grid4);
            app.Report_DatePicker2.DisplayFormat = 'dd/MMM/uuuu';
            app.Report_Spinner3 = uispinner(app.Report_Grid4);
            app.Report_Spinner3.Limits = [0 23];
            app.Report_Spinner3.RoundFractionalValues = 'on';
            app.Report_Spinner3.ValueDisplayFormat = '%.0f';
            app.Report_Spinner3.Value = 23;
            app.Report_Spinner4 = uispinner(app.Report_Grid4);
            app.Report_Spinner4.Step = 10;
            app.Report_Spinner4.Limits = [0 59];
            app.Report_Spinner4.RoundFractionalValues = 'on';
            app.Report_Spinner4.ValueDisplayFormat = '%.0f';
            app.Report_Spinner4.Value = 59;
            app.Report_Grid2 = uigridlayout(app.Report_Grid0);
            app.Report_Label4 = uilabel(app.Report_Grid2);
            app.Report_Label4.Text = 'Ocupação';
            app.Report_Label5 = uilabel(app.Report_Grid2);
            app.Report_Label5.Text = 'Identificação de emissões';
            app.Report_Grid5 = uigridlayout(app.Report_Grid2);
            app.Report_Panel1 = uipanel(app.Report_Grid5);
            app.Report_Grid8 = uigridlayout(app.Report_Panel1);
            app.Report_occFcn = uidropdown(app.Report_Grid8);
            app.Report_occFcn.Items = {'Mediana', 'Média'};
            app.Report_occFcn.ValueChangedFcn = createCallbackFcn(app, @Report_occFcnValueChanged, true);
            app.Report_occFcn.Enable = 'off';
            app.Report_occFcn.Value = 'Mediana';
            app.FunoestatsticaLabel = uilabel(app.Report_Grid8);
            app.FunoestatsticaLabel.Enable = 'off';
            app.FunoestatsticaLabel.Text = 'Função estatística:';
            app.AmostrasdescartadasLabel = uilabel(app.Report_Grid8);
            app.AmostrasdescartadasLabel.Enable = 'off';
            app.AmostrasdescartadasLabel.Text = 'Amostras descartadas (%):';
            app.Report_tSamples = uispinner(app.Report_Grid8);
            app.Report_tSamples.Limits = [0 10];
            app.Report_tSamples.ValueChangedFcn = createCallbackFcn(app, @Report_tSamplesValueChanged, true);
            app.Report_tSamples.Enable = 'off';
            app.AmostrasteisLabel = uilabel(app.Report_Grid8);
            app.AmostrasteisLabel.Enable = 'off';
            app.AmostrasteisLabel.Text = 'Amostras úteis (%):';
            app.Report_uSamples = uispinner(app.Report_Grid8);
            app.Report_uSamples.Limits = [10 90];
            app.Report_uSamples.ValueChangedFcn = createCallbackFcn(app, @Report_uSamplesValueChanged, true);
            app.Report_uSamples.Enable = 'off';
            app.Report_uSamples.Value = 20;
            app.Report_Grid7 = uigridlayout(app.Report_Grid5);
            app.Report_occType = uidropdown(app.Report_Grid7);
            app.Report_occType.Items = {'Linear adaptativo'};
            app.Report_occType.ValueChangedFcn = createCallbackFcn(app, @Report_occTypeValueChanged, true);
            app.Report_occType.Enable = 'off';
            app.Report_occType.Value = 'Linear adaptativo';
            app.ThresholdDropDownLabel_2 = uilabel(app.Report_Grid7);
            app.ThresholdDropDownLabel_2.Enable = 'off';
            app.ThresholdDropDownLabel_2.Text = 'Threshold:';
            app.Report_occValue = uispinner(app.Report_Grid7);
            app.Report_occValue.RoundFractionalValues = 'on';
            app.Report_occValue.ValueDisplayFormat = '%d';
            app.Report_occValue.ValueChangedFcn = createCallbackFcn(app, @Report_occValueValueChanged, true);
            app.Report_occValue.Enable = 'off';
            app.Report_occValue.Value = -80;
            app.ValordBmLabel_2 = uilabel(app.Report_Grid7);
            app.ValordBmLabel_2.Enable = 'off';
            app.ValordBmLabel_2.Text = 'Valor (dBm):';
            app.Report_occOffset = uispinner(app.Report_Grid7);
            app.Report_occOffset.Limits = [5 12];
            app.Report_occOffset.ValueChangedFcn = createCallbackFcn(app, @Report_occOffsetValueChanged, true);
            app.Report_occOffset.Enable = 'off';
            app.Report_occOffset.Value = 12;
            app.OffSetLabel_2 = uilabel(app.Report_Grid7);
            app.OffSetLabel_2.Enable = 'off';
            app.OffSetLabel_2.Text = 'OffSet:';
            app.TempointegraominLabel = uilabel(app.Report_Grid7);
            app.TempointegraominLabel.Enable = 'off';
            app.TempointegraominLabel.Text = 'Tempo integração (min):';
            app.Report_occTime = uispinner(app.Report_Grid7);
            app.Report_occTime.Limits = [1 60];
            app.Report_occTime.ValueChangedFcn = createCallbackFcn(app, @Report_occTimeValueChanged, true);
            app.Report_occTime.Enable = 'off';
            app.Report_occTime.Value = 60;
            app.NveldeconfianaLabel_2 = uilabel(app.Report_Grid7);
            app.NveldeconfianaLabel_2.Enable = 'off';
            app.NveldeconfianaLabel_2.Text = 'Nível de confiança (%):';
            app.Report_occConfLevel = uidropdown(app.Report_Grid7);
            app.Report_occConfLevel.Items = {'68', '95', '99'};
            app.Report_occConfLevel.ValueChangedFcn = createCallbackFcn(app, @Report_occConfLevelValueChanged, true);
            app.Report_occConfLevel.Enable = 'off';
            app.Report_occConfLevel.Value = '95';
            app.Report_Label6 = uilabel(app.Report_Grid7);
            app.Report_Label6.Enable = 'off';
            app.Report_Label6.Text = 'Parâmetros relacionados à estimativa do piso de ruído:';
            app.Report_Grid6 = uigridlayout(app.Report_Grid2);
            app.Report_Panel2 = uipanel(app.Report_Grid6);
            app.Report_Grid10 = uigridlayout(app.Report_Panel2);
            app.Report_peakFcn = uidropdown(app.Report_Grid10);
            app.Report_peakFcn.Items = {'Média', 'Mediana'};
            app.Report_peakFcn.ValueChangedFcn = createCallbackFcn(app, @Report_peakFcnValueChanged, true);
            app.Report_peakFcn.Enable = 'off';
            app.Report_peakFcn.Value = 'Mediana';
            app.FunoestatsticaLabel_2 = uilabel(app.Report_Grid10);
            app.FunoestatsticaLabel_2.Enable = 'off';
            app.FunoestatsticaLabel_2.Text = {'Função'; 'estatística:'};
            app.Report_peakProeminence_C1 = uispinner(app.Report_Grid10);
            app.Report_peakProeminence_C1.Step = 10;
            app.Report_peakProeminence_C1.Limits = [1 Inf];
            app.Report_peakProeminence_C1.RoundFractionalValues = 'on';
            app.Report_peakProeminence_C1.ValueDisplayFormat = '%.0f';
            app.Report_peakProeminence_C1.ValueChangedFcn = createCallbackFcn(app, @Report_peakProeminence_C1ValueChanged, true);
            app.Report_peakProeminence_C1.Enable = 'off';
            app.Report_peakProeminence_C1.Value = 10;
            app.ProeminnciamnimadBLabel = uilabel(app.Report_Grid10);
            app.ProeminnciamnimadBLabel.Enable = 'off';
            app.ProeminnciamnimadBLabel.Text = {'Proeminência '; 'mínima (dB):'};
            app.Report_Panel3 = uipanel(app.Report_Grid6);
            app.Report_Grid11 = uigridlayout(app.Report_Panel3);
            app.Report_peakProeminence_C2 = uispinner(app.Report_Grid11);
            app.Report_peakProeminence_C2.Step = 10;
            app.Report_peakProeminence_C2.Limits = [1 Inf];
            app.Report_peakProeminence_C2.RoundFractionalValues = 'on';
            app.Report_peakProeminence_C2.ValueDisplayFormat = '%.0f';
            app.Report_peakProeminence_C2.ValueChangedFcn = createCallbackFcn(app, @Report_peakProeminence_C2ValueChanged, true);
            app.Report_peakProeminence_C2.Enable = 'off';
            app.Report_peakProeminence_C2.Value = 30;
            app.ProeminnciamnimadBLabel_2 = uilabel(app.Report_Grid11);
            app.ProeminnciamnimadBLabel_2.Enable = 'off';
            app.ProeminnciamnimadBLabel_2.Text = {'Proeminência '; 'mínima (dB):'};
            app.Report_peakOCC = uispinner(app.Report_Grid11);
            app.Report_peakOCC.Step = 10;
            app.Report_peakOCC.Limits = [0 Inf];
            app.Report_peakOCC.RoundFractionalValues = 'on';
            app.Report_peakOCC.ValueDisplayFormat = '%.0f';
            app.Report_peakOCC.ValueChangedFcn = createCallbackFcn(app, @Report_peakOCCValueChanged, true);
            app.Report_peakOCC.Enable = 'off';
            app.Report_peakOCC.Value = 67;
            app.MnimaocupaomximaLabel = uilabel(app.Report_Grid11);
            app.MnimaocupaomximaLabel.Enable = 'off';
            app.MnimaocupaomximaLabel.Text = 'Mínima ocupação máxima (%):';
            app.Report_Label9 = uilabel(app.Report_Grid6);
            app.Report_Label9.Enable = 'off';
            app.Report_Label9.Text = 'Em relação à curva de MaxHold:';
            app.Report_Grid9 = uigridlayout(app.Report_Grid6);
            app.Report_peakClass = uidropdown(app.Report_Grid9);
            app.Report_peakClass.Items = {'Genérico', 'Radiocomunicação', 'Radiodifusão (FM)', 'Radiodifusão (TV)'};
            app.Report_peakClass.ValueChangedFcn = createCallbackFcn(app, @Report_peakClassValueChanged, true);
            app.Report_peakClass.Enable = 'off';
            app.Report_peakClass.Value = 'Radiodifusão (FM)';
            app.ClassificaodafaixaLabel = uilabel(app.Report_Grid9);
            app.ClassificaodafaixaLabel.Enable = 'off';
            app.ClassificaodafaixaLabel.Text = 'Classificação da faixa:';
            app.Report_Label8 = uilabel(app.Report_Grid9);
            app.Report_Label8.Enable = 'off';
            app.Report_Label8.Text = 'Emissões com alta ocupação, cujas portadoras são moduladas em frequência e ocupam canais espaçados em 200 kHz.';
            app.Report_peakDistance = uispinner(app.Report_Grid9);
            app.Report_peakDistance.Step = 10;
            app.Report_peakDistance.Limits = [0 Inf];
            app.Report_peakDistance.RoundFractionalValues = 'on';
            app.Report_peakDistance.ValueDisplayFormat = '%.0f';
            app.Report_peakDistance.ValueChangedFcn = createCallbackFcn(app, @Report_peakDistanceValueChanged, true);
            app.Report_peakDistance.Enable = 'off';
            app.Report_peakDistance.Value = 200;
            app.DistnciamnimaentrepicoskHzLabel = uilabel(app.Report_Grid9);
            app.DistnciamnimaentrepicoskHzLabel.Enable = 'off';
            app.DistnciamnimaentrepicoskHzLabel.Text = {'Distância mínima '; 'entre picos (kHz):'};
            app.Report_peakBW = uispinner(app.Report_Grid9);
            app.Report_peakBW.Step = 10;
            app.Report_peakBW.Limits = [0 Inf];
            app.Report_peakBW.RoundFractionalValues = 'on';
            app.Report_peakBW.ValueDisplayFormat = '%.0f';
            app.Report_peakBW.ValueChangedFcn = createCallbackFcn(app, @Report_peakBWValueChanged, true);
            app.Report_peakBW.Enable = 'off';
            app.Report_peakBW.Value = 15;
            app.LarguramnimaocupadakHzLabel_2 = uilabel(app.Report_Grid9);
            app.LarguramnimaocupadakHzLabel_2.Enable = 'off';
            app.LarguramnimaocupadakHzLabel_2.Text = {'Largura mínima'; 'ocupada (kHz):'};
            app.Report_Label7 = uilabel(app.Report_Grid9);
            app.Report_Label7.Enable = 'off';
            app.Report_Label7.Text = 'Em relação à curva de tendência central:';
            app.Report_Label0 = uilabel(app.Grid3_Report);
            app.Report_Label0.Text = 'Relatório';
            app.Report_specDataSave = uibutton(app.Grid3_Report, 'push');
            app.Report_specDataSave.ButtonPushedFcn = createCallbackFcn(app, @Report_specDataSaveValueChanged, true);
            app.Report_specDataSave.Icon = 'save3.png';
            app.Report_specDataSave.Enable = 'off';
            app.Report_specDataSave.Text = '';
            app.Report_tableView = uibutton(app.Grid3_Report, 'push');
            app.Report_tableView.ButtonPushedFcn = createCallbackFcn(app, @Report_tableViewValueChanged, true);
            app.Report_tableView.Icon = 'img_318783a.png';
            app.Report_tableView.Enable = 'off';
            app.Report_tableView.Text = '';
            app.Button_fiscaliza = uibutton(app.Grid3_Report, 'push');
            app.Button_fiscaliza.ButtonPushedFcn = createCallbackFcn(app, @AJUSTARNOME_Fiscaliza, true);
            app.Button_fiscaliza.Icon = 'Redmine.png';
            app.Button_fiscaliza.Enable = 'off';
            app.Button_fiscaliza.Text = '';
            app.Tab5_Edit = uitab(app.TabGroup);
            app.Edit_Grid1 = uigridlayout(app.Tab5_Edit);
            app.Grid3_Info = uigridlayout(app.Edit_Grid1);
            app.Info_Panel2 = uipanel(app.Grid3_Info);
            app.Info_Grid3 = uigridlayout(app.Info_Panel2);
            app.Info_Label1 = uilabel(app.Info_Grid3);
            app.Info_Label1.Text = 'Informações extraídas de base de dados da Agência relacionadas ao sensor';
            app.Info_Label2 = uilabel(app.Info_Grid3);
            app.Info_Label2.Text = 'Informações passíveis de edição relacionada ao fluxo selecionado';
            app.Info_Panel1 = uipanel(app.Info_Grid3);
            app.Info_Grid2 = uigridlayout(app.Info_Panel1);
            app.LongitudeEditFieldLabel = uilabel(app.Info_Grid2);
            app.LongitudeEditFieldLabel.Text = 'Longitude:';
            app.Info_MetaData6 = uieditfield(app.Info_Grid2, 'numeric');
            app.Info_MetaData6.ValueDisplayFormat = '%.6f';
            app.Info_MetaData6.Editable = 'off';
            app.Info_MetaData6.Value = -35.092938;
            app.Info_MetaData5 = uieditfield(app.Info_Grid2, 'numeric');
            app.Info_MetaData5.ValueDisplayFormat = '%.6f';
            app.Info_MetaData5.Editable = 'off';
            app.Info_MetaData5.Value = -12.345762;
            app.LatitudeEditFieldLabel = uilabel(app.Info_Grid2);
            app.LatitudeEditFieldLabel.Text = 'Latitude:';
            app.Info_MetaData3 = uieditfield(app.Info_Grid2, 'text');
            app.Info_MetaData3.Editable = 'off';
            app.Info_MetaData3.Value = 'GR08';
            app.UnidaderesponsvelLabel_2 = uilabel(app.Info_Grid2);
            app.UnidaderesponsvelLabel_2.Text = 'Unidade responsável:';
            app.LocalidadeLabel = uilabel(app.Info_Grid2);
            app.LocalidadeLabel.Text = 'Localidade:';
            app.Info_MetaData4 = uieditfield(app.Info_Grid2, 'text');
            app.Info_MetaData4.Editable = 'off';
            app.Info_MetaData4.Value = 'Salvador/BA';
            app.Info_Panel1_2 = uipanel(app.Info_Grid3);
            app.Info_Grid2_2 = uigridlayout(app.Info_Panel1_2);
            app.LatitudeEditField_2Label = uilabel(app.Info_Grid2_2);
            app.LatitudeEditField_2Label.Text = 'Latitude:';
            app.Info_Latitude = uieditfield(app.Info_Grid2_2, 'numeric');
            app.Info_Latitude.Limits = [-90 90];
            app.Info_Latitude.ValueDisplayFormat = '%.6f';
            app.Info_Latitude.ValueChangedFcn = createCallbackFcn(app, @Info_LongitudeValueChanged, true);
            app.Info_Latitude.Value = -1;
            app.Info_Longitude = uieditfield(app.Info_Grid2_2, 'numeric');
            app.Info_Longitude.Limits = [-180 180];
            app.Info_Longitude.ValueDisplayFormat = '%.6f';
            app.Info_Longitude.ValueChangedFcn = createCallbackFcn(app, @Info_LongitudeValueChanged, true);
            app.Info_Longitude.Value = -1;
            app.LongitudeEditField_2Label = uilabel(app.Info_Grid2_2);
            app.LongitudeEditField_2Label.Text = 'Longitude:';
            app.Info_CheckBox = uicheckbox(app.Info_Grid2_2);
            app.Info_CheckBox.Text = 'Transportar esses valores para outros fluxos de dados do instrumento, caso existentes.';
            app.Info_OCC = uidropdown(app.Info_Grid2_2);
            app.Info_OCC.Items = {'Ocupação a ser aferida'};
            app.Info_OCC.Value = 'Ocupação a ser aferida';
            app.OcupaoDropDownLabel = uilabel(app.Info_Grid2_2);
            app.OcupaoDropDownLabel.Text = 'Ocupação:';
            app.LocalidadeLabel_2 = uilabel(app.Info_Grid2_2);
            app.LocalidadeLabel_2.Text = 'Localidade:';
            app.Info_City = uieditfield(app.Info_Grid2_2, 'text');
            app.Info_City.ValueChangedFcn = createCallbackFcn(app, @Info_CityValueChanged, true);
            app.Info_Button = uibutton(app.Grid3_Info, 'push');
            app.Info_Button.ButtonPushedFcn = createCallbackFcn(app, @Info_ButtonPushed, true);
            app.Info_Button.Icon = 'EditWhite.png';
            app.Info_Button.Text = 'Confirma edição';
            app.Info_Label1_2 = uilabel(app.Grid3_Info);
            app.Info_Label1_2.Text = 'Editar';
            app.Tab6_Save = uitab(app.TabGroup);
            app.Save_Grid1 = uigridlayout(app.Tab6_Save);
            app.Grid3_Info_2 = uigridlayout(app.Save_Grid1);
            app.Info_Panel2_2 = uipanel(app.Grid3_Info_2);
            app.Info_Grid3_2 = uigridlayout(app.Info_Panel2_2);
            app.Info_Label1_4 = uilabel(app.Info_Grid3_2);
            app.Info_Label1_4.Text = 'Informações gerais';
            app.Info_Label2_2 = uilabel(app.Info_Grid3_2);
            app.Info_Label2_2.Text = 'Período de observação';
            app.Info_Panel1_3 = uipanel(app.Info_Grid3_2);
            app.Info_Grid2_3 = uigridlayout(app.Info_Panel1_3);
            app.LongitudeEditFieldLabel_2 = uilabel(app.Info_Grid2_3);
            app.LongitudeEditFieldLabel_2.Text = 'Longitude:';
            app.Info_MetaData6_2 = uieditfield(app.Info_Grid2_3, 'numeric');
            app.Info_MetaData6_2.ValueDisplayFormat = '%.6f';
            app.Info_MetaData6_2.Editable = 'off';
            app.Info_MetaData6_2.Value = -35.092938;
            app.Info_MetaData5_2 = uieditfield(app.Info_Grid2_3, 'numeric');
            app.Info_MetaData5_2.ValueDisplayFormat = '%.6f';
            app.Info_MetaData5_2.Editable = 'off';
            app.Info_MetaData5_2.Value = -12.345762;
            app.LatitudeEditFieldLabel_2 = uilabel(app.Info_Grid2_3);
            app.LatitudeEditFieldLabel_2.Text = 'Latitude:';
            app.FormatodoarquivodesadaLabel = uilabel(app.Info_Grid2_3);
            app.FormatodoarquivodesadaLabel.Text = 'Formato do arquivo de saída:';
            app.Info_OCC_2 = uidropdown(app.Info_Grid2_3);
            app.Info_OCC_2.Items = {'CRFS BIN v.5', 'MAT v. 7.3 (HDF5)', 'RF Look v.1', 'SM1809 (UIT)'};
            app.Info_OCC_2.Value = 'MAT v. 7.3 (HDF5)';
            app.Info_MetaData4_4 = uieditfield(app.Info_Grid2_3, 'text');
            app.NomedoarquivoLabel = uilabel(app.Info_Grid2_3);
            app.NomedoarquivoLabel.Text = 'Nome do arquivo:';
            app.Info_MetaData4_5 = uieditfield(app.Info_Grid2_3, 'text');
            app.LocalidadeLabel_3 = uilabel(app.Info_Grid2_3);
            app.LocalidadeLabel_3.Text = 'Localidade:';
            app.Panel_5 = uipanel(app.Info_Grid3_2);
            app.Report_Grid4_2 = uigridlayout(app.Panel_5);
            app.Report_DatePicker1_2 = uidatepicker(app.Report_Grid4_2);
            app.Report_DatePicker1_2.DisplayFormat = 'dd/MMM/uuuu';
            app.Report_Spinner1_2 = uispinner(app.Report_Grid4_2);
            app.Report_Spinner1_2.Limits = [0 23];
            app.Report_Spinner1_2.RoundFractionalValues = 'on';
            app.Report_Spinner1_2.ValueDisplayFormat = '%.0f';
            app.Report_Spinner2_2 = uispinner(app.Report_Grid4_2);
            app.Report_Spinner2_2.Step = 10;
            app.Report_Spinner2_2.Limits = [0 59];
            app.Report_Spinner2_2.RoundFractionalValues = 'on';
            app.Report_Spinner2_2.ValueDisplayFormat = '%.0f';
            app.Report_DatePicker2_2 = uidatepicker(app.Report_Grid4_2);
            app.Report_DatePicker2_2.DisplayFormat = 'dd/MMM/uuuu';
            app.Report_Spinner3_2 = uispinner(app.Report_Grid4_2);
            app.Report_Spinner3_2.Limits = [0 23];
            app.Report_Spinner3_2.RoundFractionalValues = 'on';
            app.Report_Spinner3_2.ValueDisplayFormat = '%.0f';
            app.Report_Spinner3_2.Value = 23;
            app.Report_Spinner4_2 = uispinner(app.Report_Grid4_2);
            app.Report_Spinner4_2.Step = 10;
            app.Report_Spinner4_2.Limits = [0 59];
            app.Report_Spinner4_2.RoundFractionalValues = 'on';
            app.Report_Spinner4_2.ValueDisplayFormat = '%.0f';
            app.Report_Spinner4_2.Value = 59;
            app.Info_Button_2 = uibutton(app.Grid3_Info_2, 'push');
            app.Info_Button_2.Icon = 'SaveWhite.png';
            app.Info_Button_2.Text = 'Salvar arquivo';
            app.Info_Label1_3 = uilabel(app.Grid3_Info_2);
            app.Info_Label1_3.Text = 'Exportar';
            app.Panel = uipanel(app.Grid1);
            app.GridLayout = uigridlayout(app.Panel);
            app.ToolBar = uigridlayout(app.GridLayout);
            app.ToolBar_Button3 = uibutton(app.ToolBar, 'push');
            app.ToolBar_Button3.ButtonPushedFcn = createCallbackFcn(app, @ToolBar_Button3Pushed, true);
            app.ToolBar_Button3.Icon = 'Help.png';
            app.ToolBar_Button3.Text = '';
            app.ToolBar_Button2 = uibutton(app.ToolBar, 'push');
            app.ToolBar_Button2.ButtonPushedFcn = createCallbackFcn(app, @ToolBar_Button2Pushed, true);
            app.ToolBar_Button2.Icon = 'InfoIcon7.png';
            app.ToolBar_Button2.Text = '';
            app.ToolBar_Button1 = uibutton(app.ToolBar, 'push');
            app.ToolBar_Button1.ButtonPushedFcn = createCallbackFcn(app, @ToolBar_Button1Pushed, true);
            app.ToolBar_Button1.Icon = 'global-icon-13.png';
            app.ToolBar_Button1.Text = '';
            app.Panel_2 = uipanel(app.Grid1);
            app.GridLayout2 = uigridlayout(app.Panel_2);
            app.MainMenu = uigridlayout(app.GridLayout2);
            app.menu_Grid2 = uigridlayout(app.MainMenu);
            app.menu_userPath = uidropdown(app.menu_Grid2);
            app.menu_userPath.Items = {'Path'};
            app.menu_userPath.Value = 'Path';
            app.menu_searchPath = uiimage(app.menu_Grid2);
            app.menu_searchPath.ImageClickedFcn = createCallbackFcn(app, @General_searchPathImageClicked, true);
            app.menu_searchPath.ImageSource = 'Zoom4.png';
            app.menu_Grid1 = uigridlayout(app.MainMenu);
            app.Button_Separator1 = uiimage(app.menu_Grid1);
            app.Button_Separator1.ImageSource = 'Line.png';
            app.Button_Home = uibutton(app.menu_Grid1, 'state');
            app.Button_Home.ValueChangedFcn = createCallbackFcn(app, @mainMenu_Home, true);
            app.Button_Home.Tooltip = {''};
            app.Button_Home.Icon = 'Menu_Home.png';
            app.Button_Home.Text = '';
            app.Button_Home.Value = true;
            app.Button_Separator2 = uiimage(app.menu_Grid1);
            app.Button_Separator2.ImageSource = 'Line.png';
            app.Button_File = uibutton(app.menu_Grid1, 'state');
            app.Button_File.ValueChangedFcn = createCallbackFcn(app, @mainMenu_File, true);
            app.Button_File.Tooltip = {'Abrir arquivo(s)'};
            app.Button_File.Icon = '62319c.png';
            app.Button_File.Text = '';
            app.Button_Playback = uibutton(app.menu_Grid1, 'state');
            app.Button_Playback.ValueChangedFcn = createCallbackFcn(app, @mainMenu_Playback, true);
            app.Button_Playback.Enable = 'off';
            app.Button_Playback.Tooltip = {'Playback'};
            app.Button_Playback.Icon = 'RF02.png';
            app.Button_Playback.Text = '';
            app.Button_Report = uibutton(app.menu_Grid1, 'state');
            app.Button_Report.ValueChangedFcn = createCallbackFcn(app, @mainMenu_Report, true);
            app.Button_Report.Enable = 'off';
            app.Button_Report.Tooltip = {'Relatório'};
            app.Button_Report.Icon = 'ReportIcon.png';
            app.Button_Report.Text = '';
            app.Button_EditInfo = uibutton(app.menu_Grid1, 'state');
            app.Button_EditInfo.ValueChangedFcn = createCallbackFcn(app, @mainMenu_EditInfo, true);
            app.Button_EditInfo.Enable = 'off';
            app.Button_EditInfo.Tooltip = {'Edição fluxo(s) de dados'};
            app.Button_EditInfo.Icon = 'Edit2.png';
            app.Button_EditInfo.Text = '';
            app.Button_SaveFile = uibutton(app.menu_Grid1, 'state');
            app.Button_SaveFile.ValueChangedFcn = createCallbackFcn(app, @mainMenu_SaveFile, true);
            app.Button_SaveFile.Enable = 'off';
            app.Button_SaveFile.Tooltip = {'Exportar fluxo(s) de dados'};
            app.Button_SaveFile.Icon = 'save3.png';
            app.Button_SaveFile.Text = '';
            app.Button_Separator3 = uiimage(app.menu_Grid1);
            app.Button_Separator3.ImageSource = 'Line.png';
            app.File_ContextMenu1 = uicontextmenu(app.MainApp);
            app.File_ContextMenu1A = uimenu(app.File_ContextMenu1);
            app.File_ContextMenu1A.MenuSelectedFcn = createCallbackFcn(app, @File_Tree1Delete, true);
            app.File_ContextMenu1A.Text = 'Excluir';
            app.File_ContextMenu2 = uicontextmenu(app.MainApp);
            app.File_ContextMenu2A = uimenu(app.File_ContextMenu2);
            app.File_ContextMenu2A.MenuSelectedFcn = createCallbackFcn(app, @File_Tree2Delete, true);
            app.File_ContextMenu2A.Text = 'Excluir';
            % Show the figure after all components are created
            app.MainApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppAnalise

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainApp)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainApp)
        end
    end
end