classdef winFiscaliza < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Grid1                          matlab.ui.container.GridLayout
        Hyperlink2                     matlab.ui.control.Hyperlink
        Label1                         matlab.ui.control.Label
        Sublabel1                      matlab.ui.control.Label
        Sublabel2                      matlab.ui.control.Label
        Grid2                          matlab.ui.container.GridLayout
        DatePicker2                    matlab.ui.control.DatePicker
        DateLabel2                     matlab.ui.control.Label
        DatePicker1                    matlab.ui.control.DatePicker
        DateLabel1                     matlab.ui.control.Label
        Panel1                         matlab.ui.container.Panel
        Grid3                          matlab.ui.container.GridLayout
        Grid3A                         matlab.ui.container.GridLayout
        issueType                      matlab.ui.control.EditField
        TipoEditFieldLabel             matlab.ui.control.Label
        issueUnit                      matlab.ui.control.EditField
        CoordenaoLabel                 matlab.ui.control.Label
        issueYear                      matlab.ui.control.EditField
        AnodeexecuoEditFieldLabel      matlab.ui.control.Label
        ClasseEditFieldLabel           matlab.ui.control.Label
        issueClass                     matlab.ui.control.EditField
        PrioridadeEditFieldLabel       matlab.ui.control.Label
        issuePriority                  matlab.ui.control.EditField
        SituaoEditFieldLabel           matlab.ui.control.Label
        issueStatus                    matlab.ui.control.EditField
        Grid3B                         matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        ConclusoEditFieldLabel         matlab.ui.control.Label
        Hours4                         matlab.ui.control.NumericEditField
        Hours3                         matlab.ui.control.NumericEditField
        ExecuoEditFieldLabel           matlab.ui.control.Label
        DeslocamentoEditFieldLabel     matlab.ui.control.Label
        Hours2                         matlab.ui.control.NumericEditField
        PreparaoEditFieldLabel         matlab.ui.control.Label
        Hours1                         matlab.ui.control.NumericEditField
        Sublabel3                      matlab.ui.control.Label
        DescriodainspeoTextAreaLabel   matlab.ui.control.Label
        Description                    matlab.ui.control.TextArea
        Grid3C                         matlab.ui.container.GridLayout
        FiscalresponsvelDropDownLabel  matlab.ui.control.Label
        Responsable                    matlab.ui.control.DropDown
        Tree1                          matlab.ui.container.Tree
        Sublabel4                      matlab.ui.control.Label
        Panel_4                        matlab.ui.container.Panel
        Grid4                          matlab.ui.container.GridLayout
        File                           matlab.ui.control.TextArea
        Hyperlink                      matlab.ui.control.Hyperlink
        RelatriodemonitoraoTextAreaLabel  matlab.ui.control.Label
        Grid4A                         matlab.ui.container.GridLayout
        Panel_9                        matlab.ui.container.Panel
        GridLayout13                   matlab.ui.container.GridLayout
        Longitude                      matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel        matlab.ui.control.Label
        Latitude                       matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel         matlab.ui.control.Label
        Sublabel7                      matlab.ui.control.Label
        MunicpioEditFieldLabel         matlab.ui.control.Label
        City                           matlab.ui.control.EditField
        Grid4B                         matlab.ui.container.GridLayout
        EmissionsCount2                matlab.ui.control.NumericEditField
        EmissesnolicenciadasEditFieldLabel  matlab.ui.control.Label
        EmissesidentificadasEditFieldLabel  matlab.ui.control.Label
        EmissionsCount1                matlab.ui.control.NumericEditField
        Panel_10                       matlab.ui.container.Panel
        GridLayout14                   matlab.ui.container.GridLayout
        Limit2                         matlab.ui.control.NumericEditField
        LimitesuperiorMHzEditFieldLabel  matlab.ui.control.Label
        Limit1                         matlab.ui.control.NumericEditField
        LimiteinferiorMHzLabel         matlab.ui.control.Label
        BandLimits                     matlab.ui.control.Label
        Grid4C                         matlab.ui.container.GridLayout
        Journal                        matlab.ui.control.TextArea
        InformaesainserirnohistricodainspeoLabel  matlab.ui.control.Label
        ServiosdetelecomunicaesrelacionadossemissesidentificadasLabel  matlab.ui.control.Label
        Services                       matlab.ui.control.TextArea
        Label3                         matlab.ui.control.Label
        Label2                         matlab.ui.control.Label
        Button1                        matlab.ui.control.Button
        Button2                        matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        CallingApp
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Position, USR, PWD, IssueProject)
            
            x0 = fix(Position(1)+Position(3)/2-426);
            x1 = fix(Position(2)+Position(4)/2-311);
            app.UIFigure.Position(1:2) = [x0, x1];

            app.CallingApp = mainapp;
            
            USR
            PWD
            IssueProject
            
            % A partir do relatório...
            app.Latitude.Value  = ReportProject.Latitude;
            app.Longitude.Value = ReportProject.Longitude;
            app.City.Value      = ReportProject.City;
            
            app.Limit1.Value    = ReportProject.F0;
            app.Limit2.Value    = ReportProject.F1;
            
            app.EmissionsCount1.Value = ReportProject.emissionsValue1;
            app.EmissionsCount2.Value = ReportProject.emissionsValue2;
            
            app.Services.Value  = str2double(ReportProject.Services);
            app.Journal.Value   = jsonencode(ReportProject.tableJournal{1});
            
            
            % Variável que se assemelha ao que será recebido do pyRed
            pyInfo = struct('id',          '55842',               ...
                            'subject',     'INSP_GR08_2021_0190', ...
                            'status',      'Rascunho',            ...
                            'priority',    'Normal',              ...
                            'start_date',  '',                    ...
                            'due_date',    '',                    ...
                            'issueMother', '123456',              ... % Issue Mother (Ação)
                            'issueClass',  '',                    ...
                            'issueType',   '',                    ...
                            'issueYear',   '2021',                ...
                            'Unit',        'FI2',                 ...
                            'Process',     '{"numero"=>"53504.002549/2021-67", "link_acesso"=>"https://sei.anatel.gov.br/sei/controlador.php?acao=procedimento_trabalhar&id_procedimento=7635995"}', ...
                            'Description', '',                    ...
                            'Coordinator', '',                    ...
                            'Workers',     py.list({'Alessandro Vasconcelos Fernandes', 'Alexnaldo Cerqueira da Silva', 'Anecy Oliveira de Freitas', 'Brasílio Rios Leite', 'Carlos Fernando Muniz Santos', 'Carolina Valenca', 'David Santana e Silva Barreto', 'Eric Magalhaes Delgado', 'Gilmar Ferreira do Nascimento', 'Ilva Perla Monteiro Ferreira', 'Isabel Cristina Soares e Souza', 'Iulo de Araujo Lima Lobo', 'Jorgean Ferreira Leal', 'Jose Mauricio Leite Araujo', 'Leonardo Lago Araujo', 'Lin Yu Wan', 'Luis Fernando Camelier de Queiroz', 'Michel Kulhavy Cerqueira', 'Tiago Mota', 'Wilton Machado de Oliveira'}));
            
            pyInfo.Process = jsondecode(replace(pyInfo.Process, '=>', ':'));
            pyInfo.Workers = cell(pyInfo.Workers);
            
            app.issueStatus.Value   = char(pyInfo.status);
            app.issuePriority.Value = char(pyInfo.priority);
            app.issueClass.Value    = char(pyInfo.issueClass);
            app.issueYear.Value     = char(pyInfo.issueYear);
            app.issueUnit.Value     = char(pyInfo.Unit);
            app.issueType.Value     = char(pyInfo.issueType);
            
            app.Process.Value       = char(pyInfo.Description);
            
            if ~isempty(char(pyInfo.Description))
                app.Description.Value = char(pyInfo.Description);
            else
                Band = ReportProject.tableJournal{1}.("Faixa de frequência")(1);
                for ii = 2:height(ReportProject.tableJournal{1})
                    if ii ~= height(ReportProject.tableJournal{1})
                        Band = sprintf('%s, %s', Band, ReportProject.tableJournal{1}.("Faixa de frequência")(ii));
                    else
                        Band = sprintf('%s e %s', Band, ReportProject.tableJournal{1}.("Faixa de frequência")(ii));
                    end
                end
                
                app.Description.Value = sprintf(['Monitoração da(s) faixa(s) %s, no município %s, no âmbito ' ...
                                                 'da demanda de fiscalização registrada na Ação nº %s.'],      ...
                                                 Band, app.City.Value, char(pyInfo.issueMother);
            end
            
            for ii = 1:numel(pyInfo.Workers)
                uitreenode(app.Tree1, 'Text', pyInfo.Workers{ii}, 'NodeData', ii);
            end
            
            
%             d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on');
%             
%             ISSUE = int64(app.Report_Issue.Value);
%             try
%                 if app.FiscalizaCount.Value == 0
%                     d.Message = sprintf('Inspeção nº %d\n\nPasso %d de 4.\nCriação do recurso de comunicação...', ISSUE, (app.FiscalizaCount.Value+1));
%                     app.mod = py.importlib.import_module('pyRedmine');
%                     
%                     d.Message = sprintf('Inspeção nº %d\n\nPasso %d de 4.\nValidação do login e senha...', ISSUE, (app.FiscalizaCount.Value+1));
%                     app.redmineObj = app.mod.connFcn('eric', '123456');
%                     d.Message = sprintf('Inspeção nº %d\n\nPasso %d de 4.\nEm edição campos do formulário para alterar o estado de "Rascunho" para "Aguardando execução".', ISSUE, (app.FiscalizaCount.Value+1));
%                     app.mod.setFcn(app.redmineObj, ISSUE, 1, py.list({'11', 'Primeira escrita...'}),                      ...
%                                  py.list({'{"valor":"Técnica","texto":"Técnica"}',                                    ...
%                                  '{"valor":"Uso do Espectro - Monitoração","texto":"Uso do Espectro - Monitoração"}', ...
%                                  'Uma descrição qualquer...', '174', py.list({'174', '302', '1207'})}));
%                 
%                 elseif app.FiscalizaCount.Value == 1
%                     d.Message = sprintf('Inspeção nº %d\n\nPasso %d de 4.\nEm edição campos do formulário para alterar o estado de "Aguardando execução" para "Em andamento".', ISSUE, (app.FiscalizaCount.Value+1));
%                     app.mod.setFcn(app.redmineObj, ISSUE, 2, py.list({'13', 'Segunda escrita....'}), ...
%                                  'C:/Users/Administrador/OneDrive - ANATEL/appAnalise/Temp/Report_2021.03.08_T19.52.18_FinalVersion.html');
%                     
%                 elseif app.FiscalizaCount.Value == 2
%                     d.Message = sprintf('Inspeção nº %d\n\nPasso %d de 4.\nEm edição campos do formulário para alterar o estado de "Em andamento" para "Relatando".', ISSUE, (app.FiscalizaCount.Value+1));
%                     app.mod.setFcn(app.redmineObj, ISSUE, 3, py.list({'14', 'Terceira escrita...', '2021-01-01', '2021-03-08'}), py.list({'108', 'MHz', '137', 'MHz'}));
%     
%                 elseif app.FiscalizaCount.Value == 3
%                     d.Message = sprintf('Inspeção nº %d\n\nPasso %d de 4.\nEm edição campos do formulário para alterar o estado de "Relatando" para "Relatada".', ISSUE, (app.FiscalizaCount.Value+1));
%                     app.mod.setFcn(app.redmineObj, ISSUE, 4, py.list({'15', 'Quarta escrita.....'}),                            ...
%                                  py.list({'{"valor":"BA/Salvador","texto":"BA/Salvador"}',                                      ...
%                                  '{"valor":"507 - RESTRITO - MÓVEL AERONÁUTICO","texto":"507 - RESTRITO - MÓVEL AERONÁUTICO"}', ...
%                                  '10', '2', '0', '1', '2', '3', '-12.123456', '-38.123456', 'Não se aplica PF - demanda específica', 'Não', '1'}));
%                     
%                 end
%                 
%                 
%                 if app.FiscalizaCount.Value == 3
%                     app.FiscalizaCount.Value = 0;
%                 else
%                     app.FiscalizaCount.Value = app.FiscalizaCount.Value + 1;
%                 end
%                 
%             catch ME
%                 uialert(app.MainApp, sprintf('ME.identifier: %s\nME.message: %s.', ME.identifier, ME.message), 'Alerta!', 'Icon', 'error')
%                 
%             end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            app.CallingApp.auxWin3 = [];
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnálise: Fiscaliza';
            app.UIFigure.Icon = 'Bluemine.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Button2 = uibutton(app.Grid1, 'push');
            app.Button2.Icon = 'Media_PlayWhite.png';
            app.Button2.Text = 'Iniciar relato';
            app.Button1 = uibutton(app.Grid1, 'push');
            app.Button1.Icon = '62319c.png';
            app.Button1.Enable = 'off';
            app.Button1.Text = '';
            app.Label2 = uilabel(app.Grid1);
            app.Label2.Text = 'Aspectos gerais da fiscalização.';
            app.Label3 = uilabel(app.Grid1);
            app.Label3.Text = 'Aspectos técnicos da fiscalização.';
            app.Panel_4 = uipanel(app.Grid1);
            app.Grid4 = uigridlayout(app.Panel_4);
            app.Grid4C = uigridlayout(app.Grid4);
            app.Services = uitextarea(app.Grid4C);
            app.Services.Editable = 'off';
            app.Services.Value = {'019 - Limitado Privado'; '700 - Radiodifusão Sonora em FM'};
            app.ServiosdetelecomunicaesrelacionadossemissesidentificadasLabel = uilabel(app.Grid4C);
            app.ServiosdetelecomunicaesrelacionadossemissesidentificadasLabel.Text = 'Serviço(s) de telecomunicações relacionado(s) à(s) faixa(s) monitorada(s) ou emissões identificadas:';
            app.InformaesainserirnohistricodainspeoLabel = uilabel(app.Grid4C);
            app.InformaesainserirnohistricodainspeoLabel.Text = 'Informações a inserir no histórico da inspeção:';
            app.Journal = uitextarea(app.Grid4C);
            app.Journal.Editable = 'off';
            app.Journal.Value = {'[{"Faixa":"88.000-108.000 MHz", "Classe_A":4,"Classe_B": 0,"Classe_C":10,"Radcom": 2}, {"Faixa":"88.000-108.000 MHz", "Classe_A":4,"Classe_B": 0,"Classe_C":10,"Radcom": 2}]'};
            app.Grid4B = uigridlayout(app.Grid4);
            app.BandLimits = uilabel(app.Grid4B);
            app.BandLimits.Text = 'Limites das faixas de frequência:';
            app.Panel_10 = uipanel(app.Grid4B);
            app.GridLayout14 = uigridlayout(app.Panel_10);
            app.LimiteinferiorMHzLabel = uilabel(app.GridLayout14);
            app.LimiteinferiorMHzLabel.Text = 'Limite inferior (MHz):';
            app.Limit1 = uieditfield(app.GridLayout14, 'numeric');
            app.Limit1.ValueDisplayFormat = '%.6f';
            app.Limit1.Editable = 'off';
            app.Limit1.Value = 54;
            app.LimitesuperiorMHzEditFieldLabel = uilabel(app.GridLayout14);
            app.LimitesuperiorMHzEditFieldLabel.Text = 'Limite superior (MHz):';
            app.Limit2 = uieditfield(app.GridLayout14, 'numeric');
            app.Limit2.ValueDisplayFormat = '%.6f';
            app.Limit2.Editable = 'off';
            app.Limit2.Value = 798;
            app.EmissionsCount1 = uieditfield(app.Grid4B, 'numeric');
            app.EmissionsCount1.Editable = 'off';
            app.EmissionsCount1.Value = 64;
            app.EmissesidentificadasEditFieldLabel = uilabel(app.Grid4B);
            app.EmissesidentificadasEditFieldLabel.Text = 'Emissões identificadas:';
            app.EmissesnolicenciadasEditFieldLabel = uilabel(app.Grid4B);
            app.EmissesnolicenciadasEditFieldLabel.Text = 'Emissões não licenciadas:';
            app.EmissionsCount2 = uieditfield(app.Grid4B, 'numeric');
            app.EmissionsCount2.Editable = 'off';
            app.EmissionsCount2.Value = 8;
            app.Grid4A = uigridlayout(app.Grid4);
            app.City = uieditfield(app.Grid4A, 'text');
            app.City.Editable = 'off';
            app.MunicpioEditFieldLabel = uilabel(app.Grid4A);
            app.MunicpioEditFieldLabel.Text = 'Município:';
            app.Sublabel7 = uilabel(app.Grid4A);
            app.Sublabel7.Text = 'Coordenadas geográficas:';
            app.Panel_9 = uipanel(app.Grid4A);
            app.GridLayout13 = uigridlayout(app.Panel_9);
            app.LatitudeEditFieldLabel = uilabel(app.GridLayout13);
            app.LatitudeEditFieldLabel.Text = 'Latitude:';
            app.Latitude = uieditfield(app.GridLayout13, 'numeric');
            app.Latitude.ValueDisplayFormat = '%.6f';
            app.Latitude.Editable = 'off';
            app.Latitude.Value = -12.123456;
            app.LongitudeEditFieldLabel = uilabel(app.GridLayout13);
            app.LongitudeEditFieldLabel.Text = 'Longitude:';
            app.Longitude = uieditfield(app.GridLayout13, 'numeric');
            app.Longitude.ValueDisplayFormat = '%.6f';
            app.Longitude.Editable = 'off';
            app.Longitude.Value = -38.123456;
            app.RelatriodemonitoraoTextAreaLabel = uilabel(app.Grid4);
            app.RelatriodemonitoraoTextAreaLabel.Text = 'Relatório de monitoração:';
            app.Hyperlink = uihyperlink(app.Grid4);
            app.Hyperlink.URL = 'https://sistemas.anatel.gov.br/fiscaliza/issues/48281';
            app.Hyperlink.Text = 'SEI nº 082986';
            app.File = uitextarea(app.Grid4);
            app.File.Editable = 'off';
            app.Panel1 = uipanel(app.Grid1);
            app.Grid3 = uigridlayout(app.Panel1);
            app.Grid3C = uigridlayout(app.Grid3);
            app.Sublabel4 = uilabel(app.Grid3C);
            app.Sublabel4.Text = 'Fiscais envolvidos na fiscalização:';
            app.Tree1 = uitree(app.Grid3C);
            app.Tree1.Multiselect = 'on';
            app.Responsable = uidropdown(app.Grid3C);
            app.Responsable.Items = {};
            app.Responsable.Enable = 'off';
            app.Responsable.Value = {};
            app.FiscalresponsvelDropDownLabel = uilabel(app.Grid3C);
            app.FiscalresponsvelDropDownLabel.Text = 'Fiscal responsável:';
            app.Grid3B = uigridlayout(app.Grid3);
            app.Description = uitextarea(app.Grid3B);
            app.Description.Value = {'Monitoração das faixas X, Y e Z no município de Salvador/BA no âmbito da demanda de fiscalização registrada na Ação nº 000001.'};
            app.DescriodainspeoTextAreaLabel = uilabel(app.Grid3B);
            app.DescriodainspeoTextAreaLabel.Text = 'Descrição da inspeção:';
            app.Sublabel3 = uilabel(app.Grid3B);
            app.Sublabel3.Text = 'Estimativas de horas:';
            app.GridLayout3 = uigridlayout(app.Grid3B);
            app.Hours1 = uieditfield(app.GridLayout3, 'numeric');
            app.Hours1.Limits = [0 Inf];
            app.Hours1.RoundFractionalValues = 'on';
            app.Hours1.ValueDisplayFormat = '%.0f';
            app.PreparaoEditFieldLabel = uilabel(app.GridLayout3);
            app.PreparaoEditFieldLabel.Text = 'Preparação:';
            app.Hours2 = uieditfield(app.GridLayout3, 'numeric');
            app.Hours2.Limits = [0 Inf];
            app.Hours2.RoundFractionalValues = 'on';
            app.Hours2.ValueDisplayFormat = '%.0f';
            app.DeslocamentoEditFieldLabel = uilabel(app.GridLayout3);
            app.DeslocamentoEditFieldLabel.Text = 'Deslocamento:';
            app.ExecuoEditFieldLabel = uilabel(app.GridLayout3);
            app.ExecuoEditFieldLabel.Text = 'Execução:';
            app.Hours3 = uieditfield(app.GridLayout3, 'numeric');
            app.Hours3.Limits = [0 Inf];
            app.Hours3.RoundFractionalValues = 'on';
            app.Hours3.ValueDisplayFormat = '%.0f';
            app.Hours4 = uieditfield(app.GridLayout3, 'numeric');
            app.Hours4.Limits = [0 Inf];
            app.Hours4.RoundFractionalValues = 'on';
            app.Hours4.ValueDisplayFormat = '%.0f';
            app.ConclusoEditFieldLabel = uilabel(app.GridLayout3);
            app.ConclusoEditFieldLabel.Text = 'Conclusão:';
            app.Grid3A = uigridlayout(app.Grid3);
            app.issueStatus = uieditfield(app.Grid3A, 'text');
            app.issueStatus.Editable = 'off';
            app.SituaoEditFieldLabel = uilabel(app.Grid3A);
            app.SituaoEditFieldLabel.Text = 'Situação:';
            app.issuePriority = uieditfield(app.Grid3A, 'text');
            app.issuePriority.Editable = 'off';
            app.PrioridadeEditFieldLabel = uilabel(app.Grid3A);
            app.PrioridadeEditFieldLabel.Text = 'Prioridade:';
            app.issueClass = uieditfield(app.Grid3A, 'text');
            app.issueClass.Editable = 'off';
            app.ClasseEditFieldLabel = uilabel(app.Grid3A);
            app.ClasseEditFieldLabel.Text = 'Classe:';
            app.AnodeexecuoEditFieldLabel = uilabel(app.Grid3A);
            app.AnodeexecuoEditFieldLabel.Text = 'Ano de execução:';
            app.issueYear = uieditfield(app.Grid3A, 'text');
            app.issueYear.Editable = 'off';
            app.CoordenaoLabel = uilabel(app.Grid3A);
            app.CoordenaoLabel.Text = 'Coordenação:';
            app.issueUnit = uieditfield(app.Grid3A, 'text');
            app.issueUnit.Editable = 'off';
            app.TipoEditFieldLabel = uilabel(app.Grid3A);
            app.TipoEditFieldLabel.Text = 'Tipo:';
            app.issueType = uieditfield(app.Grid3A, 'text');
            app.issueType.Editable = 'off';
            app.Grid2 = uigridlayout(app.Grid1);
            app.DateLabel1 = uilabel(app.Grid2);
            app.DateLabel1.Text = 'Previsão de início:';
            app.DatePicker1 = uidatepicker(app.Grid2);
            app.DatePicker1.DisplayFormat = 'dd/MMM/uuuu';
            app.DateLabel2 = uilabel(app.Grid2);
            app.DateLabel2.Text = 'Data limite:';
            app.DatePicker2 = uidatepicker(app.Grid2);
            app.DatePicker2.DisplayFormat = 'dd/MMM/uuuu';
            app.Sublabel2 = uilabel(app.Grid1);
            app.Sublabel2.Text = 'Atualizado por Fulano de Tal no dia 12/02/2021 às 12:02:22.';
            app.Sublabel1 = uilabel(app.Grid1);
            app.Sublabel1.Text = 'INSP_GR08_2021_0017';
            app.Label1 = uilabel(app.Grid1);
            app.Label1.Text = 'Inspeção nº 123456, relacionada à Ação nº 000001';
            app.Hyperlink2 = uihyperlink(app.Grid1);
            app.Hyperlink2.URL = 'https://sei.anatel.gov.br/sei/controlador.php?acao=procedimento_trabalhar&id_procedimento=6458483';
            app.Hyperlink2.Text = 'Processo SEI nº 53554.001003/2021-11';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winFiscaliza(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end