classdef winTreeEdit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Grid1                        matlab.ui.container.GridLayout
        Tree                         matlab.ui.container.Tree
        deleteButton                 matlab.ui.control.Image
        addButton                    matlab.ui.control.Image
        Label3                       matlab.ui.control.Label
        Grid2                        matlab.ui.container.GridLayout
        Panel                        matlab.ui.container.Panel
        Grid3                        matlab.ui.container.GridLayout
        Grid5                        matlab.ui.container.GridLayout
        ProvvelesquemademodulaoDropDownLabel  matlab.ui.control.Label
        InformaesadicionaisLabel     matlab.ui.control.Label
        SubLabel2                    matlab.ui.control.Label
        Station2                     matlab.ui.control.TextArea
        Modulation                   matlab.ui.control.DropDown
        TipodeemissoDropDownLabel    matlab.ui.control.Label
        Type                         matlab.ui.control.DropDown
        Grid4                        matlab.ui.container.GridLayout
        EmissoDropDownLabel          matlab.ui.control.Label
        FrequnciacentraldocanalEditFieldLabel  matlab.ui.control.Label
        ProvvelemissorTextAreaLabel  matlab.ui.control.Label
        SubLabel1                    matlab.ui.control.Label
        Station1                     matlab.ui.control.TextArea
        Unit                         matlab.ui.control.Label
        CF                           matlab.ui.control.EditField
        regulatoryInfo               matlab.ui.control.DropDown
        Label2                       matlab.ui.control.Label
        Label1                       matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
    end

    
    properties (Access = private)
        
        CallingApp
        peakValues
        tableStyle
        
    end
    
    methods (Access = private)
        
        function Fcn1(app)

            delete(app.Tree.Children);
            
            value = height(app.CallingApp.exceptionList);
            if value
                [~, ind] = sort(cell2mat(app.CallingApp.exceptionList(:,1)));
                app.CallingApp.exceptionList = app.CallingApp.exceptionList(ind,:);
                
                for ii = 1:value
                    uitreenode(app.Tree, 'Text', sprintf('%s MHz, [%s][%s] "%s"',     ...
                                                 app.CallingApp.exceptionList{ii,2},  ...
                                                 app.CallingApp.exceptionList{ii,4},  ...
                                                 app.CallingApp.exceptionList{ii,5},  ...
                                                 app.CallingApp.exceptionList{ii,6}), ...
                                         'NodeData', ii);
                end
            end
            
        end
        
        
        function Fcn2(app)
            
            if ~isempty(app.CallingApp.exceptionList)
                ind1 = [];
                for ii = 1:height(app.CallingApp.exceptionList)
                    ind2 = find(strcmp(app.CallingApp.peaksTable.Truncated, app.CallingApp.exceptionList(ii,2)));
                    if ~isempty(ind2)
                        ind1 = [ind1, ind2];
                    end
                end
                if ~isempty(ind1)
                    addStyle(app.UITable, app.tableStyle, 'row', ind1)
                end
            end
            
        end
        
        
        function Fcn3(app, row)
            
            app.CF.Value       = app.UITable.Data.Truncated{row};
            app.Station1.Value = app.UITable.Data.("Stel/Mosaico database"){row};
            
            ind = [];
            if ~isempty(app.CallingApp.exceptionList)
                ind = find(strcmp(app.CallingApp.exceptionList(:,2), app.UITable.Data.Truncated{row}));
            end
            
            app.regulatoryInfo.Items = {'Licenciada', 'Não licenciada', 'Não passível de licenciamento'};
            if ~isempty(ind)
                app.regulatoryInfo.Items = {'Não licenciada', 'Não passível de licenciamento'}; 
                
                app.regulatoryInfo.Value = app.CallingApp.exceptionList{ind,3};
                app.Type.Value           = app.CallingApp.exceptionList{ind,4};
                app.Modulation.Value     = app.CallingApp.exceptionList{ind,5};
                app.Station2.Value       = app.CallingApp.exceptionList{ind,6};
                
            else
                str = app.CallingApp.peaksTable.regulatoryInfo{row};
                if strcmp(str, 'Não licenciada')
                    app.regulatoryInfo.Items = {'Não licenciada', 'Não passível de licenciamento'}; 
                end
                
                app.regulatoryInfo.Value = str;
                app.Type.Value           = 'Normal';
                app.Modulation.Value     = 'Analógico';
                app.Station2.Value       = '';
            end
            
            regulatoryInfo_ValueChanged(app)
            
            if ~strcmp(app.regulatoryInfo.Value, 'Licenciada')
                Type_ValueChanged(app)
                app.addButton.Enable = 1;
            end
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
%             Trecho ainda não operacional. Objetivo é ajustar tamanho e
%             tipo da fonte do cabeçalho da tabela via JS.
%             pause(2)
%             while true
%                 try
%                     hWin = struct(struct(struct(app.UIFigure).Controller).PlatformHost).CEF;
%                     hWin.executeJS('W = dojo.query("div[class=mw-default-header-cell]");');
%                     hWin.URL
%                     hWin.CurrentURL
%                     break
%                 catch
%                     pause(1)
%                 end
%             end
%             
%             for ii = 1:width(app.UITable.Data)
%                 hWin.executeJS(sprintf('dojo.style(W[%d], ''font-size'', ''11px'');', ii-1));
%                 hWin.executeJS(sprintf('dojo.style(W[%d], ''font-family'', ''Calibri'');', ii-1));
%             end
            
            app.UIFigure.Position(1:2) = [1022, 354];

            app.CallingApp = mainapp;
            app.tableStyle = uistyle('BackgroundColor', [0.80, 0.80, 0.80]);
            

            app.UITable.Data = app.CallingApp.peaksTable(:,2:9);
            app.UITable.ColumnName = {sprintf('Frequência\n(MHz)'), ...
                                      sprintf('Frequência\ncentral do\ncanal (MHz)'), ...
                                      sprintf('Nível\nMínimo\n(dBm)'), ...
                                      sprintf('Nível\nMáximo\n(dBm)'), ...
                                      sprintf('Ocupação\nmédia\n(%%)'), ...
                                      sprintf('Ocupação\nmáxima\n(%%)'), ...
                                      sprintf('Identificação do provável emissor\n(Serviço, Entidade, Município/UF)'), ...
                                      sprintf('Distância\n(km)')};
%             app.UITable.BackgroundColor = [0.94,0.94,0.94];
%             app.UITable.RowName = 'numbered';
            
            Fcn1(app)
            Fcn2(app)
            
        end

        % Cell selection callback: UITable
        function UITable_CellSelection(app, event)
            
            if numel(unique(event.Indices(:,1))) == 1
                set(app.Grid4.Children, 'Enable', 1)
                
                row = event.Indices(1);
                Fcn3(app, row)
            else
                set(app.Grid4.Children, 'Enable', 0)
                set(app.Grid5.Children, 'Enable', 0)
            end
            
        end

        % Value changed function: regulatoryInfo
        function regulatoryInfo_ValueChanged(app, event)
            
            switch app.regulatoryInfo.Value
                case 'Licenciada'
                    set(app.Grid5.Children, 'Enable', 0);
                    app.addButton.Enable = 0;
                case 'Não licenciada'
                    set(app.Grid5.Children, 'Enable', 1);
                    app.addButton.Enable = 1;
                case 'Não passível de licenciamento'
                    set(app.Grid5.Children, 'Enable', 1);
                    app.addButton.Enable = 1;
            end
            
        end

        % Value changed function: Type
        function Type_ValueChanged(app, event)

            switch app.Type.Value
                case 'Não se manifestou'
                    app.ProvvelesquemademodulaoDropDownLabel.Enable = 0;
                    app.Modulation.Enable      = 0;
                otherwise
                    app.ProvvelesquemademodulaoDropDownLabel.Enable = 1;
                    app.Modulation.Enable      = 1;
            end
            
        end

        % Image clicked function: addButton
        function addButton_Clicked(app, event)
            
            if ~strcmp(deblank(app.Station2.Value), {''})
                str = '';
                for ii = 1:length(app.Station2.Value)
                    str = [str, app.Station2.Value{ii}];
                end
                
                exceptionValue = {str2double(app.CF.Value), ...
                                  app.CF.Value,             ...
                                  app.regulatoryInfo.Value, ...
                                  app.Type.Value,           ...
                                  app.Modulation.Value,     ...
                                  str};
                
                ind = [];
                if ~isempty(app.CallingApp.exceptionList)
                    ind = find(strcmp(app.CallingApp.exceptionList(:,2), app.CF.Value));
                end
                
                if isempty(ind)
                    app.CallingApp.exceptionList = [app.CallingApp.exceptionList; exceptionValue];
                else
                    app.CallingApp.exceptionList(ind,:) = exceptionValue;
                end
                
                Fcn1(app)
                Fcn2(app)
                
            else
                uialert(app.UIFigure, 'Campo "Informações adicionais" não pode ficar vazio.', 'Alerta', 'Icon', 'warning')
                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'appAnálise: Prévia de relatório';
            app.UIFigure.Icon = 'Bluemine.png';
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Tree = uitree(app.Grid1);
            app.Tree.Multiselect = 'on';
            app.deleteButton = uiimage(app.Grid1);
            app.deleteButton.Enable = 'off';
            app.deleteButton.Tooltip = {''};
            app.deleteButton.ImageSource = 'adicionar3d.png';
            app.addButton = uiimage(app.Grid1);
            app.addButton.ImageClickedFcn = createCallbackFcn(app, @addButton_Clicked, true);
            app.addButton.Enable = 'off';
            app.addButton.Tooltip = {''};
            app.addButton.ImageSource = 'adicionar3a.png';
            app.Label3 = uilabel(app.Grid1);
            app.Label3.Text = 'Lista de exceções';
            app.Grid2 = uigridlayout(app.Grid1);
            app.Panel = uipanel(app.Grid2);
            app.Grid3 = uigridlayout(app.Panel);
            app.Grid5 = uigridlayout(app.Grid3);
            app.ProvvelesquemademodulaoDropDownLabel = uilabel(app.Grid5);
            app.ProvvelesquemademodulaoDropDownLabel.Enable = 'off';
            app.ProvvelesquemademodulaoDropDownLabel.Text = 'Provável esquema de modulação:';
            app.InformaesadicionaisLabel = uilabel(app.Grid5);
            app.InformaesadicionaisLabel.Enable = 'off';
            app.InformaesadicionaisLabel.Text = 'Informações adicionais:';
            app.SubLabel2 = uilabel(app.Grid5);
            app.SubLabel2.Enable = 'off';
            app.SubLabel2.Text = '(inserir, em especial, informações que podem ser úteis em eventual diligência de campo)';
            app.Station2 = uitextarea(app.Grid5);
            app.Station2.Enable = 'off';
            app.Station2.Value = {'dedede'; 'dedede'; 'dedede'};
            app.Modulation = uidropdown(app.Grid5);
            app.Modulation.Items = {'Analógico', 'Digital', 'Não identificado'};
            app.Modulation.Enable = 'off';
            app.Modulation.Value = 'Analógico';
            app.TipodeemissoDropDownLabel = uilabel(app.Grid5);
            app.TipodeemissoDropDownLabel.Enable = 'off';
            app.TipodeemissoDropDownLabel.Text = 'Tipo de emissão:';
            app.Type = uidropdown(app.Grid5);
            app.Type.Items = {'Normal', 'Espúrio', 'Não identificado', 'Não se manifestou'};
            app.Type.ValueChangedFcn = createCallbackFcn(app, @Type_ValueChanged, true);
            app.Type.Enable = 'off';
            app.Type.Value = 'Normal';
            app.Grid4 = uigridlayout(app.Grid3);
            app.EmissoDropDownLabel = uilabel(app.Grid4);
            app.EmissoDropDownLabel.Enable = 'off';
            app.EmissoDropDownLabel.Text = 'Emissão:';
            app.FrequnciacentraldocanalEditFieldLabel = uilabel(app.Grid4);
            app.FrequnciacentraldocanalEditFieldLabel.Enable = 'off';
            app.FrequnciacentraldocanalEditFieldLabel.Text = 'Frequência central do canal:';
            app.ProvvelemissorTextAreaLabel = uilabel(app.Grid4);
            app.ProvvelemissorTextAreaLabel.Enable = 'off';
            app.ProvvelemissorTextAreaLabel.Text = 'Provável emissor:';
            app.SubLabel1 = uilabel(app.Grid4);
            app.SubLabel1.Enable = 'off';
            app.SubLabel1.Text = '(sugerido pelo algoritmo embarcado no appAnálise)';
            app.Station1 = uitextarea(app.Grid4);
            app.Station1.Editable = 'off';
            app.Station1.Enable = 'off';
            app.Unit = uilabel(app.Grid4);
            app.Unit.Enable = 'off';
            app.Unit.Text = 'MHz';
            app.CF = uieditfield(app.Grid4, 'text');
            app.CF.Editable = 'off';
            app.CF.Enable = 'off';
            app.regulatoryInfo = uidropdown(app.Grid4);
            app.regulatoryInfo.Items = {'Licenciada', 'Não licenciada', 'Não passível de licenciamento'};
            app.regulatoryInfo.ValueChangedFcn = createCallbackFcn(app, @regulatoryInfo_ValueChanged, true);
            app.regulatoryInfo.Enable = 'off';
            app.regulatoryInfo.Value = 'Licenciada';
            app.Label2 = uilabel(app.Grid2);
            app.Label2.Text = 'Registro selecionado';
            app.Label1 = uilabel(app.Grid1);
            app.Label1.Text = 'Picos que atendem às condições estabelecidas';
            app.UITable = uitable(app.Grid1);
            app.UITable.ColumnName = {''; ''; ''; ''; ''; ''; ''; ''};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITable_CellSelection, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winTreeEdit(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end